classdef BottomWaterAuxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_BottomWaterAuxApp        matlab.ui.Figure
        ChooseBottomWaterPanel          matlab.ui.container.Panel
        edit_chosenBW                   matlab.ui.control.NumericEditField
        RecordNumberEditField_2Label_2  matlab.ui.control.Label
        line_2                          matlab.ui.control.Label
        edit_avtopsens                  matlab.ui.control.NumericEditField
        LowesttemperaturerecordedaftersensorcalibrationCLabel_2  matlab.ui.control.Label
        edit_lowesttemp                 matlab.ui.control.NumericEditField
        LowesttemperaturerecordedaftersensorcalibrationCLabel  matlab.ui.control.Label
        line                            matlab.ui.control.Label
        InformationfrompenetrationLabel  matlab.ui.control.Label
        FindrecordedtemperaturestouseasbottomwaterLabel  matlab.ui.control.Label
        dropdown_sensors                matlab.ui.control.DropDown
        SensorDropDownLabel             matlab.ui.control.Label
        button_continue                 matlab.ui.control.Button
        BottomwatertemperatureCLabel    matlab.ui.control.Label
        edit_BWtemp                     matlab.ui.control.NumericEditField
        edit_recordnumber               matlab.ui.control.NumericEditField
        RecordNumberEditField_2Label    matlab.ui.control.Label
    end

    
    properties (Access = private)
       
        CallingApp  
        NumberOfSensors
        SensorsToUse
        AllRecords
        PenetrationRecord
        AllSensorsTempRelBW
        WaterThermistor
        AllSensorsCalibratedTemp
        WaterSensorTemp
        CurrentSensor

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, AllSensorsCalibratedTemp, WaterSensorTemp, WaterThermistor, NumberOfSensors, SensorsToUse, AllRecords, PenetrationRecord, AllSensorsTempRelBW, CurrentBW)
% ===============================================================
%% Automatically called when this application is generated.
% ===============================================================

%% Initialize GUI
            app.CallingApp = mainapp;
            app.figure_BottomWaterAuxApp.WindowStyle = 'modal';
            movegui(app.figure_BottomWaterAuxApp, "center");

%% Initialize parameters
            app.NumberOfSensors = NumberOfSensors;
            app.SensorsToUse = SensorsToUse;
            app.AllRecords = AllRecords;
            app.PenetrationRecord = PenetrationRecord;
            app.AllSensorsTempRelBW = AllSensorsTempRelBW;
            app.WaterThermistor = WaterThermistor;
            app.AllSensorsCalibratedTemp = AllSensorsCalibratedTemp;
            app.WaterSensorTemp = WaterSensorTemp;

            % Initiate sensor dropdown
            app.dropdown_sensors.Items = [string(app.SensorsToUse), 'Top', 'NaN'];
            app.dropdown_sensors.Value = app.dropdown_sensors.Items(end-2);
            app.CurrentSensor = str2double(app.dropdown_sensors.Value);

            % Intitiate edit fields
            app.edit_recordnumber.Value = app.PenetrationRecord-1;
            app.edit_lowesttemp.Value = min(min(app.AllSensorsCalibratedTemp, [],'omitnan'));
            if app.WaterThermistor
                try
                    app.edit_avtopsens.Value = round(mean(app.WaterSensorTemp, 'omitnan'),3);
                catch
                    a = uiconfirm(app.figure_BottomWaterAuxApp, ['ERROR: ' newline ...
                    newline 'Look at parameters. If a top sensor was not ' ...
                        'used to measure bottom water, ensure this parameter ' ...
                        'is set to "0".'],'ERROR','Icon','error');
                    waitfor(a)
                    close(app.figure_BottomWaterAuxApp)
                    return
                end
            else
                app.edit_avtopsens.Value = -999;
            end

            if contains(app.dropdown_sensors.Value, 'Top') && app.WaterThermistor
                 app.edit_chosenBW.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, end);
                 app.edit_chosenBW.Value = round(app.edit_chosenBW.Value, 3);
            elseif contains(app.dropdown_sensors.Value, 'Top') && ~app.WaterThermistor
                 app.edit_chosenBW.Value = -999;
            else
                app.edit_chosenBW.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, str2double(app.dropdown_sensors.Value));
                app.edit_chosenBW.Value = round(app.edit_chosenBW.Value, 3);
            end

            app.edit_BWtemp.Value=round(CurrentBW,3);

        end

        % Value changed function: dropdown_sensors
        function dropdown_sensorsValueChanged(app, event)
% ==================================================================
%% Called when the sensor value for finding new BW temp is changed.
% ==================================================================

            % Update current sensor value
            app.CurrentSensor = str2double(app.dropdown_sensors.Value);

            % Find the temperature at the defined point in the penetration 
            % for this sensor
            if ~isnan(app.CurrentSensor) && app.edit_recordnumber.Value ~= -999
                app.edit_chosenBW.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, str2double(app.dropdown_sensors.Value));
                app.edit_chosenBW.Value = round(app.edit_chosenBW.Value, 3);
            elseif contains(app.dropdown_sensors.Value, 'Top') && app.WaterThermistor 
                 app.edit_chosenBW.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, end);
                 app.edit_chosenBW.Value = round(app.edit_chosenBW.Value, 3);
            elseif contains(app.dropdown_sensors.Value, 'Top') && ~app.WaterThermistor
                 app.edit_chosenBW.Value = -999;
            end

        end

        % Value changed function: edit_recordnumber
        function edit_recordnumberValueChanged(app, event)
% ==================================================================
%% Called when the record number for finding new BW temp is changed.
% ==================================================================
        
            % Update record number
            RecordNumber = app.edit_recordnumber.Value;

            % Find the temperature at for defined sensor at this 
            % record number
            if ~isnan(RecordNumber) && ~contains(app.dropdown_sensors.Value, 'Top') && app.WaterThermistor
                app.edit_chosenBW.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, str2double(app.dropdown_sensors.Value));
                app.edit_chosenBW.Value = round(app.edit_chosenBW.Value, 3);
            elseif contains(app.dropdown_sensors.Value, 'Top') && app.WaterThermistor
                 app.edit_chosenBW.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, end);
                 app.edit_chosenBW.Value = round(app.edit_chosenBW.Value, 3);
            elseif contains(app.dropdown_sensors.Value, 'Top') && ~app.WaterThermistor
                 app.edit_chosenBW.Value = -999;
            end

        end

        % Button pushed function: button_continue
        function button_continueButtonPushed(app, event)
% ============================================
%% Called when the update button is pressed.
% ============================================

            % Send new bottom water value to the main app and run the
            % function for updating this value and re-processing
            SetBottomWater(app.CallingApp, app.edit_BWtemp.Value)

            % Delete app
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_BottomWaterAuxApp = uifigure('Visible', 'off');
            app.figure_BottomWaterAuxApp.Name = 'MATLAB App';
            app.ChooseBottomWaterPanel = uipanel(app.figure_BottomWaterAuxApp);
            app.ChooseBottomWaterPanel.Title = 'Choose Bottom Water';
            app.RecordNumberEditField_2Label = uilabel(app.ChooseBottomWaterPanel);
            app.RecordNumberEditField_2Label.Text = 'Record Number:';
            app.edit_recordnumber = uieditfield(app.ChooseBottomWaterPanel, 'numeric');
            app.edit_recordnumber.ValueChangedFcn = createCallbackFcn(app, @edit_recordnumberValueChanged, true);
            app.edit_BWtemp = uieditfield(app.ChooseBottomWaterPanel, 'numeric');
            app.BottomwatertemperatureCLabel = uilabel(app.ChooseBottomWaterPanel);
            app.BottomwatertemperatureCLabel.Text = 'Update bottom water temperature (°C):';
            app.button_continue = uibutton(app.ChooseBottomWaterPanel, 'push');
            app.button_continue.ButtonPushedFcn = createCallbackFcn(app, @button_continueButtonPushed, true);
            app.button_continue.Text = 'Update ';
            app.SensorDropDownLabel = uilabel(app.ChooseBottomWaterPanel);
            app.SensorDropDownLabel.Text = 'Sensor:';
            app.dropdown_sensors = uidropdown(app.ChooseBottomWaterPanel);
            app.dropdown_sensors.Items = {};
            app.dropdown_sensors.ValueChangedFcn = createCallbackFcn(app, @dropdown_sensorsValueChanged, true);
            app.dropdown_sensors.Value = {};
            app.FindrecordedtemperaturestouseasbottomwaterLabel = uilabel(app.ChooseBottomWaterPanel);
            app.FindrecordedtemperaturestouseasbottomwaterLabel.Text = 'Find recorded temperatures to use as bottom water:';
            app.InformationfrompenetrationLabel = uilabel(app.ChooseBottomWaterPanel);
            app.InformationfrompenetrationLabel.Text = 'Information from penetration:';
            app.line = uilabel(app.ChooseBottomWaterPanel);
            app.line.Text = '----------------------------------------------------------------------------------';
            app.LowesttemperaturerecordedaftersensorcalibrationCLabel = uilabel(app.ChooseBottomWaterPanel);
            app.LowesttemperaturerecordedaftersensorcalibrationCLabel.Text = 'Lowest temperature recorded during penetration (°C):';
            app.edit_lowesttemp = uieditfield(app.ChooseBottomWaterPanel, 'numeric');
            app.edit_lowesttemp.Editable = 'off';
            app.LowesttemperaturerecordedaftersensorcalibrationCLabel_2 = uilabel(app.ChooseBottomWaterPanel);
            app.LowesttemperaturerecordedaftersensorcalibrationCLabel_2.Text = 'Average temperature recorded from top sensor during penetration (°C):';
            app.edit_avtopsens = uieditfield(app.ChooseBottomWaterPanel, 'numeric');
            app.edit_avtopsens.Editable = 'off';
            app.line_2 = uilabel(app.ChooseBottomWaterPanel);
            app.line_2.Text = '----------------------------------------------------------------------------------';
            app.RecordNumberEditField_2Label_2 = uilabel(app.ChooseBottomWaterPanel);
            app.RecordNumberEditField_2Label_2.Text = 'Temperature:';
            app.edit_chosenBW = uieditfield(app.ChooseBottomWaterPanel, 'numeric');
            % Show the figure after all components are created
            app.figure_BottomWaterAuxApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BottomWaterAuxApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_BottomWaterAuxApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_BottomWaterAuxApp)
        end
    end
end