classdef SetParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_SetParams                matlab.ui.Figure
        panel_Main                      matlab.ui.container.Panel
        edit_MaxkIter                   matlab.ui.control.NumericEditField
        MinimumchangeofSigmakLabel_2    matlab.ui.control.Label
        edit_MinSigChange               matlab.ui.control.NumericEditField
        MinimumchangeofSigmakLabel      matlab.ui.control.Label
        edit_ErrorTolerance             matlab.ui.control.NumericEditField
        ErrortoleranceonthermalconductivityiterationEditFieldLabel  matlab.ui.control.Label
        TimeboundaryconditionsLabel     matlab.ui.control.Label
        edit_HorizkAnisotropy           matlab.ui.control.NumericEditField
        HorizontalthermalconductivityanisotropyEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsforsensitivityanalysisLabel  matlab.ui.control.Label
        edit_SAIterNum                  matlab.ui.control.NumericEditField
        TimeshiftsLabel                 matlab.ui.control.Label
        edit_MinThickness               matlab.ui.control.NumericEditField
        MininumlayerthicknessforsensitivityanalysisLabel  matlab.ui.control.Label
        edit_SAStandDev                 matlab.ui.control.NumericEditField
        StandarddeviationinkforsensitivityanalysisLabel  matlab.ui.control.Label
        edit_SAkMax                     matlab.ui.control.NumericEditField
        edit_SAkMin                     matlab.ui.control.NumericEditField
        label_SAkMax                    matlab.ui.control.Label
        label_SAMin                     matlab.ui.control.Label
        label_SAkBounds                 matlab.ui.control.Label
        InitialsensitivityanalysisparametersLabel  matlab.ui.control.Label
        edit_MaxFricSteps               matlab.ui.control.NumericEditField
        MaxstepsinfrictiondecayLabel    matlab.ui.control.Label
        DimensionlesstimeboundaryconditionsLabel  matlab.ui.control.Label
        DefaultfunctionsusedfordeterminingkwithnoheatpulseLabel  matlab.ui.control.Label
        GeneralprobesetupLabel          matlab.ui.control.Label
        edit_probelength                matlab.ui.control.NumericEditField
        ProbeLengthLabel                matlab.ui.control.Label
        edit_kInit_7                    matlab.ui.control.NumericEditField
        label_kniit_1                   matlab.ui.control.Label
        edit_ManOffset                  matlab.ui.control.NumericEditField
        MaxstepsinfrictiondecayLabel_2  matlab.ui.control.Label
        TemperaturecalibrationLabel     matlab.ui.control.Label
        edit_InitTimeShift              matlab.ui.control.NumericEditField
        InitialtimeshiftLabel_2         matlab.ui.control.Label
        edit_TimeShiftInc               matlab.ui.control.NumericEditField
        InitialtimeshiftLabel           matlab.ui.control.Label
        UpdateParametersButton          matlab.ui.control.Button
        edit_HPTimeDelay                matlab.ui.control.NumericEditField
        HeatpulsetimedelayssLabel       matlab.ui.control.Label
        edit_FricTimeDelay              matlab.ui.control.NumericEditField
        FrictionaltimedelayssLabel      matlab.ui.control.Label
        label_HyndCo                    matlab.ui.control.Label
        edit_HPTauMax                   matlab.ui.control.NumericEditField
        edit_HPTauMin                   matlab.ui.control.NumericEditField
        edit_FricTauMax                 matlab.ui.control.NumericEditField
        edit_FricTauMin                 matlab.ui.control.NumericEditField
        label_HPTauMax                  matlab.ui.control.Label
        label_HPTauMin                  matlab.ui.control.Label
        label_HPTau                     matlab.ui.control.Label
        label_FricTauMax                matlab.ui.control.Label
        label_FricTauMin                matlab.ui.control.Label
        label_FricTau                   matlab.ui.control.Label
        edit_kInit_6                    matlab.ui.control.NumericEditField
        edit_kInit_5                    matlab.ui.control.NumericEditField
        edit_kInit_4                    matlab.ui.control.NumericEditField
        edit_kInit_3                    matlab.ui.control.NumericEditField
        edit_kInit_2                    matlab.ui.control.NumericEditField
        edit_kInit_1                    matlab.ui.control.NumericEditField
        label_kInit                     matlab.ui.control.Label
        edit_HyndCo_3                   matlab.ui.control.NumericEditField
        edit_HyndCo_2                   matlab.ui.control.NumericEditField
        edit_HyndCo_1                   matlab.ui.control.NumericEditField
        edit_DepthFirstTherm            matlab.ui.control.NumericEditField
        DepthoffirstthermistorbelowweightstandmLabel  matlab.ui.control.Label
        edit_HPDuration                 matlab.ui.control.NumericEditField
        DurationofheatpulsesEditFieldLabel  matlab.ui.control.Label
        edit_PulsePower                 matlab.ui.control.NumericEditField
        PulsepowerperlengthJmEditFieldLabel  matlab.ui.control.Label
        edit_TempError                  matlab.ui.control.NumericEditField
        AssumedtemperatureerrorCLabel   matlab.ui.control.Label
        edit_SensorRadius               matlab.ui.control.NumericEditField
        RadiusofsensormLabel            matlab.ui.control.Label
        edit_SensorSpacing              matlab.ui.control.NumericEditField
        DistancebetweensensorsmEditFieldLabel  matlab.ui.control.Label
        edit_HPTimeInc                  matlab.ui.control.NumericEditField
        TimeshiftincrementinheatpulsedecaysLabel  matlab.ui.control.Label
        edit_WaterTempSensor            matlab.ui.control.NumericEditField
        Watertemperaturethermistor1Yes2NoLabel  matlab.ui.control.Label
        edit_ThermTiming                matlab.ui.control.NumericEditField
        TimebeteenthermistorreadingssLabel  matlab.ui.control.Label
        edit_HPMaxSteps                 matlab.ui.control.NumericEditField
        MaxtimestepsinheatpulsedecayLabel  matlab.ui.control.Label
        edit_NumSensors                 matlab.ui.control.NumericEditField
        edit_TimeScaling                matlab.ui.control.NumericEditField
        TimescalingfactorsecrecordnumberLabel  matlab.ui.control.Label
        NumberofSensorsLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp
        HyndCo
        kInitMat✓
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, offset, numsen, watertherm, timescale, deltatime, sensrad, sensdist, temperror, hyndco, fricdelay, fricmaxstep, timeinc, frictaumin, frictaumax, pulsedelay, kinit, ktype, pulsepow, timshiftinit, timeshiftinc, pulsemaxstep, ktol, pulsetaumin, pulsetaumax, HPlength, minkchange, maxiter, maxSAiter, sigk, kmin, kmax, minthick, kAni, topsensor, probelength)
        % =========================================
        %% Automatically run when aux app is called  
        % =========================================

            % Initialize application
            % ---------------------
            app.CallingApp = mainapp;
            app.figure_SetParams.WindowStyle = 'modal';
            movegui(app.figure_SetParams, "center");
            
            % Brings parameters from main SlugHeat app to this app
            % ------------------------------------------------------
            app.edit_ManOffset.Value             = offset;
            app.edit_NumSensors.Value            = numsen;
            app.edit_WaterTempSensor.Value       = watertherm;
            app.edit_TimeScaling.Value           = timescale;
            app.edit_ThermTiming.Value           = deltatime;
            app.edit_SensorRadius.Value          = sensrad;
            app.edit_SensorSpacing.Value         = sensdist;
            app.edit_TempError.Value             = temperror;
            app.edit_HyndCo_1.Value              = hyndco(1); 
            app.edit_HyndCo_2.Value              = hyndco(2);
            app.edit_HyndCo_3.Value              = hyndco(3);
            app.edit_FricTimeDelay.Value         = fricdelay(1);
            app.edit_MaxFricSteps.Value          = fricmaxstep;
            app.edit_HPTimeInc.Value             = timeinc;
            app.edit_FricTauMin.Value            = frictaumin;
            app.edit_FricTauMax.Value            = frictaumax;
            app.edit_HPTimeDelay.Value           = pulsedelay(1);
            app.edit_kInit_1.Value               = ktype;
            app.edit_kInit_2.Value               = kinit(1);
            app.edit_kInit_3.Value               = kinit(2);
            app.edit_kInit_4.Value               = kinit(3);
            app.edit_kInit_5.Value               = kinit(4);
            app.edit_kInit_6.Value               = kinit(5);
            app.edit_kInit_7.Value               = kinit(6);
            app.edit_PulsePower.Value            = pulsepow;
            app.edit_InitTimeShift.Value         = timshiftinit;
            app.edit_TimeShiftInc.Value          = timeshiftinc;
            app.edit_HPMaxSteps.Value            = pulsemaxstep;
            app.edit_ErrorTolerance.Value        = ktol;
            app.edit_HPTauMin.Value              = pulsetaumin;
            app.edit_HPTauMax.Value              = pulsetaumax;
            app.edit_HPDuration.Value            = HPlength;
            app.edit_MinSigChange.Value          = minkchange;
            app.edit_MaxkIter.Value              = maxiter;
            app.edit_SAIterNum.Value             = maxSAiter;
            app.edit_SAStandDev.Value            = sigk(1);
            app.edit_SAkMin.Value                = kmin(1);
            app.edit_SAkMax.Value                = kmax(1);
            app.edit_MinThickness.Value          = minthick;
            app.edit_HorizkAnisotropy.Value      = kAni;
            app.edit_DepthFirstTherm.Value       = topsensor;
            app.edit_probelength.Value           = probelength;                
           
        
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
        % =========================================
        %% Called when update parameters button is pressed
        % ================================================

            % Create structures for Hyndmann coefficients and 
            % initial k values
            % -------------------------------------------------
            app.HyndCo = [app.edit_HyndCo_1.Value app.edit_HyndCo_2.Value 
                app.edit_HyndCo_3.Value];
            app.kInitMat = [app.edit_kInit_2.Value app.edit_kInit_3.Value 
                app.edit_kInit_4.Value app.edit_kInit_5.Value 
                app.edit_kInit_6.Value app.edit_kInit_7.Value];

            % Call public function in the main SlugHeat application to
            % define new parameters based on those defined in this app
            % ---------------------------------------------------------
            setParams(app.CallingApp, app.edit_ManOffset.Value, ...
                app.edit_NumSensors.Value, app.edit_WaterTempSensor.Value, ...
                app.edit_TimeScaling.Value, app.edit_ThermTiming.Value, ...
                app.edit_SensorRadius.Value, app.edit_SensorSpacing.Value, ...
                app.edit_TempError.Value, app.HyndCo(:), ...
                app.edit_FricTimeDelay.Value, app.edit_MaxFricSteps.Value, ...
                app.edit_HPTimeInc.Value, app.edit_FricTauMin.Value, ...
                app.edit_FricTauMax.Value, app.edit_HPTimeDelay.Value, ...
                app.kInitMat(:), app.edit_kInit_1.Value, ...
                app.edit_PulsePower.Value, app.edit_InitTimeShift.Value, ...
                app.edit_TimeShiftInc.Value, app.edit_HPMaxSteps.Value, ...
                app.edit_ErrorTolerance.Value, app.edit_HPTauMin.Value, ...
                app.edit_HPTauMax.Value, app.edit_HPDuration.Value, ...
                app.edit_MinSigChange.Value, app.edit_MaxkIter.Value, ...
                app.edit_SAIterNum.Value, app.edit_SAStandDev.Value, ...
                app.edit_SAkMin.Value, app.edit_SAkMax.Value, ...
                app.edit_MinThickness.Value, ...
                app.edit_HorizkAnisotropy.Value, a ...
                pp.edit_DepthFirstTherm.Value, app.edit_probelength.Value)

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_SetParams = uifigure('Visible', 'off');
            app.figure_SetParams.Name = 'MATLAB App';
            app.panel_Main = uipanel(app.figure_SetParams);
            app.panel_Main.Title = 'Set Default Parameters';
            app.NumberofSensorsLabel = uilabel(app.panel_Main);
            app.NumberofSensorsLabel.Text = 'Number of Sensors (excluding water termperature sensor):';
            app.TimescalingfactorsecrecordnumberLabel = uilabel(app.panel_Main);
            app.TimescalingfactorsecrecordnumberLabel.Text = 'Time scaling factor (seconds/record number):';
            app.edit_TimeScaling = uieditfield(app.panel_Main, 'numeric');
            app.edit_NumSensors = uieditfield(app.panel_Main, 'numeric');
            app.MaxtimestepsinheatpulsedecayLabel = uilabel(app.panel_Main);
            app.MaxtimestepsinheatpulsedecayLabel.Text = 'Max time steps in heat pulse decay:';
            app.edit_HPMaxSteps = uieditfield(app.panel_Main, 'numeric');
            app.TimebeteenthermistorreadingssLabel = uilabel(app.panel_Main);
            app.TimebeteenthermistorreadingssLabel.Text = 'Time between thermistor readings (s): ';
            app.edit_ThermTiming = uieditfield(app.panel_Main, 'numeric');
            app.Watertemperaturethermistor1Yes2NoLabel = uilabel(app.panel_Main);
            app.Watertemperaturethermistor1Yes2NoLabel.Text = {'Water temperature thermistor             '; '(1:Yes   0:No)'};
            app.edit_WaterTempSensor = uieditfield(app.panel_Main, 'numeric');
            app.TimeshiftincrementinheatpulsedecaysLabel = uilabel(app.panel_Main);
            app.TimeshiftincrementinheatpulsedecaysLabel.Text = 'Time shift increment in frictional decay (s):';
            app.edit_HPTimeInc = uieditfield(app.panel_Main, 'numeric');
            app.DistancebetweensensorsmEditFieldLabel = uilabel(app.panel_Main);
            app.DistancebetweensensorsmEditFieldLabel.Text = 'Distance between sensors (m): ';
            app.edit_SensorSpacing = uieditfield(app.panel_Main, 'numeric');
            app.RadiusofsensormLabel = uilabel(app.panel_Main);
            app.RadiusofsensormLabel.Text = 'Radius of sensor (m):';
            app.edit_SensorRadius = uieditfield(app.panel_Main, 'numeric');
            app.AssumedtemperatureerrorCLabel = uilabel(app.panel_Main);
            app.AssumedtemperatureerrorCLabel.Text = 'Assumed temperature error (°C):';
            app.edit_TempError = uieditfield(app.panel_Main, 'numeric');
            app.PulsepowerperlengthJmEditFieldLabel = uilabel(app.panel_Main);
            app.PulsepowerperlengthJmEditFieldLabel.Text = 'Pulse power per length (J/m)';
            app.edit_PulsePower = uieditfield(app.panel_Main, 'numeric');
            app.DurationofheatpulsesEditFieldLabel = uilabel(app.panel_Main);
            app.DurationofheatpulsesEditFieldLabel.Text = 'Duration of heat pulse (s):';
            app.edit_HPDuration = uieditfield(app.panel_Main, 'numeric');
            app.DepthoffirstthermistorbelowweightstandmLabel = uilabel(app.panel_Main);
            app.DepthoffirstthermistorbelowweightstandmLabel.Text = 'Depth of first thermistor below weight stand (m):';
            app.edit_DepthFirstTherm = uieditfield(app.panel_Main, 'numeric');
            app.edit_HyndCo_1 = uieditfield(app.panel_Main, 'numeric');
            app.edit_HyndCo_2 = uieditfield(app.panel_Main, 'numeric');
            app.edit_HyndCo_3 = uieditfield(app.panel_Main, 'numeric');
            app.label_kInit = uilabel(app.panel_Main);
            app.label_kInit.Text = 'Initial thermal conductivity function:';
            app.edit_kInit_1 = uieditfield(app.panel_Main, 'numeric');
            app.edit_kInit_2 = uieditfield(app.panel_Main, 'numeric');
            app.edit_kInit_3 = uieditfield(app.panel_Main, 'numeric');
            app.edit_kInit_4 = uieditfield(app.panel_Main, 'numeric');
            app.edit_kInit_5 = uieditfield(app.panel_Main, 'numeric');
            app.edit_kInit_6 = uieditfield(app.panel_Main, 'numeric');
            app.label_FricTau = uilabel(app.panel_Main);
            app.label_FricTau.Text = 'Frictional decay τ boundaries:';
            app.label_FricTauMin = uilabel(app.panel_Main);
            app.label_FricTauMin.Text = 'Min:';
            app.label_FricTauMax = uilabel(app.panel_Main);
            app.label_FricTauMax.Text = 'Max:';
            app.label_HPTau = uilabel(app.panel_Main);
            app.label_HPTau.Text = 'Heat pulse decay τ boundaries:';
            app.label_HPTauMin = uilabel(app.panel_Main);
            app.label_HPTauMin.Text = 'Min:';
            app.label_HPTauMax = uilabel(app.panel_Main);
            app.label_HPTauMax.Text = 'Max:';
            app.edit_FricTauMin = uieditfield(app.panel_Main, 'numeric');
            app.edit_FricTauMax = uieditfield(app.panel_Main, 'numeric');
            app.edit_HPTauMin = uieditfield(app.panel_Main, 'numeric');
            app.edit_HPTauMax = uieditfield(app.panel_Main, 'numeric');
            app.label_HyndCo = uilabel(app.panel_Main);
            app.label_HyndCo.Text = 'Hyndman Coefficients for k:';
            app.FrictionaltimedelayssLabel = uilabel(app.panel_Main);
            app.FrictionaltimedelayssLabel.Text = 'Frictional time delays (s):';
            app.edit_FricTimeDelay = uieditfield(app.panel_Main, 'numeric');
            app.HeatpulsetimedelayssLabel = uilabel(app.panel_Main);
            app.HeatpulsetimedelayssLabel.Text = '  Heat pulse time delays (s):';
            app.edit_HPTimeDelay = uieditfield(app.panel_Main, 'numeric');
            app.UpdateParametersButton = uibutton(app.panel_Main, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.InitialtimeshiftLabel = uilabel(app.panel_Main);
            app.InitialtimeshiftLabel.Text = 'Time shift increment in heat pulse decay (s):';
            app.edit_TimeShiftInc = uieditfield(app.panel_Main, 'numeric');
            app.InitialtimeshiftLabel_2 = uilabel(app.panel_Main);
            app.InitialtimeshiftLabel_2.Text = 'Initial time shift (s):';
            app.edit_InitTimeShift = uieditfield(app.panel_Main, 'numeric');
            app.TemperaturecalibrationLabel = uilabel(app.panel_Main);
            app.TemperaturecalibrationLabel.Text = 'Temperature calibration:';
            app.MaxstepsinfrictiondecayLabel_2 = uilabel(app.panel_Main);
            app.MaxstepsinfrictiondecayLabel_2.Text = 'Manual offset (°C):';
            app.edit_ManOffset = uieditfield(app.panel_Main, 'numeric');
            app.label_kniit_1 = uilabel(app.panel_Main);
            app.label_kniit_1.Text = 'Does an initial k vs. z function exists (Yes=99)';
            app.edit_kInit_7 = uieditfield(app.panel_Main, 'numeric');
            app.ProbeLengthLabel = uilabel(app.panel_Main);
            app.ProbeLengthLabel.Text = 'Probe Length (m):';
            app.edit_probelength = uieditfield(app.panel_Main, 'numeric');
            app.GeneralprobesetupLabel = uilabel(app.panel_Main);
            app.GeneralprobesetupLabel.Text = 'General probe setup:';
            app.DefaultfunctionsusedfordeterminingkwithnoheatpulseLabel = uilabel(app.panel_Main);
            app.DefaultfunctionsusedfordeterminingkwithnoheatpulseLabel.Text = 'Default functions used for determining k with no heat pulse:';
            app.DimensionlesstimeboundaryconditionsLabel = uilabel(app.panel_Main);
            app.DimensionlesstimeboundaryconditionsLabel.Text = 'Dimensionless time (τ) boundary conditions:';
            app.MaxstepsinfrictiondecayLabel = uilabel(app.panel_Main);
            app.MaxstepsinfrictiondecayLabel.Text = 'Max time steps in frictional decay:';
            app.edit_MaxFricSteps = uieditfield(app.panel_Main, 'numeric');
            app.InitialsensitivityanalysisparametersLabel = uilabel(app.panel_Main);
            app.InitialsensitivityanalysisparametersLabel.Text = 'Initial sensitivity analysis parameters:';
            app.label_SAkBounds = uilabel(app.panel_Main);
            app.label_SAkBounds.Text = 'Thermal conductivity boundaries (W/m°C):';
            app.label_SAMin = uilabel(app.panel_Main);
            app.label_SAMin.Text = 'Min:';
            app.label_SAkMax = uilabel(app.panel_Main);
            app.label_SAkMax.Text = 'Max:';
            app.edit_SAkMin = uieditfield(app.panel_Main, 'numeric');
            app.edit_SAkMax = uieditfield(app.panel_Main, 'numeric');
            app.StandarddeviationinkforsensitivityanalysisLabel = uilabel(app.panel_Main);
            app.StandarddeviationinkforsensitivityanalysisLabel.Text = 'Standard deviation in k (W/m°C):';
            app.edit_SAStandDev = uieditfield(app.panel_Main, 'numeric');
            app.MininumlayerthicknessforsensitivityanalysisLabel = uilabel(app.panel_Main);
            app.MininumlayerthicknessforsensitivityanalysisLabel.Text = 'Mininum layer thickness (m):';
            app.edit_MinThickness = uieditfield(app.panel_Main, 'numeric');
            app.TimeshiftsLabel = uilabel(app.panel_Main);
            app.TimeshiftsLabel.Text = 'Time shifts:';
            app.edit_SAIterNum = uieditfield(app.panel_Main, 'numeric');
            app.NumberofIterationsforsensitivityanalysisLabel = uilabel(app.panel_Main);
            app.NumberofIterationsforsensitivityanalysisLabel.Text = 'Number of iterations for sensitivity analysis:';
            app.HorizontalthermalconductivityanisotropyEditFieldLabel = uilabel(app.panel_Main);
            app.HorizontalthermalconductivityanisotropyEditFieldLabel.Text = 'Horizontal thermal conductivity anisotropy:';
            app.edit_HorizkAnisotropy = uieditfield(app.panel_Main, 'numeric');
            app.TimeboundaryconditionsLabel = uilabel(app.panel_Main);
            app.TimeboundaryconditionsLabel.Text = 'Iterating between temperature and thermal conductivity determinations:';
            app.ErrortoleranceonthermalconductivityiterationEditFieldLabel = uilabel(app.panel_Main);
            app.ErrortoleranceonthermalconductivityiterationEditFieldLabel.Text = 'Error tolerance on thermal conductivity iteration:';
            app.edit_ErrorTolerance = uieditfield(app.panel_Main, 'numeric');
            app.MinimumchangeofSigmakLabel = uilabel(app.panel_Main);
            app.MinimumchangeofSigmakLabel.Text = 'Minimum k change between iteration for convergence (W/m°C): ';
            app.edit_MinSigChange = uieditfield(app.panel_Main, 'numeric');
            app.MinimumchangeofSigmakLabel_2 = uilabel(app.panel_Main);
            app.MinimumchangeofSigmakLabel_2.Text = 'Maximum number of iterations for k computations:';
            app.edit_MaxkIter = uieditfield(app.panel_Main, 'numeric');
            % Show the figure after all components are created
            app.figure_SetParams.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_SetParams)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_SetParams)
        end
    end
end