classdef SetUpSensAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_SensAnalSetUp          matlab.ui.Figure
        grid_histograms               matlab.ui.container.GridLayout
        GridLayout                    matlab.ui.container.GridLayout
        panel_runanalysis_2           matlab.ui.container.Panel
        DistributionTypeLabel         matlab.ui.control.Label
        panel_distributionchoices     matlab.ui.container.ButtonGroup
        buttongroup_distributiontype  matlab.ui.container.ButtonGroup
        button_none                   matlab.ui.control.RadioButton
        button_gamma                  matlab.ui.control.RadioButton
        button_normal                 matlab.ui.control.RadioButton
        panel_runanalysis             matlab.ui.container.Panel
        label_excludedT               matlab.ui.control.Label
        label_excludedT_title         matlab.ui.control.Label
        label_excludedT_desc          matlab.ui.control.Label
        button_runsensanalysis        matlab.ui.control.Button
        panel_setupsens               matlab.ui.container.Panel
        label_NOTusingHP_5            matlab.ui.control.Label
        dropdown_sensor               matlab.ui.control.DropDown
        SensorLabel                   matlab.ui.control.Label
        edit_mink                     matlab.ui.control.NumericEditField
        MinimumthermalconductivityWmCLabel_2  matlab.ui.control.Label
        label_NOTusingHP_4            matlab.ui.control.Label
        button_resetparams            matlab.ui.control.Button
        edit_numbins                  matlab.ui.control.NumericEditField
        NumberofbinsLabel             matlab.ui.control.Label
        button_updateparams           matlab.ui.control.Button
        table_inputdata               matlab.ui.control.Table
        label_NOTusingHP_3            matlab.ui.control.Label
        edit_kanisotropy              matlab.ui.control.NumericEditField
        HorizontalthermalconductivityanisotropyLabel  matlab.ui.control.Label
        edit_minthickness             matlab.ui.control.NumericEditField
        MinimumlayerthicknessmLabel   matlab.ui.control.Label
        edit_maxk                     matlab.ui.control.NumericEditField
        MaximumthermalconductivityWmCLabel  matlab.ui.control.Label
        edit_meank                    matlab.ui.control.NumericEditField
        MinimumthermalconductivityWmCLabel  matlab.ui.control.Label
        edit_standdev                 matlab.ui.control.NumericEditField
        StandarddeviationinthermalconductivityLabel  matlab.ui.control.Label
        edit_numIter                  matlab.ui.control.NumericEditField
        NumberofrealizationsLabel     matlab.ui.control.Label
        line                          matlab.ui.control.Label
        grid_histogramplots           matlab.ui.container.GridLayout
        panel_normal                  matlab.ui.container.Panel
        grid_normal                   matlab.ui.container.GridLayout
        axes_normal                   matlab.ui.control.UIAxes
        panel_gamma                   matlab.ui.container.Panel
        grid_gamma                    matlab.ui.container.GridLayout
        axes_gamma                    matlab.ui.control.UIAxes
        panel_hidehistograms          matlab.ui.container.Panel
    end

    
    properties (Access = private)
        
        % App
        CallingApp 

        % Action parameters
        RunAnalysis
        UseFrictional

        % Initial distribution parameters
        kDistribution
        r
        RandomNormal
        RandomGamma
        histnormal
        histgamma
        MinThickness
        k0
        Sigmak0
        kMin
        kMax
        kAnisotropy
        kMaxNorm
        Bins
        MaxSAIterations
        DefaultSigmak0
        DefaultkMin
        DefaultkMax        
        DefaultBins 
        DefaultMaxSAIterations 
        DefaultMinThickness 
        BadT

        % For defining sensors
        CurrentSensor
        SensorsToUse
        NumberOfSensors

        % For plitting
        HistogramPlotGrid
        panels_histaxes
        orighist

        % Lines
        normMax
        normMin
        normMean
        gammMin
        gammMean

    end
    
    methods (Access = private)
        
        function distibutionsEachSens(app)                   
        
        % ==============================================================
        %% This is called to generate distributions for thermal 
        %% conductivity for EACH sensor separately. This includes 
        %% plotting each distribution for the user to see before running 
        %% analysis.
        % ==============================================================
            
        %% Generate and plot k distribution 

                for i=app.CurrentSensor
                
                % Normal distributions
                % --------------------

                    % Make a normal distribution with the mean 1 
                    % (a typical therm con value) and std of 0.1
                    pd = makedist('normal', app.k0(i), app.Sigmak0(i));
                    
                    % Truncate the normal distributiom between the minimum 
                    % and maximum k values determined by the user
                    pd = truncate(pd, app.kMin(i), app.kMax(i));
            
                    % Generate random numbers based on this distribution. 
                    % This is a 100x11 matrix for 100 iterations x number 
                    % of sensors used
                    app.RandomNormal(:,i) = random(pd,app.MaxSAIterations,1);
    
                    % Plot histograms separtely
                    % ------------------------
                    % delete current plots
                    delete(app.normMax{i})
                    delete(app.normMin{i})
                    delete(app.normMean{i})
    
                    % plot new 
                    app.histnormal{i} = histfit(app.axes_normal, app.RandomNormal(:,i),app.Bins(i),'normal', 'Visible', 'on');
                    app.histnormal{i}(1).FaceColor = app.orighist{i}(1).FaceColor;
                    app.normMax{i} = xline(app.axes_normal, app.kMaxNorm(i), '--k', 'LineWidth',3,'Label', 'Max k');
                    app.normMin{i} = xline(app.axes_normal, app.kMin(i), '--k', 'LineWidth',3, 'Label', 'Min k');
                    app.normMean{i} = xline(app.axes_normal, app.k0(i), ':m', 'LineWidth',3, 'Label', 'Mean k');
                    hold(app.axes_normal, 'on')

                end 

                for i=app.CurrentSensor

                % Gamma distributions
                % --------------------
                   
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        % Calculate shape parameter (a) and scale 
                        % parameter (b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        
                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin(i), app.kMax(i));
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);
               
    
                    % Plot histograms separtely
                    % -------------------------              
                    % delete current plots
                    delete(app.histgamma{i})
                    delete(app.gammMin{i})
                    delete(app.gammMean{i})
                    
                    % plot new 
                    app.histgamma{i} = histfit(app.axes_gamma,app.RandomGamma(:,i),app.Bins(i),'gamma', 'Visible', 'on');
                    app.histgamma{i}(1).FaceColor = app.orighist{i}(1).FaceColor;
                    app.gammMin{i} = xline(app.axes_gamma, app.kMin(i), '--k', 'LineWidth',3, 'Label', 'Min k');
                    app.gammMean{i} = xline(app.axes_gamma, app.k0(i), ':m','LineWidth',3, 'Label', 'Mean k');
                    hold(app.axes_gamma, 'on')

                end 

                % Set limits to axes
                i = app.CurrentSensor;
                min_x = min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i));
                max_x = max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMaxNorm(i));
                app.axes_normal.XLim = [min_x-.05 max_x+.05];
                app.axes_gamma.XLim = [min_x-.05 max_x+.05];
   
                % link axes
                ax=[app.axes_gamma, app.axes_normal];
                linkaxes(ax,'x');
                
                % turn off plots where sensors are ignored
                if i ~= app.CurrentSensor
                   app.histnormal{i}(1).Visible = 'off';
                   app.histnormal{i}(2).Visible = 'off';
                   app.normMax{i}.Visible = 'off';
                   app.normMin{i}.Visible = 'off';
                   app.normMean{i}.Visible = 'off';
                   app.histgamma{i}(1).Visible = 'off';
                   app.histgamma{i}(2).Visible = 'off';
                   app.gammMin{i}.Visible = 'off';
                   app.gammMean{i}.Visible = 'off';
                end


            %% Generate a table of all distribtion parameters used
            % ====================================================
                Sensors = app.SensorsToUse';
                Meank = app.k0(app.SensorsToUse)';
                STDk = app.Sigmak0(app.SensorsToUse)';
                AllMink = app.kMin(app.SensorsToUse)';
                AllMaxk = app.kMax(app.SensorsToUse)';
                AllBins = app.Bins(app.SensorsToUse)';
                [nrs,~] = size(Sensors);
                [nrk,~] = size(Meank);
                if nrs~=nrk
                    Sensors = Sensors';
                end
                T = table(Sensors, Meank,STDk, AllMink, AllMaxk, AllBins);
                app.table_inputdata.Data = T;
        end

        function distibutionsAllSens(app)

        % ==============================================================
        % This is called to generate distributions for thermal 
        % conductivity for ALL sensors together. This includes
        % plotting each distribution for the user to see before running 
        % analysis.
        % ==============================================================

        %% Initialize random distributions
                app.RandomNormal = NaN*zeros(app.MaxSAIterations, app.NumberOfSensors);
                app.RandomGamma = NaN*zeros(app.MaxSAIterations, app.NumberOfSensors);        
        
        %% Generate and plot k distribution 

                % Normal distributions
                % --------------------

                for i=1:(app.NumberOfSensors) % need to make distributions for ALL sensors for indexing purposes
                    
                    % Make a normal distribution with the mean = the calculated k and std of 0.1
                    pd = makedist('normal', app.k0(i), app.Sigmak0(i));
                    
                    % Truncate the normal distributiom between the minimum and maximum 
                    % k values determined by the user
                    pd = truncate(pd, app.kMin(i), app.kMax(i));
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomNormal(:,i) = random(pd,app.MaxSAIterations,1);
                end

                for i=app.SensorsToUse
    
                    % Plot histograms separtely for only USED sensors
                    % ----------------------------------------------
                    % delete current plots
                    delete(app.normMax{i})
                    delete(app.normMin{i})
                    delete(app.normMean{i})
    
                    % % plot new 
                    app.histnormal{i} = histfit(app.axes_normal, app.RandomNormal(:,i),app.Bins(i),'normal', 'Visible', 'on');
                    app.histnormal{i}(1).FaceColor = app.orighist{i}(1).FaceColor;
                    app.normMax{i} = xline(app.axes_normal, app.kMaxNorm(i), '--k', 'LineWidth',3,'Label', 'Max k');
                    app.normMin{i} = xline(app.axes_normal, app.kMin(i), '--k', 'LineWidth',3, 'Label', 'Min k');
                    app.normMean{i} = xline(app.axes_normal, app.k0(i), ':m', 'LineWidth',3, 'Label', 'Mean k');
                    hold(app.axes_normal, 'on')

                end 

                for i=1:app.NumberOfSensors 
                
                    % Gamma distributions
                    % --------------------
                    
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        %Calculate shape parameter (a) and scale parameter
                        %(b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        

                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin(i), app.kMax(i));
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);

                end
    
                for i=app.SensorsToUse   

                    % Plot histograms separtely for only USED sensors
                    % ---------------------------------------------- 
                    % delete current plots
                    delete(app.histgamma{i})
                    delete(app.gammMin{i})
                    delete(app.gammMean{i})
                    
                    % plot new 
                    app.histgamma{i} = histfit(app.axes_gamma,app.RandomGamma(:,i),app.Bins(i),'gamma', 'Visible', 'on');
                    app.histgamma{i}(1).FaceColor = app.orighist{i}(1).FaceColor;
                    app.gammMin{i} = xline(app.axes_gamma, app.kMin(i), '--k', 'LineWidth',3, 'Label', 'Min k');
                    app.gammMean{i} = xline(app.axes_gamma, app.k0(i), ':m','LineWidth',3, 'Label', 'Mean k');
                    hold(app.axes_gamma, 'on')
    
                    % turn off plots where sensors are ignored
                    if i ~= app.CurrentSensor
                        app.histnormal{i}(1).Visible = 'off';
                        app.histnormal{i}(2).Visible = 'off';
                        app.normMax{i}.Visible = 'off';
                        app.normMin{i}.Visible = 'off';
                        app.normMean{i}.Visible = 'off';
                        app.histgamma{i}(1).Visible = 'off';
                        app.histgamma{i}(2).Visible = 'off';
                        app.gammMin{i}.Visible = 'off';
                        app.gammMean{i}.Visible = 'off';
                    end

                end 

                 % Set limits to axes
                 i = app.CurrentSensor;
                 app.axes_normal.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMaxNorm(i))+.05];
                 app.axes_gamma.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMaxNorm(i))+.05];
    
                 % link axes
                 ax=[app.axes_gamma, app.axes_normal];
                 linkaxes(ax,'x');             


   %% Generate a table of all distribtion parameters used

                Sensors = app.SensorsToUse';
                Meank = app.k0(app.SensorsToUse)';
                STDk = app.Sigmak0(app.SensorsToUse)';
                AllMink = app.kMin(app.SensorsToUse)';
                AllMaxk = app.kMax(app.SensorsToUse)';
                AllBins = app.Bins(app.SensorsToUse)';
                [nrs,~] = size(Sensors);
                [nrk,~] = size(Meank);
                if nrs~=nrk
                    Sensors = Sensors';
                end

                T = table(Sensors, Meank,STDk, AllMink, AllMaxk, AllBins);
                app.table_inputdata.Data = T;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NumberOfSensors, SensorsToUse, kToUse, k0, PulseData, MaxSAIterations, Sigmak0, kMin, kMax, MinThickness, kAnisotropy, Bins, BadT)
% ==============================================================
%% Automatically called when this application is generated. 
%% Includes setting up the GUI 
% ==============================================================                          
            
%% Initialize GUI

            app.CallingApp = mainapp;
            app.figure_SensAnalSetUp.WindowStyle = 'modal';
            movegui(app.figure_SensAnalSetUp, "center");

%% Initialize input parameters

            app.SensorsToUse = kToUse';            
            app.k0 = round(k0,3);
            app.Sigmak0 = Sigmak0;
            app.kMin = kMin;
            app.kMax = kMax;
            app.kMaxNorm = app.kMax;
            app.Bins = Bins; 
            app.MaxSAIterations = MaxSAIterations;   
            app.MinThickness = MinThickness;
            app.kAnisotropy = kAnisotropy;
            app.NumberOfSensors = NumberOfSensors;
            AllSensFactors = ones(1,app.NumberOfSensors);    
            app.BadT = BadT;

            if app.BadT~=0
                new =['T' num2str(app.BadT(1))];
                app.label_excludedT.Text = new;
                for i = 2:length(app.BadT)
                    new = [new, ', T' num2str(app.BadT(i))]; %#ok<AGROW>
                    app.label_excludedT.Text = new;
                end
            end

            if length(app.kMax) == 1
                app.Sigmak0 = app.Sigmak0.*AllSensFactors;
                app.kMin = app.kMin.*AllSensFactors;
                app.kMax = app.kMax.*AllSensFactors;
                app.kMaxNorm = app.kMaxNorm.*AllSensFactors;
                app.Bins = app.Bins.*AllSensFactors;
            end

            if length(app.Bins) == 1
                app.Bins = app.Bins.*AllSensFactors;
            end
        
            app.NumberOfSensors = NumberOfSensors;

            % Initiate sensor dropdown button
            app.dropdown_sensor.Items = string(app.SensorsToUse);
            app.dropdown_sensor.Value = app.dropdown_sensor.Items(1);
            app.CurrentSensor = str2double(app.dropdown_sensor.Value);
            
            app.edit_meank.Value = app.k0(app.CurrentSensor);
            app.edit_minthickness.Value = app.MinThickness;
            app.edit_maxk.Value = app.kMax(app.CurrentSensor);
            app.edit_mink.Value = app.kMin(app.CurrentSensor);
            app.edit_standdev.Value = app.Sigmak0(app.CurrentSensor);
            app.edit_numIter.Value = app.MaxSAIterations;
            app.edit_kanisotropy.Value = app.kAnisotropy;
            app.edit_numbins.Value = app.Bins(app.CurrentSensor); 

            % Set this paramater to 0 until the button 'Run Sensitivity
            % Analysis' is pressed. This way if the user x's out of this
            % app, the sensitivity analysis will not run.
            app.RunAnalysis = 0;
            

            % Generate a distribution of random conductivities for each sensor
            %   mu: initial k calculated in heat pulse analysis
            %   sigma: standard deviation decided by user
            %   truncation: min and max k decided by user 
            % ------------------------------------------------------------
            
            % Initialize arrays
            app.r = NaN*zeros(app.MaxSAIterations, app.NumberOfSensors);
            app.RandomNormal = NaN*zeros(app.MaxSAIterations, app.NumberOfSensors);
            app.RandomGamma = NaN*zeros(app.MaxSAIterations, app.NumberOfSensors);
           
           
            % Table of all input data
            % -----------------------
            Sensors = app.SensorsToUse';
            Meank = app.k0(app.SensorsToUse)';
            STDk = app.Sigmak0(app.SensorsToUse)';
            AllMink = app.kMin(app.SensorsToUse)';
            AllMaxk = app.kMax(app.SensorsToUse)';
            AllBins = app.Bins(app.SensorsToUse)';
            [nrs,~] = size(Sensors);
            [nrk,~] = size(Meank);
            if nrs~=nrk
                Sensors = Sensors';
            end
            T = table(Sensors, Meank,STDk, AllMink, AllMaxk, AllBins);
            app.table_inputdata.Data = T;

    
%% Thermal conductivity: Plot all distributions and calculate 
%% random distributions 

                % Highlight axes with NORMAL distribution
                app.panel_normal.BorderType = 'line';
                app.panel_gamma.BorderType = 'none';
                app.panel_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.panel_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.grid_gamma.BackgroundColor = [.94 .94 .94]; % make background white
            
                for i=1:app.NumberOfSensors % make distributions for ALL sensors. Ignored sensors will be ignored later, but need to be created for indexing purposed
                    
                    % Make a normal distribution with the mean 1 (a typical therm con value) and std of 0.1
                    pd = makedist('normal', app.k0(i), app.Sigmak0(i));
                    
                    % Truncate the normal distributiom between the minimum and maximum 
                    % k values determined by the user
                    pd = truncate(pd, app.kMin(i), app.kMax(i));
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomNormal(:,i) = random(pd,app.MaxSAIterations,1);

                end  

                [nr,~] = size(app.SensorsToUse);
                if nr~=1
                    app.SensorsToUse=app.SensorsToUse';
                end

                for i = app.SensorsToUse
    
                    % Plot histograms for only USED sensors separtely 
                    % -----------------------------------------------
                    % Construct a histogram using 100 bins with a normal distribution fit. This is only a histogram of the 1ST column of r (a random therm con value for each iteration for the 1st sensor)
                    app.histnormal{i} = histfit(app.axes_normal, app.RandomNormal(:,i),app.Bins(i),'normal');
                    app.normMax{i} = xline(app.axes_normal, app.kMaxNorm(i), '--k', 'LineWidth',3,'Label', 'Max k');
                    app.normMin{i} = xline(app.axes_normal, app.kMin(i), '--k', 'LineWidth',3, 'Label', 'Min k');
                    app.normMean{i} = xline(app.axes_normal, app.k0(i), ':m', 'LineWidth',3, 'Label', 'Mean k');
                    app.orighist = app.histnormal;
    
                    if i > 1
                       app.histnormal{i}(1).Visible = 'off';
                       app.histnormal{i}(2).Visible = 'off';
                       app.normMax{i}.Visible = 'off';
                       app.normMin{i}.Visible = 'off';
                       app.normMean{i}.Visible = 'off';
                    end
                    hold(app.axes_normal, 'on')
    
                end 

                for i=1:app.NumberOfSensors % make distributions for ALL sensors. Ignored sensors will be ignored later, but need to be created for indexing purposed
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        %Calculate shape parameter (a) and scale parameter
                        %(b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        

                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin(i), app.kMax(i));
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);

                end

                for i = app.SensorsToUse
    
                    % Plot histograms separtely for only USED sensors
                    % -----------------------------------------------                                       
                    app.histgamma{i} = histfit(app.axes_gamma,app.RandomGamma(:,i),app.Bins(i),'gamma', 'FaceColor', app.orighist{i}(1).FaceColor);
                    app.gammMin{i} = xline(app.axes_gamma, app.kMin(i), '--k', 'LineWidth',3, 'Label', 'Min k');
                    app.gammMean{i} = xline(app.axes_gamma, app.k0(i), ':m','LineWidth',3, 'Label', 'Mean k');
                    if i > 1
                       app.histgamma{i}(1).Visible = 'off';
                       app.histgamma{i}(2).Visible = 'off';
                       app.gammMin{i}.Visible = 'off';
                       app.gammMean{i}.Visible = 'off';
                    end
                    hold(app.axes_gamma, 'on')

                end 

                 % Set limits to axes
                 i = app.CurrentSensor;
                 app.axes_normal.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMax(i))+.05];
                 app.axes_gamma.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMax(i))+.05];
    
                 % Link axes
                 ax=[app.axes_gamma, app.axes_normal];
                 linkaxes(ax,'x');

                % Initially set to normal distribution of k
                app.kDistribution = 1;
                app.r = app.RandomNormal;
                app.UseFrictional = 1;

                % Allow user to now run the analysis
                app.button_runsensanalysis.Enable="on";

        end

        % Button pushed function: button_runsensanalysis
        function button_runsensanalysisPushed(app, event)
% =================================================
%% Called when the Run Analysis button is pressed.
% =================================================
            
            % Determine whether to use the normal or gamma distribution 
            % (or no distribution)
            % ---------------------------------
            if app.kDistribution == 1
                app.r = app.RandomNormal;
            elseif app.kDistribution == 2
                app.r = app.RandomGamma;
            elseif app.kDistribution == 3
                app.r = app.k0;
            end

            app.RunAnalysis = 1;
            
    %% Call a public funciton in the main application for setting 
    %% sensitivity analysis parameter distributions to be used.

            setSens(app.CallingApp, ...
                app.MaxSAIterations, ...
                app.Sigmak0, ...
                app.kMin, ...
                app.kMax, ...
                app.MinThickness, ...
                app.kAnisotropy, ...
                app.kDistribution, ...
                app.r, ...
                app.k0,...
                app.RunAnalysis, ...
                app.Bins, ...
                app.UseFrictional)

            delete(app)

        end

        % Selection changed function: buttongroup_distributiontype
        function buttongroup_distributiontypeSelectionChanged(app, event)
% ===========================================================
%% Called when the distribution type button group is changed.
% ===========================================================

            if app.button_normal.Value == 1

                % Highlight axes with NORMAL distribution
                app.panel_normal.BorderType = 'line';
                app.panel_gamma.BorderType = 'none';
                app.panel_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.panel_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_normal.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.grid_gamma.BackgroundColor = [.94 .94 .94]; % make background white

                % Default for normal distribution is kMax from .par file
                app.edit_maxk.Value = app.kMaxNorm(app.CurrentSensor);
                app.kMax(:) = app.kMaxNorm(:);

                % Enable edit fields
                app.edit_meank.Enable = 'on';
                app.edit_mink.Enable = 'on';
                app.edit_maxk.Enable = 'on';
                app.edit_standdev.Enable = 'on';
                app.edit_numbins.Enable = 'on';
                app.table_inputdata.Enable = 'on';

                % Enable buttons
                app.button_resetparams.Enable = 'on';
                app.button_updateparams.Enable = 'on';

                % show histogram plots
                app.grid_histogramplots.ColumnWidth = {'1x', '0x'};

                app.UseFrictional = 1;

                app.kDistribution = 1;

                % Update distributions function
                app.distibutionsEachSens

                app.r = app.RandomNormal;

            end

            if app.button_gamma.Value == 1

                % Highlight axes with GAMMA distribution
                app.panel_gamma.BorderType = 'line';
                app.panel_normal.BorderType = 'none';
                app.panel_gamma.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.panel_normal.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_gamma.BackgroundColor = [0.47,0.67,0.19]; % make background green
                app.grid_normal.BackgroundColor = [.94 .94 .94]; % make background white
                
                % Default for gamma distribution is no max k
                app.edit_maxk.Value = Inf;
                app.kMax(:) = Inf;

                % Enable edit fields
                app.edit_meank.Enable = 'on';
                app.edit_mink.Enable = 'on';
                app.edit_maxk.Enable = 'on';
                app.edit_standdev.Enable = 'on';
                app.edit_numbins.Enable = 'on';
                app.table_inputdata.Enable = 'on';

                % Enable buttons
                app.button_resetparams.Enable = 'on';
                app.button_updateparams.Enable = 'on';

                % show histogram plots
                app.grid_histogramplots.ColumnWidth = {'1x', '0x'};

                app.UseFrictional = 1;

                app.kDistribution = 2;

               % Make gamma distributions
               for i=app.CurrentSensor
                    % Make a gamma distribution with the mean as the
                    % initial thermal conductivty value and std determined
                    % by user
                    
                        %Calculate shape parameter (a) and scale parameter
                        %(b)

                        a = ((app.k0(i))^2) / (app.Sigmak0(i))^2;
                        b = ((app.Sigmak0(i))^2) / (app.k0(i)) ;
                        

                    pd = makedist('gamma', 'a', a, 'b', b);
                    
                    % Truncate the left side of the gamma distributiom
                    % between the minimum k value determined by user
                    pd = truncate(pd, app.kMin(i), app.kMax(i));
            
                    % Generate random numbers based on this distribution. This is a 100x11
                    % matrix for 100 iterations x number of sensors used
                    app.RandomGamma(:,i) = random(pd,app.MaxSAIterations,1);
               end
               
                app.r = app.RandomGamma;

            end

            if app.button_none.Value == 1

                % update GUI
                app.panel_gamma.BorderType = 'none';
                app.panel_normal.BorderType = 'none';
                app.panel_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.panel_normal.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_gamma.BackgroundColor = [.94 .94 .94]; % make background white
                app.grid_normal.BackgroundColor = [.94 .94 .94]; % make background white

                % Disable edit fields
                app.edit_meank.Enable = 'off';
                app.edit_mink.Enable = 'off';
                app.edit_maxk.Enable = 'off';
                app.edit_standdev.Enable = 'off';
                app.edit_numbins.Enable = 'off';
                app.table_inputdata.Enable = 'off';

                % Disable buttons    
                app.button_resetparams.Enable = 'off';
                app.button_updateparams.Enable = 'off';

                % hide histogram plots
                app.grid_histogramplots.ColumnWidth = {'0x', '1x'};

                app.UseFrictional = 0; % equilibrium temps NOT recalculated with no k value distribution
                
                app.kDistribution = 3;
                

            end

            % Update table of all input data
            % -----------------------
            Sensors = app.SensorsToUse';
            Meank = app.k0(app.SensorsToUse)';
            STDk = app.Sigmak0(app.SensorsToUse)';
            AllMink = app.kMin(app.SensorsToUse)';
            AllMaxk = app.kMax(app.SensorsToUse)';
            AllBins = app.Bins(app.SensorsToUse)';
            [nrs,~] = size(Sensors);
            [nrk,~] = size(Meank);
            if nrs~=nrk
                Sensors = Sensors';
            end
            T = table(Sensors, Meank,STDk, AllMink, AllMaxk, AllBins);
            app.table_inputdata.Data = T;

            % Set limits to axes
            i = app.CurrentSensor;
            app.axes_normal.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMaxNorm(i))+.05];
            app.axes_gamma.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMaxNorm(i))+.05];
            ax=[app.axes_gamma, app.axes_normal];
            linkaxes(ax,'x');

        end

        % Value changed function: dropdown_sensor
        function dropdown_sensorValueChanged(app, event)
% ===========================================================
%% Called when the sensor selection dropdown menu is changed.
% ===========================================================

            % Set which sensor we are changing
            sens = str2double(app.dropdown_sensor.Value);

            % Turn off old sensor plot
            app.histnormal{app.CurrentSensor}(1).Visible = 'off';
            app.histnormal{app.CurrentSensor}(2).Visible = 'off';

            app.histgamma{app.CurrentSensor}(1).Visible = 'off';
            app.histgamma{app.CurrentSensor}(2).Visible = 'off';

            app.normMax{app.CurrentSensor}.Visible = 'off';
            app.normMin{app.CurrentSensor}.Visible = 'off';
            app.normMean{app.CurrentSensor}.Visible = 'off';

            app.gammMin{app.CurrentSensor}.Visible = 'off';
            app.gammMean{app.CurrentSensor}.Visible = 'off';


            % Turn on new sensor plot
            app.histnormal{sens}(1).Visible = 'on';
            app.histnormal{sens}(2).Visible = 'on';
            
            app.histgamma{sens}(1).Visible = 'on';
            app.histgamma{sens}(2).Visible = 'on';

            app.normMax{sens}.Visible = 'on';
            app.normMin{sens}.Visible = 'on';
            app.normMean{sens}.Visible = 'on';

            app.gammMin{sens}.Visible = 'on';
            app.gammMean{sens}.Visible = 'on';

            % Update text edit field values for parameter distributions
            app.edit_meank.Value = app.k0(sens);
            app.edit_maxk.Value = app.kMax(sens);
            app.edit_mink.Value = app.kMin(sens);
            app.edit_standdev.Value = app.Sigmak0(sens);
            app.edit_numbins.Value = app.Bins(sens);

            % Update the current sensor 
            app.CurrentSensor = sens;

            % Set limits to axes
            i = app.CurrentSensor;
            app.axes_normal.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMaxNorm(i))+.05];
            app.axes_gamma.XLim = [min(min(min(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMin(i))-.05 max(max(max(app.RandomGamma(:,i), app.RandomNormal(:,i))), app.kMaxNorm(i))+.05];
   
            % Link axes
            ax=[app.axes_gamma, app.axes_normal];
            linkaxes(ax,'x');

        end

        % Value changed function: edit_maxk
        function edit_maxkValueChanged(app, event)
% ======================================
%% Called when max k value is changed.
% ====================================== 
           
        % If the new max is lower than the current min, tell user the max 
        % is too low
        if app.edit_maxk.Value <= app.kMin(app.CurrentSensor)
            uialert(app.figure_SensAnalSetUp, ...
                ['Thermal conductivity maximum too low. Must be higher ' ...
                'than the thermal conductivity minimum'], 'TOO LOW', 'Icon','error')
            app.edit_maxk.Value = app.kMax(app.CurrentSensor);
            return
        end

        % Set the max k value for the current sensor to be the new value 
        % in the text edit field
        app.kMax(app.CurrentSensor) = app.edit_maxk.Value;
        app.kMaxNorm(app.CurrentSensor) = app.edit_maxk.Value;

        % Turn off histograms that use old max for the current sensor
        app.histnormal{app.CurrentSensor}(1).Visible = 'off';
        app.histnormal{app.CurrentSensor}(2).Visible = 'off';
        app.histgamma{app.CurrentSensor}(1).Visible = 'off';
        app.histgamma{app.CurrentSensor}(2).Visible = 'off';
        app.normMax{app.CurrentSensor}.Visible = 'off';

        % Update distributions function to reflect the new k max 
        app.distibutionsEachSens

        end

        % Value changed function: edit_meank
        function edit_meankValueChanged(app, event)
% ======================================
%% Called when mean k value is changed.
% ====================================== 
        
        % Set the mean k value for the current sensor to be the new value 
        % in the text edit field
        app.k0(app.CurrentSensor) = app.edit_meank.Value;

        % Turn off histograms that use old max for the current sensor
        app.histnormal{app.CurrentSensor}(1).Visible = 'off';
        app.histnormal{app.CurrentSensor}(2).Visible = 'off';
        app.histgamma{app.CurrentSensor}(1).Visible = 'off';
        app.histgamma{app.CurrentSensor}(2).Visible = 'off';
        app.normMin{app.CurrentSensor}.Visible = 'off';
        app.gammMin{app.CurrentSensor}.Visible = 'off';
        
        % Update distributions function to reflect the new k mean
        app.distibutionsEachSens

        end

        % Value changed function: edit_standdev
        function edit_standdevValueChanged(app, event)
% ======================================
%% Called when mean k value is changed.
% ====================================== 

        % Set the standard deviation in k for the current sensor to be the 
        % new value in the text edit field
        app.Sigmak0(app.CurrentSensor) = app.edit_standdev.Value;

        % Ensure there is a standard deviation set for all sensors
        if size(app.Sigmak0) == 1
            SigmakFactors = ones(1,length(app.SensorsToUse));
            app.Sigmak0 = app.Sigmak0.*SigmakFactors;    
        end

        % Turn off histograms that use old max for the current sensor
        app.histnormal{app.CurrentSensor}(1).Visible = 'off';
        app.histnormal{app.CurrentSensor}(2).Visible = 'off';
        app.histgamma{app.CurrentSensor}(1).Visible = 'off';
        app.histgamma{app.CurrentSensor}(2).Visible = 'off';
            
        % Update distributions function to reflect the new k standard
        % deviation
        app.distibutionsEachSens
        
        end

        % Button pushed function: button_updateparams
        function button_updateparamsPushed(app, event)
% ====================================================================
%% Called when the button for updating all parameters to match the 
%% current values is pressed.
% ====================================================================

        % For all sensors, set all parameters to those matching the 
        % current values in the text edit fields of the GUI
         for i = app.SensorsToUse
             app.Sigmak0(i) = app.edit_standdev.Value;
             app.kMin(i) = app.edit_mink.Value;
             app.kMax(i) = app.edit_maxk.Value;
             app.Bins(i) = app.edit_numbins.Value;
                
             % Turn off all current histogram plots
             app.histnormal{i}(1).Visible = 'off';
             app.histnormal{i}(2).Visible = 'off';
             app.histgamma{i}(1).Visible = 'off';
             app.histgamma{i}(2).Visible = 'off';

         end
         app.kMaxNorm(app.SensorsToUse) = app.kMax(app.SensorsToUse);

        % Update distributions function to reflect the new parameters
        app.distibutionsAllSens

        end

        % Button pushed function: button_resetparams
        function button_resetparamsPushed(app, event)
% ====================================================================
%% Called when the button for resetting all parameters their default 
%% values current values is pressed.
% ====================================================================

        % Define default parameters
        app.DefaultSigmak0 = 0.1;
        app.DefaultkMin = 0.6;
        app.DefaultkMax = 1.5;
        app.DefaultBins = 25;
        app.DefaultMaxSAIterations = 100;
        app.DefaultMinThickness = 0.5;     
        
        % For all sensors, reset current parameters to default values
        for i = app.SensorsToUse
            app.MaxSAIterations = app.DefaultMaxSAIterations;
            app.edit_numIter.Value = app.MaxSAIterations;
            app.MinThickness = app.DefaultMinThickness;
            app.edit_minthickness.Value = app.MinThickness;
            app.Sigmak0(i) = app.DefaultSigmak0;
            app.edit_standdev.Value = app.DefaultSigmak0;
            app.kMin(i) = app.DefaultkMin;
            app.edit_mink.Value = app.DefaultkMin;
            if app.kDistribution == 1
                app.kMax(i) = app.DefaultkMax;
                app.edit_maxk.Value  = app.DefaultkMax;
            elseif app.kDistribution == 2
                app.kMax(i) = Inf;
                app.edit_maxk.Value = Inf;
            end
            app.edit_numbins.Value = app.DefaultBins;
            app.Bins(i) = app.DefaultBins;
        
         % Hide all current histograms
         app.histnormal{i}(1).Visible = 'off';
         app.histnormal{i}(2).Visible = 'off';
         app.histgamma{i}(1).Visible = 'off';
         app.histgamma{i}(2).Visible = 'off';
        end
        
        app.kMaxNorm(app.SensorsToUse) = app.kMax(app.SensorsToUse);

        % Update distributions function to reflect the default parameters
        app.distibutionsAllSens

        end

        % Value changed function: edit_numIter
        function edit_numIterValueChanged(app, event)
% ====================================================================
%% Called when the value for number of realizations to run is changed.
% ====================================================================

            % Update maximum number of realizations
            app.MaxSAIterations = app.edit_numIter.Value;

            for i = app.SensorsToUse
                % Hide all current histograms
                 app.histnormal{i}(1).Visible = 'off';
                 app.histnormal{i}(2).Visible = 'off';
                 app.histgamma{i}(1).Visible = 'off';
                 app.histgamma{i}(2).Visible = 'off';
            end
            
            % Update distributions function to incorporate new number of
            % realizations
            app.distibutionsAllSens

        end

        % Value changed function: edit_minthickness
        function edit_minthicknessValueChanged(app, event)
% =================================================================
%% Called when the value for minimum layer thickness is changed.
% =================================================================

            % Update minimum thickness value
            app.MinThickness = app.edit_minthickness.Value;

            % Hide all current histograms for current sensor
            app.histnormal{app.CurrentSensor}(1).Visible = 'off';
            app.histnormal{app.CurrentSensor}(2).Visible = 'off';
            app.histgamma{app.CurrentSensor}(1).Visible = 'off';
            app.histgamma{app.CurrentSensor}(2).Visible = 'off';
                    
            % Update distributions function to incorporate new minimum
            % layer thickness
            app.distibutionsEachSens

        end

        % Value changed function: edit_numbins
        function edit_numbinsValueChanged(app, event)
% ========================================================================
%% Called when the value for number of bins used in histograms is changed.
% ========================================================================

            % Update bin count
            app.Bins(app.CurrentSensor) = app.edit_numbins.Value;
             
            % Hide all current histograms for current sensor
            app.histnormal{app.CurrentSensor}(1).Visible = 'off';
            app.histnormal{app.CurrentSensor}(2).Visible = 'off';
            app.histgamma{app.CurrentSensor}(1).Visible = 'off';
            app.histgamma{app.CurrentSensor}(2).Visible = 'off';

            % Update distributions function to incorporate new bin number
            app.distibutionsEachSens

        end

        % Value changed function: edit_mink
        function edit_minkValueChanged(app, event)
% ==================================================
%% Called when the value for minimum k is changed.
% ==================================================

        % If the new min is higher than the current max, tell user the min 
        % is too high
        if app.edit_mink.Value >= app.kMax(app.CurrentSensor)
            uialert(app.figure_SensAnalSetUp, ['Thermal conductivity ' ...
                'minimum too high. Must be lower than the thermal ' ...
                'conductivity maximum'], 'TOO HIGH', 'Icon','error')
            app.edit_mink.Value = app.kMin(app.CurrentSensor);
            return
        end
        
        % Update k min value of current sensor to match current value of
        % text edit field
        app.kMin(app.CurrentSensor) = app.edit_mink.Value;

        % Turn off all histograms for this sensor
        app.histnormal{app.CurrentSensor}(1).Visible = 'off';
        app.histnormal{app.CurrentSensor}(2).Visible = 'off';
        app.histgamma{app.CurrentSensor}(1).Visible = 'off';
        app.histgamma{app.CurrentSensor}(2).Visible = 'off';
        app.normMin{app.CurrentSensor}.Visible = 'off';
        app.gammMin{app.CurrentSensor}.Visible = 'off';
        
        % Update distributions function to incorporate new k min
        app.distibutionsEachSens

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_SensAnalSetUp = uifigure('Visible', 'off');
            app.figure_SensAnalSetUp.Name = 'MATLAB App';
            app.grid_histograms = uigridlayout(app.figure_SensAnalSetUp);
            app.grid_histogramplots = uigridlayout(app.grid_histograms);
            app.panel_hidehistograms = uipanel(app.grid_histogramplots);
            app.panel_gamma = uipanel(app.grid_histogramplots);
            app.panel_gamma.Title = 'Gamma Distribution';
            app.grid_gamma = uigridlayout(app.panel_gamma);
            app.axes_gamma = uiaxes(app.grid_gamma);
            xlabel(app.axes_gamma, 'Thermal Conductivity (W/m°C)')
            ylabel(app.axes_gamma, 'Count')
            zlabel(app.axes_gamma, 'Z')
            app.panel_normal = uipanel(app.grid_histogramplots);
            app.panel_normal.Title = 'Gaussian Distribution';
            app.grid_normal = uigridlayout(app.panel_normal);
            app.axes_normal = uiaxes(app.grid_normal);
            xlabel(app.axes_normal, 'Thermal Conductivity (W/m°C)')
            ylabel(app.axes_normal, 'Count')
            zlabel(app.axes_normal, 'Z')
            app.panel_setupsens = uipanel(app.grid_histograms);
            app.panel_setupsens.Title = 'Set Up Sensitivity Analysis Parameters';
            app.line = uilabel(app.panel_setupsens);
            app.line.Text = '--------------------------------------------------------------------------------------------------------';
            app.NumberofrealizationsLabel = uilabel(app.panel_setupsens);
            app.NumberofrealizationsLabel.Text = 'Number of realizations:';
            app.edit_numIter = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_numIter.ValueChangedFcn = createCallbackFcn(app, @edit_numIterValueChanged, true);
            app.StandarddeviationinthermalconductivityLabel = uilabel(app.panel_setupsens);
            app.StandarddeviationinthermalconductivityLabel.Text = 'Standard deviation  (W/m°C):';
            app.edit_standdev = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_standdev.ValueChangedFcn = createCallbackFcn(app, @edit_standdevValueChanged, true);
            app.MinimumthermalconductivityWmCLabel = uilabel(app.panel_setupsens);
            app.MinimumthermalconductivityWmCLabel.Text = 'Mean (W/m°C):';
            app.edit_meank = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_meank.ValueChangedFcn = createCallbackFcn(app, @edit_meankValueChanged, true);
            app.MaximumthermalconductivityWmCLabel = uilabel(app.panel_setupsens);
            app.MaximumthermalconductivityWmCLabel.Text = 'Maximum (W/m°C):';
            app.edit_maxk = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_maxk.ValueChangedFcn = createCallbackFcn(app, @edit_maxkValueChanged, true);
            app.MinimumlayerthicknessmLabel = uilabel(app.panel_setupsens);
            app.MinimumlayerthicknessmLabel.Text = 'Minimum layer thickness (m):';
            app.edit_minthickness = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_minthickness.ValueChangedFcn = createCallbackFcn(app, @edit_minthicknessValueChanged, true);
            app.HorizontalthermalconductivityanisotropyLabel = uilabel(app.panel_setupsens);
            app.HorizontalthermalconductivityanisotropyLabel.Text = 'Horizontal thermal conductivity anisotropy:';
            app.edit_kanisotropy = uieditfield(app.panel_setupsens, 'numeric');
            app.label_NOTusingHP_3 = uilabel(app.panel_setupsens);
            app.label_NOTusingHP_3.Text = 'Thermal conductivity distribution  for selected sensor:';
            app.table_inputdata = uitable(app.panel_setupsens);
            app.table_inputdata.ColumnName = {'Sensor'; 'Mean (W/m°C)'; 'Stan. dev. (W/m°C)'; 'Min (W/m°C)'; 'Max (W/m°C)'; 'Bins'};
            app.table_inputdata.RowName = {};
            app.button_updateparams = uibutton(app.panel_setupsens, 'push');
            app.button_updateparams.ButtonPushedFcn = createCallbackFcn(app, @button_updateparamsPushed, true);
            app.button_updateparams.Tooltip = {'Update all sensor parameters with those shown above'};
            app.button_updateparams.Text = 'Update All Sensors To Current Parameters (except mean)';
            app.NumberofbinsLabel = uilabel(app.panel_setupsens);
            app.NumberofbinsLabel.Text = 'Number of bins:';
            app.edit_numbins = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_numbins.ValueChangedFcn = createCallbackFcn(app, @edit_numbinsValueChanged, true);
            app.button_resetparams = uibutton(app.panel_setupsens, 'push');
            app.button_resetparams.ButtonPushedFcn = createCallbackFcn(app, @button_resetparamsPushed, true);
            app.button_resetparams.Tooltip = {'Reset all sensor parameters with default'};
            app.button_resetparams.Text = 'Reset All Sensors To Default (except mean)';
            app.label_NOTusingHP_4 = uilabel(app.panel_setupsens);
            app.label_NOTusingHP_4.Text = 'General setup:';
            app.MinimumthermalconductivityWmCLabel_2 = uilabel(app.panel_setupsens);
            app.MinimumthermalconductivityWmCLabel_2.Text = 'Minimum (W/m°C):';
            app.edit_mink = uieditfield(app.panel_setupsens, 'numeric');
            app.edit_mink.ValueChangedFcn = createCallbackFcn(app, @edit_minkValueChanged, true);
            app.SensorLabel = uilabel(app.panel_setupsens);
            app.SensorLabel.Text = 'Sensor:';
            app.dropdown_sensor = uidropdown(app.panel_setupsens);
            app.dropdown_sensor.ValueChangedFcn = createCallbackFcn(app, @dropdown_sensorValueChanged, true);
            app.label_NOTusingHP_5 = uilabel(app.panel_setupsens);
            app.label_NOTusingHP_5.Text = '(Optional)';
            app.panel_runanalysis = uipanel(app.grid_histograms);
            app.button_runsensanalysis = uibutton(app.panel_runanalysis, 'push');
            app.button_runsensanalysis.ButtonPushedFcn = createCallbackFcn(app, @button_runsensanalysisPushed, true);
            app.button_runsensanalysis.Enable = 'off';
            app.button_runsensanalysis.Text = 'Run Sensitivity Analysis';
            app.label_excludedT_desc = uilabel(app.panel_runanalysis);
            app.label_excludedT_desc.Text = 'To include T for these sensors in sensitivity analysis, must re-process for heat flow and include these sensors.';
            app.label_excludedT_title = uilabel(app.panel_runanalysis);
            app.label_excludedT_title.Text = 'T from these sensors are excluded from thermal gradient :';
            app.label_excludedT = uilabel(app.panel_runanalysis);
            app.label_excludedT.Text = '';
            app.GridLayout = uigridlayout(app.grid_histograms);
            app.panel_distributionchoices = uibuttongroup(app.GridLayout);
            app.buttongroup_distributiontype = uibuttongroup(app.panel_distributionchoices);
            app.buttongroup_distributiontype.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_distributiontypeSelectionChanged, true);
            app.button_normal = uiradiobutton(app.buttongroup_distributiontype);
            app.button_normal.Text = 'Gaussian';
            app.button_normal.Value = true;
            app.button_gamma = uiradiobutton(app.buttongroup_distributiontype);
            app.button_gamma.Text = 'Gamma';
            app.button_none = uiradiobutton(app.buttongroup_distributiontype);
            app.button_none.Text = 'None';
            app.panel_runanalysis_2 = uipanel(app.GridLayout);
            app.DistributionTypeLabel = uilabel(app.panel_runanalysis_2);
            app.DistributionTypeLabel.Text = 'Distribution Type:';
            % Show the figure after all components are created
            app.figure_SensAnalSetUp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetUpSensAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_SensAnalSetUp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_SensAnalSetUp)
        end
    end
end