classdef SlugHeat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_Main                     matlab.ui.Figure
        grid_Main                       matlab.ui.container.GridLayout
        button_OpenControlsPanel        matlab.ui.control.Button
        panel_TabGroup                  matlab.ui.container.Panel
        grid_TabGroup                   matlab.ui.container.GridLayout
        panel_TopBar                    matlab.ui.container.Panel
        panel_TopLabels                 matlab.ui.container.Panel
        grid_TopLabels                  matlab.ui.container.GridLayout
        label_trial                     matlab.ui.control.Label
        label_TrialTitle                matlab.ui.control.Label
        label_Iteration                 matlab.ui.control.Label
        label_IterationTitle            matlab.ui.control.Label
        label_currentpathfull           matlab.ui.control.Label
        label_CurrentPath               matlab.ui.control.Label
        label_SlugHeat                  matlab.ui.control.Label
        panel_ProcessingInfoLabels      matlab.ui.container.Panel
        grid_ProcessingInfoLabels       matlab.ui.container.GridLayout
        edit_PenetrationTitle           matlab.ui.control.Label
        label_ProcessingTime            matlab.ui.control.Label
        label_Longitude                 matlab.ui.control.Label
        label_Latitude                  matlab.ui.control.Label
        label_Penetration               matlab.ui.control.Label
        label_Station                   matlab.ui.control.Label
        label_Cruise                    matlab.ui.control.Label
        edit_CruiseTitle                matlab.ui.control.Label
        edit_ProcessingTimeTitle        matlab.ui.control.Label
        edit_LongitudeTitle             matlab.ui.control.Label
        edit_LatitudeTitle              matlab.ui.control.Label
        edit_StationTitle               matlab.ui.control.Label
        tabgroup_Main                   matlab.ui.container.TabGroup
        tab_PenetrationData             matlab.ui.container.Tab
        grid_PenetrationData            matlab.ui.container.GridLayout
        GridLayout                      matlab.ui.container.GridLayout
        button_DiscardSensors           matlab.ui.control.Button
        panel_PD_Thermistors            matlab.ui.container.Panel
        panel_PenDataAxes               matlab.ui.container.Panel
        grid_PenDataAxes                matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        image_depthlabel                matlab.ui.control.Image
        image_BWlabel                   matlab.ui.control.Image
        image_templabel                 matlab.ui.control.Image
        label_RecordNumber              matlab.ui.control.Label
        axes_Depth                      matlab.ui.control.UIAxes
        axes_BottomWaterTemp            matlab.ui.control.UIAxes
        axes_Tilt                       matlab.ui.control.UIAxes
        axes_TempAboveBWT               matlab.ui.control.UIAxes
        tab_FricitonalDecayReduction    matlab.ui.container.Tab
        grid_FrictionalDecayReduction   matlab.ui.container.GridLayout
        panel_FD_Thermistors            matlab.ui.container.Panel
        panel_FricDecayAxes             matlab.ui.container.Panel
        grid_FricDecayAxes              matlab.ui.container.GridLayout
        axes_TempvBullFunc              matlab.ui.control.UIAxes
        axes_TimeShift                  matlab.ui.control.UIAxes
        axes_TempvTau                   matlab.ui.control.UIAxes
        axes_TempvTime                  matlab.ui.control.UIAxes
        tab_HeatPulseDecayReduction     matlab.ui.container.Tab
        grid_HeatPulseDecayReduction    matlab.ui.container.GridLayout
        panel_HPD_Thermistors           matlab.ui.container.Panel
        panel_HPDecayAxes               matlab.ui.container.Panel
        grid_HPDecayAxes                matlab.ui.container.GridLayout
        label_HPIgnored                 matlab.ui.control.Label
        axes_CTempvCtime                matlab.ui.control.UIAxes
        axes_TempvTimeShift             matlab.ui.control.UIAxes
        axes_MisfitvTimeShift           matlab.ui.control.UIAxes
        axes_CTempv1_CTime              matlab.ui.control.UIAxes
        tab_HeatFlowAnalysis            matlab.ui.container.Tab
        grid_HeatFlowAnalysis           matlab.ui.container.GridLayout
        panel_BA_Thermistors            matlab.ui.container.Panel
        panel_BullAnalysisAxes          matlab.ui.container.Panel
        grid_BullAnalysisAxes           matlab.ui.container.GridLayout
        panel_CTRvTemp                  matlab.ui.container.Panel
        grid_CTRvTemp                   matlab.ui.container.GridLayout
        panel_HF_Shift                  matlab.ui.container.Panel
        grid_HFShfit                    matlab.ui.container.GridLayout
        label_sigLR                     matlab.ui.control.Label
        label_sigLRTitle                matlab.ui.control.Label
        label_heatflow                  matlab.ui.control.Label
        label_shift                     matlab.ui.control.Label
        label_ShiftTitle                matlab.ui.control.Label
        label_HeatFlowTitle             matlab.ui.control.Label
        axes_TempvCTR                   matlab.ui.control.UIAxes
        panel_TCvDepth                  matlab.ui.container.Panel
        grid_TCvDepth                   matlab.ui.container.GridLayout
        panel_averagek                  matlab.ui.container.Panel
        grid_Averagek                   matlab.ui.container.GridLayout
        label_averagek                  matlab.ui.control.Label
        label_AveragekTitle             matlab.ui.control.Label
        axes_TCvDepth                   matlab.ui.control.UIAxes
        panel_TempvDepth                matlab.ui.container.Panel
        grid_TempvDepth                 matlab.ui.container.GridLayout
        grid_GradientDepth              matlab.ui.container.GridLayout
        label_GradientTitle             matlab.ui.control.Label
        label_depthoftop                matlab.ui.control.Label
        label_DepthofTopTitle           matlab.ui.control.Label
        label_gradient                  matlab.ui.control.Label
        axes_TempvDepth                 matlab.ui.control.UIAxes
        tab_HeatFlowRegressionAnalysis  matlab.ui.container.Tab
        grid_HeatFlowRegressionAnalysis  matlab.ui.container.GridLayout
        panel_HF_Thermistors            matlab.ui.container.Panel
        panel_HeatFlowRegAxes           matlab.ui.container.Panel
        grid_HeatFlowRegAxes            matlab.ui.container.GridLayout
        axes_HeatFlow                   matlab.ui.control.UIAxes
        axes_Sigma                      matlab.ui.control.UIAxes
        tab_SensitivityAnalysis         matlab.ui.container.Tab
        grid_SensitivityAnalysis        matlab.ui.container.GridLayout
        panel_SA_Thermistors            matlab.ui.container.Panel
        panel_SensAnalysisAxes          matlab.ui.container.Panel
        grid_SensAnalysisAxes           matlab.ui.container.GridLayout
        panel_SA_TCvDepth               matlab.ui.container.Panel
        grid_SA_TCvDepth                matlab.ui.container.GridLayout
        Panel_6                         matlab.ui.container.Panel
        grid_distributions              matlab.ui.container.GridLayout
        label_kDistribution             matlab.ui.control.Label
        label_MinThickTitle_2           matlab.ui.control.Label
        label_MinThickTitle_4           matlab.ui.control.Label
        label_MinThickTitle_3           matlab.ui.control.Label
        label_ThermConBoundsTitle       matlab.ui.control.Label
        label_minthickness              matlab.ui.control.Label
        label_thermconbounds            matlab.ui.control.Label
        label_meandev                   matlab.ui.control.Label
        label_MeanDevTitle              matlab.ui.control.Label
        label_MinThickTitle             matlab.ui.control.Label
        axes_SA_TCvDepth                matlab.ui.control.UIAxes
        panel_SA_SDvIter                matlab.ui.container.Panel
        grid_SA_SDvIter                 matlab.ui.container.GridLayout
        panel_heatflow_SA               matlab.ui.container.Panel
        grid_iterations                 matlab.ui.container.GridLayout
        label_standev_perc              matlab.ui.control.Label
        label_StanDevTitle_perc         matlab.ui.control.Label
        label_InitHeatFlowTitle_3       matlab.ui.control.Label
        label_standev                   matlab.ui.control.Label
        label_StanDevTitle              matlab.ui.control.Label
        label_currentiteration          matlab.ui.control.Label
        label_CurrentIterationTitle     matlab.ui.control.Label
        axes_SA_SDvIter                 matlab.ui.control.UIAxes
        panel_SA_TempvCTR               matlab.ui.container.Panel
        grid_SA_TempvCTR                matlab.ui.container.GridLayout
        grid_heatflow_SA                matlab.ui.container.GridLayout
        label_InitHeatFlowTitle_2       matlab.ui.control.Label
        label_currentheatflow           matlab.ui.control.Label
        label_CurrentHFTitle            matlab.ui.control.Label
        label_initheatflow              matlab.ui.control.Label
        label_InitHeatFlowTitle         matlab.ui.control.Label
        label_heatflowrange             matlab.ui.control.Label
        label_HFRangeTitle              matlab.ui.control.Label
        label_meanheatflow              matlab.ui.control.Label
        label_MeanHFTitle               matlab.ui.control.Label
        axes_SA_TempvCTR                matlab.ui.control.UIAxes
        tab_HFSummary                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        panel_HFSummary                 matlab.ui.container.Panel
        grid_HFSummary                  matlab.ui.container.GridLayout
        button_clearErrResults          matlab.ui.control.Button
        button_saveErrRestults          matlab.ui.control.Button
        panel_ErrorUncertaintyAnalysisTable  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        table_ErrorUncertaintySummary   matlab.ui.control.Table
        button_saveresults              matlab.ui.control.Button
        button_clearresults             matlab.ui.control.Button
        table_HFSummary                 matlab.ui.control.Table
        MonteCarloCompareTab            matlab.ui.container.Tab
        grid_sensresults                matlab.ui.container.GridLayout
        axes_senshist                   matlab.ui.control.UIAxes
        axes_sensscatter                matlab.ui.control.UIAxes
        panel_ControlWindow             matlab.ui.container.Panel
        grid_ControlWindow              matlab.ui.container.GridLayout
        panel_PlotControls_Pen          matlab.ui.container.Panel
        grid_PlotControls_Pen           matlab.ui.container.GridLayout
        checkbox_DepthPlot              matlab.ui.control.CheckBox
        checkbox_BottomWaterPlot        matlab.ui.control.CheckBox
        checkbox_TiltPlot               matlab.ui.control.CheckBox
        panel_PlotControls_Fric         matlab.ui.container.Panel
        grid_PlotControls_Fric          matlab.ui.container.GridLayout
        buttongroup_PlotControls_Fric   matlab.ui.container.ButtonGroup
        button_TempvsBullFunFric        matlab.ui.control.RadioButton
        button_MisfitvsShiftFric        matlab.ui.control.RadioButton
        button_TempvsTauFric            matlab.ui.control.RadioButton
        button_TempvsTimeFric           matlab.ui.control.RadioButton
        button_ShowAllFric              matlab.ui.control.RadioButton
        panel_PlotControls_HP           matlab.ui.container.Panel
        grid_PlotControls_HP            matlab.ui.container.GridLayout
        buttongroup_PlotControls_HP     matlab.ui.container.ButtonGroup
        button_TempvsTimeInv_HP         matlab.ui.control.RadioButton
        button_TvShift_HP               matlab.ui.control.RadioButton
        button_MisfitvShift_HP          matlab.ui.control.RadioButton
        button_TempvsTime_HP            matlab.ui.control.RadioButton
        button_ShowAll_HP               matlab.ui.control.RadioButton
        panel_DataControls              matlab.ui.container.Panel
        grid_DataControls               matlab.ui.container.GridLayout
        checkbox_UseHP                  matlab.ui.control.CheckBox
        switch_PauseBetweenIter         matlab.ui.control.Switch
        PauseIterationsLabel            matlab.ui.control.Label
        edit_bottomwater                matlab.ui.control.NumericEditField
        BWTLabel                        matlab.ui.control.Label
        button_changeBW                 matlab.ui.control.StateButton
        edit_PenEnd                     matlab.ui.control.NumericEditField
        PenetrationEndLabel             matlab.ui.control.Label
        edit_HP                         matlab.ui.control.NumericEditField
        HeatPulseLabel                  matlab.ui.control.Label
        edit_PenStart                   matlab.ui.control.NumericEditField
        PenetrtionStartLabel            matlab.ui.control.Label
        panel_Parameters                matlab.ui.container.Panel
        grid_Parameters                 matlab.ui.container.GridLayout
        lamp_ParFile                    matlab.ui.control.Lamp
        button_NewParFile               matlab.ui.control.Button
        label_parfilename               matlab.ui.control.Label
        button_SetParams                matlab.ui.control.Button
        label_ParFile                   matlab.ui.control.Label
        panel_PenetrationInfo           matlab.ui.container.Panel
        grid_PenetrationInfo            matlab.ui.container.GridLayout
        button_LoadPen                  matlab.ui.control.Button
        label_penfilename               matlab.ui.control.Label
        label_PenFile                   matlab.ui.control.Label
        label_tapfilename               matlab.ui.control.Label
        label_TapFile                   matlab.ui.control.Label
        panel_PlotControls_HeatFlow     matlab.ui.container.Panel
        grid_PlotControls_HeatFlow      matlab.ui.container.GridLayout
        checkbox_TiltPlot_2             matlab.ui.control.CheckBox
        checkbox_TCvDepth               matlab.ui.control.CheckBox
        checkbox_TempvDepth             matlab.ui.control.CheckBox
        checkbox_TempvCTR               matlab.ui.control.CheckBox
        panel_PlotControls_Scatter      matlab.ui.container.Panel
        grid_Scatter                    matlab.ui.container.GridLayout
        checkbox_SigmaPlot              matlab.ui.control.CheckBox
        checkbox_HFPlot                 matlab.ui.control.CheckBox
        panel_PlotControls_SensAn       matlab.ui.container.Panel
        grid_PlotControls_Pen_2         matlab.ui.container.GridLayout
        checkbox_HeatFlow               matlab.ui.control.CheckBox
        checkbox_ThermCon               matlab.ui.control.CheckBox
        checkbox_std                    matlab.ui.control.CheckBox
        panel_Commands                  matlab.ui.container.Panel
        grid_Commands                   matlab.ui.container.GridLayout
        label_resfilename               matlab.ui.control.Label
        label_ResFile                   matlab.ui.control.Label
        button_Process                  matlab.ui.control.Button
        button_reset                    matlab.ui.control.Button
        button_NextIteration            matlab.ui.control.Button
        button_EndIterations            matlab.ui.control.Button
        button_IgnoreData               matlab.ui.control.Button
        button_SensAn                   matlab.ui.control.Button
        panel_header                    matlab.ui.container.Panel
        grid_header                     matlab.ui.container.GridLayout
        button_Restart                  matlab.ui.control.Button
        image_SlugHeat                  matlab.ui.control.Image
        panel_Logos                     matlab.ui.container.Panel
        grid_Logos                      matlab.ui.container.GridLayout
        image_UCSCLogo                  matlab.ui.control.Image
        button_ControlWindowExit        matlab.ui.control.Button
    end

    
    properties (Access = public)

%% Variables created in separate .m functions
%% ==================================================
        
        % Outputs from Initialize Program function
        % ---------------------------------        
            Version
            Update
			NumberOfColumns
			CurrentPath
            CurrentDateTime
            ParFile
            ParFilePath
            ParFileName
            DefaultParFile
            ProgramLogId
            AppPath
            AppOutputs
        
        % Outputs from GetFiles function
        % ---------------------------------
			PenFileName
            PenFilePath
            PenFile
			TAPName
            TAPFileName
            TAPFile
            MATFileName
            MATFile
			LogFileName
            LogFile
			ResFileName
            ResFile 
            ResFileId
            LogFileId

       % Outputs from ReadParFile function
       % ---------------------------------
            S_ParFile
            NumberOfSensors	
            WaterThermistor
            TimeScalingFactor
            DeltaTime
            SensorRadius
            SensorDistance
            TempError
            CalibrationCoeffs
            HyndmanCoeffs
            FrictionalDelays
            FricMaxStep
            TimeInc
            FricTauMin
            FricTauMax
            PulseDelays
            kInit
            ktype
            PulsePowerPARFile
            TimeShiftInit
            TimeShiftInc
            PulseMaxStep
            kTolerance
            PulseTauMin
            PulseTauMax
            HeatPulseLength
            MinTotalkChange
            MaxNumberOfIterations
            MaxSAIterations
            Sigmak0
            kMin
            kMax
            MinThickness
            kAnisotropy
            TopSensorDepth 
            ProbeLength
            Offset

        % Outputs from ReadPenFile function
        % ---------------------------------
            S_MATFile
            FullExpeditionName 
            StationName 
            Penetration 
            CruiseName 
            Datum 
            Latitude 
            Longitude 
            DepthMean
            TiltMean
            LoggerId
            ProbeId
            NumberOfSensorsWorking
            PenetrationRecord
            HeatPulseRecord
            EndRecord
            AllRecords
            AllSensorsRawData
            WaterSensorRawData
            EqmStartRecord
            EqmEndRecord 
            PulsePower
            CalibTemps            
            MeanCalibTemps        

        % Outputs from ReadTAPFile function
        % ---------------------------------
            TAPRecord
            Tilt
            Depth

        % Outputs from TemperatureCorrection function
        % ---------------------------------
            BottomWaterTemp
            WaterSensorTemp
            AllSensorsTemp
            AllSensorsCalibratedTemp

        % Outputs from SplitDecays function
        % ---------------------------------
            FricTime
            FricTemp
            PulseData
            PulseTime
            PulseTemp
        
        % Outputs from PlotTemp function
        % ---------------------------------
            S_Lines
            AllSensors
            h_axTempAboveBWT
        
       % Outputs from PlotCheckboxes function
       % ------------------------------------
            PenCheckboxes
            FricCheckboxes
            HPCheckboxes 

        % Outputs from InitializeProcessing function
        % -------------------------------------------
            kFunction
            Currentk
            CurrentT
            TChange
            kChange
            Iteration
            kInitial
            FirstIteration
            TotalIterations
            AnotherTrial
            RelativeDepths
            A
            B
            C
            D
            E
            F

        % Outputs from FrictionalDecay function
        % --------------------------------------
            NumberOfFricUsedPoints
            MinimumFricEqTemp
            MinimumFricError
            MinimumFricDelays
            MinimumFricSlope
            HPTooLow
            ShiftedTime
            IndexOfMinimums
            DataTemp
            TimeShifts
            ShiftedTau
            DataFAT
            DataLimits
            b
            a
            RMS
        
        % Outputs from PlotFrictionalDecay function
        % -----------------------------------------
            h_axFricTempvTime
            h_axFricTempvTau
            h_axFricTempvTauPoints
            h_axFricTempvTauLines
            h_axFricRMSvTimeShift
            h_axFricRMSvTimeShiftMinDelays

        % Outputs from HeatPulseDecay Function
        % -------------------------------------
            MeankPointAtMinkDiff
            kSlopeAtMinkDiff
            MeankPointAtZeroInfTemp
            MeankPointAtMinRMS
            kSlopeAtZeroInfTemp
            kSlopeAtMinRMS
            TempAtInf
            NumberOfUsedPoints
            MinimumPulseDelays
            kError
            HeatPulseTime
            MinkDiffIndex
            kSlopeRMS
            TempAtInfinity
            OneOverTime
            kSlope
        
        % Outputs from PlotHeatPulseDecay Function
        % -------------------------------------
            h_axHPTempvTime
            h_axHPRMS
            h_axHPRMSLine
            h_axHPTempvInvTime
            h_axHPTempvInvTimeBestFit
            h_axHPTempvTimeShift
            h_axHPTempvTimeShiftBestFit
                            

        % Outputs from HeatFlowAnalysis Function
        % -------------------------------------
           SensorsUsedForBullardFit
           GoodkIndex
           CTRToUse
           CTR
           ShiftedCTR
           ShiftedRelativeDepths
           SigmaR
           PenetrationLag
           TToUse
           kToUse
           Slope
           Shift
           S_BullPlots
           HeatFlow
           Averagek
           Gradient
           HFErr
           HFShift
           HFShiftErr
           kErr
           GradErr
           GradShift
           GradShiftErr

        % Outputs from HeatFlowRegression Function
        % -------------------------------------
           Scatter
           ScatterHeatFlow
           Sigmaa
           Sigmab 

        % Outputs from PlotHeatFlowRegression Function
        % -------------------------------------   
           HFLine
           ScatterLine
           SigmaHFLine
           SigmaScatterLine

        % Outputs from SensitivityAnalysis Function
        % -------------------------------------
           Results
           Params

        % Outputs from New PAR file button
        % -------------------------------
           NewParFileName
           NewParFile

 %% Variables created in the main app (SlugHeat.mlapp)
 %% ==================================================

        % Plots are all turned ON to start
        % --------------------------------
        BWPlot = 1;
        DepthPlot = 1;
        TiltPlot = 1;
        TempPlot = 1;

        TempTimePlot = 1;
        TempTauPlot = 1;
        TempBullFuncPlot = 1;
        MisfitShiftPlot = 1;

        BullTempvDepthPlot = 1;
        TCvDepthPlot = 1;
        TempvCTRPlot = 1;

        HFPlot = 1;
        SigmaPlot = 1;

        UseWaterSensor = 1;
        UseHP = 1;

        % Initialize general processing parameters
        % ----------------------------------------
        NoInitialize = 0;
        Reprocess = 0;
        RunAnalysis = 0;

        Badk = [];
        BadT = [];
        
        % Record numbers for penetration, heat pulse, and end 
        % are unchanged to start
        % -----------------------------------------------------
        PenStartChanged=0;
        HeatPulseChanged=0;
        PenEndChanged=0;

        % Begin at Trial 0
        % -----------------
        Trial = 0;

        % Turn pause and convergence off
        % ------------------------------
        Pause = 0;
        Converged = 0;

        TempTimePlotHPPlot    
        MisfitShiftHPPlot     
        TempInvTimeHPPlot      
        TempTimeShiftHPPlot

        % Auxiallary Dialog Apps
        % -------------------------
        SetParamsDialogApp 
        DiscardDataDialogApp
        SetUpSensDialogApp
        BottomWaterApp
        
        % Each time a single penetration is processed = 1 trial
        % ------------------------------------------------------
        PreviousT
        Previousk

        % Colors
        % ----------
        LightGrey = [0.97 0.97 0.97]
        
        % Save original number of sensors before discarding from dataset
        % ----------------------------------------------------------------
        OrigNumberOfSensors
        IgnoredSensors
        SensorsToUse

        % For results
        % -----------
        HFData            % Heat Flow Results Summary data
        ErrData           % Error and Uncertatinty results summary data
        S_FricResults     % Structure for all frictional decay reduction results
        S_HPResults       % Structure for all heat pulse decay reduction results
        S_HeatFlowResults % Structure for all heat flow reduction results
        S_Results         % All results
        S_SensResults = [];    % Sensitivity results
       
        % Original parameters
        OrigParams
        
        BWChosen = 0;         % If the user has already chosen a bottom water, don't ask them again
        BottomWaterValue = 0; % Value of bottom water if it's manually chosen by user
        NumSensAnalyses = 0;  % number of sensitivity analyses that have been run for this penetration
        TotkChange            % sum of differences in k each iteration for all sensor

        % For setSens function
        kDistribution 
        r 
        Bins = 25
        UseFrictional
        Meank
    end
    
    methods (Access = public)

    %% These are all public functions that connect auxiliary applications 
    %% to this application by defining and sharing variables used in both
    %% ==================================================================
       
         %% Sets the sensitivity analysis parameters based on using the 
         %% 'Run Sensitivity Analysis' button and corresponding auxiliary app
         function setParams(app, offset, numsen, watertherm, timescale, ...
                 deltatime, sensrad, sensdist, temperror, hyndco, ...
                 fricdelay, fricmaxstep, timeinc, frictaumin, frictaumax, ...
                 pulsedelay, kinit, ktype, pulsepow, timshiftinit, timeshiftinc, ...
                 pulsemaxstep, ktol, pulsetaumin, pulsetaumax, HPlength, ...
                 minkchange, maxiter, maxSAiter, sigk, kmin, kmax, minthick, ...
                 kAni, topsensor, probelength)
         
             app.Offset                  = offset;    
             app.NumberOfSensors         = numsen; 
             app.WaterThermistor         = watertherm;
             app.TimeScalingFactor       = timescale;
             app.DeltaTime               = deltatime;
             app.SensorRadius            = sensrad;
             app.SensorDistance          = sensdist;
             app.TempError               = temperror;
             app.HyndmanCoeffs           = hyndco;
             app.FrictionalDelays        = repmat(fricdelay, 1, app.NumberOfSensors);
             app.FricMaxStep             = fricmaxstep;
             app.TimeInc                 = timeinc;
             app.FricTauMin              = frictaumin;
             app.FricTauMax              = frictaumax;
             app.PulseDelays             = repmat(pulsedelay, 1, app.NumberOfSensors);
             app.kInit                   = kinit;
             app.ktype                   = ktype;
             app.PulsePower              = pulsepow;
             app.TimeShiftInit           = timshiftinit;
             app.TimeShiftInc            = timeshiftinc;
             app.PulseMaxStep            = pulsemaxstep;
             app.kTolerance              = ktol;
             app.PulseTauMin             = pulsetaumin;
             app.PulseTauMax             = pulsetaumax;
             app.HeatPulseLength         = HPlength;
             app.MinTotalkChange         = minkchange;
             app.MaxNumberOfIterations   = maxiter;
             app.MaxSAIterations         = maxSAiter;
             app.Sigmak0                 = sigk;
             app.kMin                    = kmin;
             app.kMax                    = kmax;
             app.MinThickness            = minthick;
             app.kAnisotropy             = kAni;
             app.TopSensorDepth          = topsensor;
             app.ProbeLength             = probelength;

             % Make lamp for default parameters visible to inform user that
             % parameters have been changed
             app.lamp_ParFile.Visible = "on";
         end
         
         %% Sets the sensors to discard based on using the 
         %% 'Ignore Data' button and corresponding auxiliary app
         function discardData(app, BadT, Badk)
             app.BadT = BadT;
             app.Badk = Badk;
             button_ProcessButtonPushed(app)
         end
        
         %% Sets the sensitivity analysis parameters based on using the 
         %% 'Run Sensitivity Analysis' button and corresponding auxiliary app
         function setSens(app, MaxSAIterations, Sigmak0, kMin, kMax, MinThickness, kAnisotropy, kDistribution, r, Meank,RunAnalysis, Bins, UseFrictional)
             app.MaxSAIterations = MaxSAIterations;
             app.Meank = Meank;
             app.Sigmak0 = Sigmak0;
             app.kMin = kMin;
             app.kMax = kMax;
             app.MinThickness = MinThickness;
             app.kAnisotropy = kAnisotropy;
             app.kDistribution = kDistribution;
             app.r = r;
             app.RunAnalysis = RunAnalysis;
             app.Bins = Bins;
             app.UseFrictional = UseFrictional;
         end
        
        %% Sets the new bottom water based on user input using the 'Change
        %% BW temperature button and corresponding auxiliary app
        function SetBottomWater(app, BottomWater)
            app.BottomWaterValue = BottomWater;
            app.edit_bottomwater.Value = app.BottomWaterValue;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%% =====================================================================
%% Automatically runs when running program. Initializes program and 
%% loads default parameters from .par file
%% =====================================================================

clc

%% Initialize GUI 

        % Set main figure properties
        % --------------------------
        app.figure_Main.WindowState = 'maximized';
        figure(app.figure_Main)

        % Disable sensitivity analysis functions until one is run
        % -------------------------------------------------------
        app.button_saveErrRestults.Enable = 'off';
        app.button_clearErrResults.Enable = 'off';

        % Disable checkboxes and buttons until data is loaded in
        % ------------------------------------------------------
        if ~app.WaterThermistor
            app.UseWaterSensor.Enable = 'off';
        end
        app.checkbox_BottomWaterPlot.Enable = 'off';
        app.checkbox_DepthPlot.Enable = 'off';
        app.checkbox_TiltPlot.Enable = 'off';
        app.checkbox_UseHP.Enable = 'off';
        app.button_Process.Enable='off';
        app.button_changeBW.Enable='off';
        app.button_DiscardSensors.Enable='off';
        app.switch_PauseBetweenIter.Enable='off';
        app.edit_PenStart.Enable='off';
        app.edit_PenEnd.Enable='off';
        app.edit_HP.Enable='off';
        app.edit_bottomwater.Enable='off';

        % Plot controls originally set to penetration data tab 
        % ----------------------------------------------------
        app.panel_PlotControls_Fric.Visible = 'off';
        app.panel_PlotControls_HP.Visible = 'off';
        app.panel_PlotControls_HeatFlow.Visible = 'off';
        app.panel_PlotControls_Scatter.Visible = 'off';

%% Initialize program
        [app.Version, app.Update, ...
		app.NumberOfColumns, ...
		app.CurrentPath, app.CurrentDateTime, ...
        app.ParFile, app.ParFilePath, app.ParFileName, ...
        app.DefaultParFile, ...
    	app.ProgramLogId,...
        app.AppPath, ...
        app.AppOutputs] =  InitializeProgram;

%% DIALOG BOX: Stop and inform user if they are in the wrong directory
        while ~exist(fullfile(app.CurrentPath, 'ReadParFile.m'), 'file')
            uialert(app.figure_Main, ['ERROR: You are in the wrong directory. ...' ...
                'Please be sure your current path holds all of the necessary files'], ...
                'Wrong Directory', 'Icon','error')
            uiwait(app.figure_Main)
        end
        uiresume(app.figure_Main)

%% Read in parameters from parameters file
        [app.S_ParFile, ...
        app.NumberOfSensors, ...	
        app.WaterThermistor, ...
        app.TimeScalingFactor, ...
        app.DeltaTime, ...
        app.SensorRadius, ...
        app.SensorDistance, ...
        app.TempError, ...
        app.CalibrationCoeffs, ...
        app.HyndmanCoeffs, ...
        app.FrictionalDelays, ...
        app.FricMaxStep, ...
        app.TimeInc, ...
        app.FricTauMin, ...
        app.FricTauMax, ...
        app.PulseDelays, ...
        app.kInit, ...
        app.ktype, ...
        app.PulsePowerPARFile, ...
        app.TimeShiftInit, ...
        app.TimeShiftInc, ...
        app.PulseMaxStep, ...
        app.kTolerance, ...
        app.PulseTauMin, ...
        app.PulseTauMax, ...
        app.HeatPulseLength, ...
        app.MinTotalkChange, ...
        app.MaxNumberOfIterations, ...
        app.MaxSAIterations, ...
        app.Sigmak0, ...
        app.kMin, ...
        app.kMax, ...
        app.MinThickness, ...
        app.kAnisotropy, ...
        app.TopSensorDepth, ...
        app.ProbeLength,...
        app.Offset] = ReadParFile(app.ParFile, app.ProgramLogId, app.ParFileName, app.CurrentPath);
        % Save original paramaters from PAR file in structure
        % ---------------------------------------------------
       % app.FrictionalDelays = repmat(app.FrictionalDelays(1), app.Nu)
        app.OrigParams = app.S_ParFile;

%% Update layout
        updateLabels(app.label_currentpathfull, app.CurrentPath, app.label_penfilename, ...
        app.PenFileName, app.label_tapfilename, app.TAPFileName, app.label_resfilename, ...
        app.ResFileName,app.label_Cruise, app.CruiseName, ...
        app.label_Station, app.StationName, app.label_Penetration, app.Penetration, ...
        app.label_Latitude, app.Latitude, app.label_Longitude, app.Longitude, app.edit_PenStart, ...
        app.PenetrationRecord, app.edit_PenEnd, app.EndRecord, app.edit_HP, app.HeatPulseRecord)   
        end

        % Button pushed function: button_ControlWindowExit
        function button_ControlWindowExitButtonPushed(app, event)
% =============================================================
%% Called when button for closing control window is pressed.
% =============================================================     
            
            app.grid_Main.ColumnWidth = {'0x', '.01x', '1x'};

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, ...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, ...
                app.TempPlot, app.axes_TempAboveBWT)

            % Align X axes of the two time shift plots on the heat pulse 
            % reduction tab
            % ----------------------------------------------------------
            xAlignHPD(app.button_ShowAll_HP, app.axes_MisfitvTimeShift, ...
                app.axes_TempvTimeShift)

        end

        % Button pushed function: button_LoadPen
        function button_LoadPenPushed(app, event)
% =============================================================
%% Called when button for loading penetration data is pressed.
% =============================================================      

%% Set up for processings and data preparation

            % Enable checkboxes and buttons until data is loaded in
            % ------------------------------------------------------
            app.checkbox_BottomWaterPlot.Enable = 'on';
            app.checkbox_DepthPlot.Enable = 'on';
            app.checkbox_TiltPlot.Enable = 'on';
            app.checkbox_UseHP.Enable = 'on';
            app.button_Process.Enable='on';   
            app.button_changeBW.Enable='on';
            app.button_DiscardSensors.Enable='on';
            app.switch_PauseBetweenIter.Enable='on';
            app.edit_PenStart.Enable='on';
            app.edit_PenEnd.Enable='on';
            app.edit_HP.Enable='on';
            app.edit_bottomwater.Enable='on';
    
    
            % Reset all generic program parameters
            % ------------------------------------
            app.SensorsToUse = 1:app.OrigNumberOfSensors;
            app.Trial = 0;
            app.Reprocess = 0;
            app.UseWaterSensor = 1;
            app.WaterThermistor = 1;
            app.UseHP = 1;
            app.IgnoredSensors = [];
            app.panel_PlotControls_Pen.Visible='on';
    
            % Reset all plots
            % ---------------
            ResetAll(app.axes_TempAboveBWT, app.axes_BottomWaterTemp, ...
                app.axes_Depth, app.axes_Tilt, app.axes_TempvTime, ...
                app.axes_TempvTau, app.axes_TempvBullFunc, ...
                app.axes_TimeShift, app.axes_CTempvCtime, ...
                app.axes_CTempv1_CTime, app.axes_MisfitvTimeShift, ...
                app.axes_TempvTimeShift, app.axes_TempvDepth, ...
                app.axes_TCvDepth, app.axes_TempvCTR, ...
                app.axes_HeatFlow, app.axes_Sigma, ...
                app.label_gradient, app.label_depthoftop, ...
                app.label_averagek, app.label_heatflow, app.label_shift, ...
                app.label_trial, app.label_Iteration, ...
                app.label_currentpathfull, app.label_parfilename, ...
                app.label_penfilename, app.label_tapfilename, ...
                app.label_resfilename, app.edit_PenStart, app.edit_HP, ...
                app.edit_PenEnd, app.checkbox_BottomWaterPlot, ...
                app.checkbox_DepthPlot, app.checkbox_TiltPlot, ...
                app.PenCheckboxes, app.FricCheckboxes, app.HPCheckboxes, ...
                app.checkbox_UseHP, app.S_MATFile, app.NumberOfSensors, ...
                app.ProgramLogId, app.SensorsToUse, app.label_HPIgnored, ...
                app.lamp_ParFile);                                   

            % Reset all plots for senstivity analysis
            % -----------------------------------------
            delete(app.axes_SA_TCvDepth.Children)
            delete(app.axes_SA_TempvCTR.Children)
            delete(app.axes_SA_SDvIter.Children)

            % Update processing time label
            % ------------------------------
            app.label_ProcessingTime.Text = string(datetime('now'));
            figure(app.figure_Main)

%% Get data 
    
           % Get the penetration and tap file, create log file and 
           % results file
           % -------------------------------------------------------
		    [app.PenFileName, ...
               app.PenFilePath, ...
               app.PenFile, ...
		        app.TAPName, ...
               app.TAPFileName, ...
               app.TAPFile, ...
               app.MATFileName, ...
               app.MATFile, ...
		        app.LogFileName, ...
               app.LogFile, ...
		        app.ResFileName, ...
               app.ResFile, ...
               app.LogFileId, ...
               app.ResFileId...
               ] = GetFiles(...
		        app.CurrentPath, ...
               app.ProgramLogId, ...
               app.figure_Main, ...
               app.AppPath);
            
            % if there is no .res file created yet, stop
            if isempty(app.ResFileId) 
                return
            end
    
            % if there is no .pen file chosen, stop
            if app.PenFile==0
                return
            end
    
    
            % Inform user that the penetrationd data is being loaded in 
            % or cancelled
            % ----------------------------------------------------------
            figure(app.figure_Main)
            if ~app.PenFile(2)
                uialert(app.figure_Main,'Cancelled', 'Selecting penetration', ...
                    'Icon','warning');
                return
            else
                loading = uiprogressdlg(app.figure_Main,"Message",['Getting ' ...
                    'penetration data....']);
                loading.Value = .2;
            end
    
            %% Get data using the TEXT .pen and .tap files
                %  ** NOTE ** 
                %  this is only used if there is no .mat file. If you want to override 
                % the .mat file and use the text .pen and .tap files, remove the .mat 
                % file from your datafolder. These functions will be called instead.
            
            if ~isfile(app.MATFile) 
            
                % Read in data from penetration TEXT file
                % -------------------------------------------------
            
                [app.StationName, ...
                app.Penetration, ...
                app.CruiseName, ...
                app.Datum, ...
                app.Latitude, ...
                app.Longitude, ...
                app.DepthMean, ...
                app.TiltMean, ...
                app.LoggerId, ...
                app.ProbeId, ...
                app.NumberOfSensors, ...
                app.PenetrationRecord, ...
                app.HeatPulseRecord, ...
                app.EndRecord, ...
                app.AllRecords, ...
                app.AllSensorsRawData, ...
                app.WaterSensorRawData, ...
                app.CalibTemps, ...
                app.MeanCalibTemps, ...
                app.WaterThermistor,...
                app.PulsePower] = ReadPenText_withPulse(app.PenFile, app.figure_Main, ...
                app.WaterThermistor, app.figure_Main);
            
                % Read in information from tap TEXT file
                % -------------------------------------------------
                [app.TAPRecord, ...
                app.Tilt, ...
                app.Depth ...
                ] = ReadTAPText(...
                app.LogFileId, ...
                app.ProgramLogId, ...
                app.TAPName, ...
                app.SensorDistance, ...
                app.DepthMean, ...
                app.TiltMean, ...
                app.PenFilePath);
            
            %% Get data using the .mat file
            
            else
            
                % Read in data from penetration .mat file
                % -------------------------------------------------
            
                [app.S_MATFile, app.FullExpeditionName, ...
                app.StationName, ...
                app.Penetration, ...
                app.CruiseName, ...
                app.Datum, ...
                app.Latitude, ...
                app.Longitude, ...
                app.DepthMean, ...
                app.TiltMean, ...
                app.LoggerId, ...
                app.ProbeId, ...
                app.NumberOfSensors, ...
                app.NumberOfSensorsWorking,...
                app.PenetrationRecord, ...
                app.HeatPulseRecord, ...
                app.EndRecord, ...
                app.AllRecords, ...
                app.AllSensorsRawData, ...
                app.WaterSensorRawData, ...
                app.MeanCalibTemps, ...
                app.PulsePower ...
                ] = ReadPenFile_withPulse(app.MATFile, app.LogFileId, ...
                app.PenFile, app.ProgramLogId, app.figure_Main);
            
                % Reformat FrictionalDelays to be same size as number of
                % thermistors
                app.FrictionalDelays=repmat(app.FrictionalDelays(1), app.NumberOfSensors);
                app.PulseDelays=repmat(app.PulseDelays(1), app.NumberOfSensors);

                % Read in temperature and pressure (depth) data from .mat file
                % ------------------------------------------------------------
                if isfield(app.S_MATFile, 'S_TAPVAR')
                    [app.TAPRecord, ...
                      app.Tilt, app.Depth, app.SensorDistance ...
                      ] = ReadTAPFile(app.S_MATFile, app.SensorDistance);
                else
                    app.TAPRecord = [];
                    app.Tilt = [];
                    app.Depth = [];
                end 
            end

%% Confirm heat pulse power

            % Convert pulse power to J/m based on probe
            % length, rounded to nearest integer
            % -------------------------------------------------------
            app.PulsePower  =  app.PulsePower;
            app.PulsePower  = app.PulsePower/app.ProbeLength; 
                        % Use this line ^^
                        % when we thought pulse power was reported in the 
                        % .raw file in J. 

                        %% PulsePower HAS TO BE IN J/m !!!
                        % NEW CHANGE for above code ^. Now Justin 
                        % says the power is reported in J in the .raw 
                        % file, so this line is needed to get PulsePower in J/m. 
                        % ** IF THIS CHANGES AGAIN, WE NEED TO KNOW!***
            
         
            % The pulse power will be negative if read in from a .pen file
            % or .mat file with no heat pulse record number (-999 / probe
            % length)
            if app.PulsePower < 0
            
            % Confirm with user which pulse power to use if PEN file has no
            % pulse recorded
            PowerConfirm = uiconfirm(app.figure_Main, ['The correct ' ...
               'heat pulse power may not be reported in the ' ...
               '.pen file or .mat file. Must confirm which heat pulse ' ...
               'power to use before continuing.'], 'Confirm Heat Pulse Power', ...
               'Options',{['Use power read in from .pen and .mat file: ', ...
               num2str(app.PulsePower), ' (J/m)'], ...
               ['Use power defined in new .par file: ', ...
               num2str(app.PulsePowerPARFile), ' (J/m)']});
            
              switch PowerConfirm
                  case ['Use power read in from PEN and MAT file: ', ...
                  num2str(app.PulsePower), ' (J/m)']
                      app.PulsePower = app.PulsePower;
                  case ['Use power defined in new PAR file: ', ...
                  num2str(app.PulsePowerPARFile), ' (J/m)']
                      app.PulsePower = app.PulsePowerPARFile;
              end
            end

%% Confirm working sensors to use        
    
            % If no water thermistor found (data is NaN), ignore it and set
            % WaterThermistor to 0 and NumberofSensors can remain the same
            % -------------------------------------------------------------
            if all(isnan(app.WaterSensorRawData))
                app.WaterSensorRawData = app.AllSensorsRawData(:, ...
                    app.SensorsToUse(end));
                app.WaterThermistor = 0;
            else
                app.NumberOfSensors = app.NumberOfSensors-1;
            end
            app.BWChosen = 0; 

            % Set sensors to use to all sensors originally
            % ---------------------------------------------
            app.SensorsToUse = 1:app.NumberOfSensors;
            
%% Correct temperatures by calibrating sensors
    % This is done by calibrating the sensors with temperatures 
    % measured during the calibration period defined by the user during 
    % penetration set up and making of the .pen and .mat files in SlugPen.   

           % Convert penetration file raw data to corrected temperature
           % -------------------------------------------------------------

           [...
            app.BottomWaterTemp, ...
            app.WaterSensorTemp, ...
            app.AllSensorsTemp, ...
            app.AllSensorsCalibratedTemp,...
            app.WaterThermistor] = TempCorrection(...
             app.BWChosen, ...
             app.BottomWaterValue,...
             app.AllSensorsRawData, ...
             app.WaterThermistor, ...
             app.WaterSensorRawData, ...
             app.Offset, ...
             app.PenetrationRecord, ...
             app.AllRecords, ...
             app.MeanCalibTemps, ...
             app.NumberOfSensors, ...
             app.SensorsToUse, ...
             app.figure_Main, ...
             app);

         
            % Set bottom water label on GUI, now that it has been determined
            % -------------------------------------------------------
            app.edit_bottomwater.Value = app.BottomWaterTemp;

%% Split penetration and heat pulse data

            [app.FricTime, ...
            app.FricTemp, ...
            app.PulseData, ...
            app.PulseTime, ...
            app.PulseTemp ...
            ] = SplitDecays( ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...   
            app.AllRecords, ...
            app.TimeScalingFactor, ...
            app.AllSensorsTemp, app.LogFileId,app.PenStartChanged, ...
            app.HeatPulseChanged, app.PenEndChanged, app.ProgramLogId, ...
            app.NumberOfColumns);

            %Update progress message
            %---------------------------
            loading.Value = .7;
            loading.Message = 'Plotting penetration data...';

%% Plot raw data and update GUIs

            % For checkboxes, all plots and checkboxes must have a 
            % value to start
            % ------------------------------------------------------------
            app.h_axFricTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTau = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauPoints = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauLines = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShiftMinDelays = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPRMS = zeros(1, app.NumberOfSensors);
            app.h_axHPRMSLine = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTimeBestFit = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShiftBestFit = zeros(1, app.NumberOfSensors);
            app.PenCheckboxes = zeros(1, app.NumberOfSensors);
            app.FricCheckboxes = zeros(1, app.NumberOfSensors);
            app.HPCheckboxes = zeros(1, app.NumberOfSensors);


            % Plot penetration data
            % ---------------------------
            
            % Plot raw temperature         
            [app.S_Lines, ...
            app.AllSensors,...
            app.h_axTempAboveBWT...
            ] = PlotTemp(app.axes_TempAboveBWT, ...
            app.axes_BottomWaterTemp, ...
            app.axes_Depth, ...
            app.axes_Tilt, ...
            app.checkbox_BottomWaterPlot, ...
            app.AllSensorsTemp, ...
            app.NumberOfSensors, ...
            app.WaterThermistor, ...
            app.WaterSensorTemp, ...
            app.AllRecords, ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ... 
            app.PulseData);

            % Plot depth
            PlotDepth(app.figure_Main, ...
            app.axes_Depth, ...
            app.checkbox_DepthPlot, ...
            app.Tilt, ...
            app.Depth, ...
            app.DepthMean, ...
            app.TAPRecord, ...
            app.Reprocess)

            % Plot tilt
            PlotTilt(app.TAPRecord, ...
            app.figure_Main, ...
            app.TiltMean, ...
            app.Tilt, ...
            app.checkbox_TiltPlot, ...
            app.grid_PenetrationInfo, ...
            app.axes_Tilt, ...
            app.Reprocess)

            % link axes
            ax=[app.axes_TempAboveBWT app.axes_BottomWaterTemp app.axes_Depth app.axes_Tilt];
            linkaxes(ax,'x');

            % Plot sensor checkboxes 
            % ---------------------------
            [app.PenCheckboxes, ...
            app.FricCheckboxes, ...
            app.HPCheckboxes ...
            ] = PlotCheckboxes( ...
              app.SensorsToUse, ...
              app.NumberOfSensors, ...
              app.panel_PD_Thermistors, ...
              app.panel_FD_Thermistors, ...
              app.panel_HPD_Thermistors, ...
              app.panel_BA_Thermistors, ...
              app.panel_HF_Thermistors, ...
              app.panel_SA_Thermistors, ...
              app.h_axTempAboveBWT, ...
              app.h_axFricTempvTime, ...
              app.h_axFricTempvTau, ...
              app.h_axFricTempvTauPoints, ...
              app.h_axFricTempvTauLines, ...
              app.h_axFricRMSvTimeShift, ...
              app.h_axFricRMSvTimeShiftMinDelays, ...
              app.h_axHPTempvTime, ...
              app.h_axHPRMS, ...
              app.h_axHPRMSLine, ...
              app.h_axHPTempvInvTime, ...
              app.h_axHPTempvInvTimeBestFit, ...
              app.h_axHPTempvTimeShift, ...
              app.h_axHPTempvTimeShiftBestFit);


            % Update plot layout so that bottom water plot is hidden if no
            % bottom water sensor
            % ------------------------------------------------------------
            app.BWPlot = app.checkbox_BottomWaterPlot.Value;
            app.DepthPlot = app.checkbox_DepthPlot.Value;
            app.TiltPlot = app.checkbox_TiltPlot.Value;
            
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, ...
                app.BWPlot,app.axes_BottomWaterTemp, app.TiltPlot, ...
                app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)

            for i=1:app.NumberOfSensors
                if ~app.PenCheckboxes{i}.Value 
                    app.h_axTempAboveBWT(i).Visible = 'off';
                end
            end
            
            % Update progress message
            % ---------------------------
            loading.Value = 1;
            loading.Message = 'Finishing...';
            
            % Update layout
            % -------------------------------------------
            updateLabels(app.label_currentpathfull, app.CurrentPath, ...
                app.label_penfilename, app.PenFileName, ...
                app.label_tapfilename, app.TAPFileName, ...
                app.label_resfilename, app.ResFileName,app.label_Cruise, ...
                app.CruiseName, app.label_Station, app.StationName, ...
                app.label_Penetration, app.Penetration, app.label_Latitude, ...
                app.Latitude, app.label_Longitude, app.Longitude, ...
                app.edit_PenStart, app.PenetrationRecord, app.edit_PenEnd, ...
                app.EndRecord, app.edit_HP, app.HeatPulseRecord)
            
            app.checkbox_BottomWaterPlot.Value=0;
            app.checkbox_BottomWaterPlot.Value=1;

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, ...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, ...
                app.TempPlot, app.axes_TempAboveBWT)

            drawnow;

            app.tabgroup_Main.SelectedTab = app.tab_PenetrationData;

            figure(app.figure_Main)

            % If not heat pulse, remove HP data from GUI
            % --------------------------------------------
            if app.EndRecord > app.HeatPulseRecord && app.HeatPulseRecord~=-999
                app.PulseData = 1;
            else
                app.PulseData = 0;
                app.checkbox_UseHP.Value=0;
                app.checkbox_UseHP.Enable="off";
                app.S_Lines.S_HPLines.HPLine.Visible = 'off';
                app.grid_DataControls.RowHeight = {'1x', '0x', '1x'};
            end

            % Reset parameters for sensitivity analysis
            % -----------------------------------------
            app.Bins = 25;

%% Print to results (.res) and log (.log) files

            % Print header for .res file
            % ---------------------------
            PrintHeaderResults(app.Version, ...
                app.Update, ...
	        	app.NumberOfColumns, ...
                app.CurrentDateTime, ...
                app.ParFile,...
                app.ProgramLogId, ...
                app.LogFileId, ...
                app.LogFile, ...
                app.ResFileId, ...
                app.ResFile, ...
                app.PenFile, ...
                app.SensorDistance, ...
                app.Tilt, ...
                app.TAPName, ...
                app.TAPFileName)
        
            % Save all variables created so far in a MAT file and update LOG file
            % ------------------------------------------------------------------------   
            PrintStatus(app.LogFileId,[char(datetime('now')) ' - End of raw data ' ...
                'reading and preparation !'],1);
            PrintStatus(app.LogFileId,['==========================================' ...
                '================================'],3);
        
            PrintStatus(app.ProgramLogId, ['-- Plotting unprocessed penetration ' ...
                 'data on "Penetration Data" tab'],2)


        end

        % Value changed function: checkbox_BottomWaterPlot
        function checkbox_BottomWaterPlotValueChanged(app, event)
% ================================================================
%% Called when the bottom water plot checkbox is turned on or off.
% ================================================================    

            % Define checkbox value
            % ----------------------
            app.BWPlot = app.checkbox_BottomWaterPlot.Value;
            
            % Update plot layout
            % ------------------
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)

            % Update which sensors are shown on all plots
            % -------------------------------------------
           for i=1:app.NumberOfSensors
               if ~app.PenCheckboxes{i}.Value 
                   app.h_axTempAboveBWT(i).Visible = 'off';
               end
           end
            
            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)
        end

        % Value changed function: checkbox_DepthPlot
        function checkbox_DepthPlotValueChanged(app, event)
% ================================================================
%% Called when the depth plot checkbox is turned on or off.
% ================================================================               
           
            % Define checkbox value
            % ----------------------
            app.DepthPlot = app.checkbox_DepthPlot.Value;
            
            % Update plot layout
            % ------------------
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)

            % Update which sensors are shown on all plots
            % -------------------------------------------
           for i=1:app.NumberOfSensors
               if ~app.PenCheckboxes{i}.Value 
                   app.h_axTempAboveBWT(i).Visible = 'off';
               end
           end
            
            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

        end

        % Value changed function: checkbox_TiltPlot
        function checkbox_TiltPlotValueChanged(app, event)
% ================================================================
%% Called when the tilt plot checkbox is turned on or off.
% ================================================================     

            % Define checkbox value
            % ----------------------
            app.TiltPlot = app.checkbox_TiltPlot.Value;
            
            % Update plot layout
            % ------------------
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)

            % Update which sensors are shown on all plots
            % -------------------------------------------
           for i=1:app.NumberOfSensors
               if ~app.PenCheckboxes{i}.Value 
                   app.h_axTempAboveBWT(i).Visible = 'off';
               end
           end
           
            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)


        end

        % Button pushed function: button_SetParams
        function button_SetParamsPushed(app, event)
% =================================================================
%% Called when button for manually selecting parameters is pressed.
% =================================================================   

%% LAUNCH AUXILIARY APPLICATION: Set parameters app
            app.SetParamsDialogApp = SetParams(app,...
            app.Offset, ...
            app.NumberOfSensors, ...	
            app.WaterThermistor, ...
            app.TimeScalingFactor, ...
            app.DeltaTime, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.TempError, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.ktype, ...
            app.PulsePower, ...
            app.TimeShiftInit, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.kAnisotropy, ...
            app.TopSensorDepth, ...
            app.ProbeLength);

            % Allow user to manyally select parameters again
            app.button_SetParams.Enable = 'on';

            % Wait to execute rest of code until auxillary app is deleted
            waitfor(app.SetParamsDialogApp)

            % Convert all parameters from doubles to strings
            numsens = num2str(app.NumberOfSensors);
            wt = num2str(app.WaterThermistor);
            tsf = num2str(app.TimeScalingFactor);
            dt = num2str(app.DeltaTime);
            sr = num2str(app.SensorRadius);
            sd = num2str(app.SensorDistance);
            te = num2str(app.TempError);
            hpl = num2str(app.HeatPulseLength);
            cc = num2str(app.CalibrationCoeffs);
            hc = join(string(app.HyndmanCoeffs));
            fd = num2str(app.FrictionalDelays);
            maxstep = num2str(app.FricMaxStep);
            ti = num2str(app.TimeInc);
            fmin = num2str(app.FricTauMin);
            fmax = num2str(app.FricTauMax);
            pd = num2str(app.PulseDelays);
            kt = num2str(app.ktype);
            initialk = join(string(app.kInit));
            pp = num2str(app.PulsePower);
            tsi = num2str(app.TimeShiftInit);
            tsinc = num2str(app.TimeShiftInc);
            pmaxstep = num2str(app.PulseMaxStep);
            ktol = num2str(app.kTolerance);
            pmax = num2str(app.PulseTauMin);
            pmin = num2str(app.PulseTauMax);
            mintolchange = num2str(app.MinTotalkChange);
            maxit = num2str(app.MaxNumberOfIterations);
            maxsait = num2str(app.MaxSAIterations);
            sigk = num2str(app.Sigmak0);
            ksamin= num2str(app.kMin);
            ksamax = num2str(app.kMax);
            minthick = num2str(app.MinThickness);
            kani = num2str(app.kAnisotropy);
            tsd = num2str(app.TopSensorDepth);
            pl = num2str(app.ProbeLength);
            off = num2str(app.Offset);

            % Update parameter structure for new PAR file
            app.S_ParFile.Params = [...
            numsens ; ...
            wt ; ...
            tsf ; ...
            dt ; ...
            sr ; ...
            sd ; ...
            te ; ...
            hpl; ...
            cc ; ...
            hc ; ...
            fd ; ...
            maxstep ; ...
            ti ; ...
            fmin ; ...
            fmax ; ...
            pd ; ...
            kt ; ...
            initialk; ... 
            pp ; ...
            tsi ; ...
            tsinc ; ...
            pmaxstep; ... 
            ktol ; ...
            pmax ; ...
            pmin ; ...
            mintolchange ; ... 
            maxit ; ...
            maxsait ; ...
            sigk ; ...
            ksamin; ...
            ksamax ; ...
            minthick; ... 
            kani ; ...
            tsd;...
            pl;
            off];

             % Tell program to re-iterate for equil. temp and therm.
             % con. values with new parameters
             app.Reprocess = 1;


        end

        % Button pushed function: button_Process
        function button_ProcessButtonPushed(app, event)
% =================================================================
%% Called when button for manually selecting parameters is pressed.
% =================================================================   

%% ENABLE BUTTONS     
            % Confirm with user which pulse power to use
            % ------------------------------------------
  if app.PulsePower<=0
            PowerConfirm = uiconfirm(app.figure_Main, ['Pulse power ' ...
                'will be changed from what is read in from the ' ...
                'PEN file or MAT file. Must confirm which heat pulse ' ...
                'power to use before continuing'], 'Confirm pulse power to use', ...
                'Options',{['Use power read in from PEN and MAT file: ', num2str(app.PulsePower)], ...
                ['Use power defined in new PAR file: ', num2str(app.PulsePowerPARFile)]});
            switch PowerConfirm
                case ['Use power read in from PEN and MAT file: ', num2str(app.PulsePower)]
                    app.PulsePower = app.PulsePower;
                case ['Use power defined in new PAR file: ', num2str(app.PulsePowerPARFile)]
                    app.PulsePower = app.PulsePowerPARFile;
            end 
  end

%% ENABLE BUTTONS     
        % Enable iteration buttons, sensitivity analysis button,
        % Ignore data button, and reset penetration button
        % -------------------------------------------------------
          app.button_SensAn.Enable        = 1;
          app.button_IgnoreData.Enable    = 1;
          app.button_reset.Enable         = 1;
          app.button_NextIteration.Enable = 1;
          app.button_EndIterations.Enable = 1;

%% FLAGS 

        % If there is no penetration selected, stop
        % ------------------------------------------
        if ~exist(app.PenFile, 'file')
                uialert(app.figure_Main, 'Must select a penetration', 'ERROR', ...
                    'Icon','error')
                return

        % Else, continue
        % ------------------------------------------
        else
        
            % If the heat pulse reduction is used, but the power is zero, stop
            % ----------------------------------------------------------------
            if app.PulseData && app.PulsePower==0
                    uialert(app.figure_Main, ['You are using heat pulse ' ...
                        'reduction, but the recorded heat pulse power is 0. ' ...
                        'Either ignore heat pulse reduction or change ' ...
                        'pulse power.'], 'ERROR', ...
                        'Icon','error')
                    return
            end

%% Update all general parameters before processing begins

            % Update trial
            % -------------
            app.Trial=app.Trial+1;

            % Update start of processing time
            % ---------------------------------
            app.label_ProcessingTime.Text = string(datetime('now'));
            PrintStatus(app.LogFileId, repmat('-',1,app.NumberOfColumns),1)
            PrintStatus(app.LogFileId, [string(datetime('now')) ...
                ' - Begin SlugHeat22 PROCESSING'], 1)
            PrintStatus(app.ProgramLogId, ['===========================' ...
                '=====================================================' ...
                '=================='],2);

            PrintStatus(app.ProgramLogId, [string(datetime('now')) ...
                ' - Begin SlugHeat22 PROCESSING'], 1)
	        PrintStatus(app.ProgramLogId, ['============================' ...
                '======================================================' ...
                '================'],2);

            % Update record numbers for frictional decay and heat pulse 
            % decay in case manually changed by user
            % ---------------------------------------------------
            [app.FricTime, ...
            app.FricTemp, ...
            app.PulseData, ...
            app.PulseTime, ...
            app.PulseTemp ...
            ] = SplitDecays( ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...   
            app.AllRecords, ...
            app.TimeScalingFactor, ...
            app.AllSensorsTemp, app.LogFileId, app.PenStartChanged, ...
            app.HeatPulseChanged, app.PenEndChanged, app.ProgramLogId, ...
            app.NumberOfColumns);

            % In case iterations were paused before, update so that
            % convergence has not been met
            app.Converged = 0;

            % Set pause on or off depending on switch status
            if contains(app.switch_PauseBetweenIter.Value, 'On')
                app.Pause=1;
            elseif contains(app.switch_PauseBetweenIter.Value, 'Off')
                app.Pause=0;
            end

            % Clear current plots
            % --------------------

                % Heat Flow Analysis axes
                % ----------------------
                cla(app.axes_TempvDepth, 'reset')
                cla(app.axes_TCvDepth, 'reset')
                cla(app.axes_TempvCTR, 'reset')
                app.label_gradient.Text = '';
                app.label_depthoftop.Text = '';
                app.label_averagek.Text = '';
                app.label_heatflow.Text = ''; 
                app.label_shift.Text = '';

                % Heat Flow Regression axes
                % ----------------------
                cla(app.axes_HeatFlow, 'reset')
                cla(app.axes_Sigma, 'reset')

                % Reset all plots for senstivity analysis
                % -----------------------------------------
                delete(app.axes_SA_TCvDepth.Children)
                delete(app.axes_SA_TempvCTR.Children)
                delete(app.axes_SA_SDvIter.Children)
           
                app.grid_FricDecayAxes.RowHeight = {'1x', '1x'};
                app.grid_FricDecayAxes.ColumnWidth = {'1x', '1x'};
                app.grid_HPDecayAxes.RowHeight = {'1x', '1x'};
                app.grid_HPDecayAxes.ColumnWidth = {'1x', '1x'};
                app.grid_BullAnalysisAxes.RowHeight = {'1x'};
                app.grid_BullAnalysisAxes.ColumnWidth = {'1x', '1x', ...
                    '1x'};

            % Use heat pulse decay reduction or theoretical thermal
            % conductivity values based on 'Use HP' checkbox
            % -----------------------------------------------------
            if app.checkbox_UseHP.Value == 1 && app.PulseData == 1
                app.PulseData = 1;
                HPStatus = 'Used';
            else
                app.PulseData = 0;
                HPStatus = 'Not Used';
                app.label_HPIgnored.Visible = 'on';
            end
           
            % Set bottom water temperature for all sensors, 
            % if not already
            % -----------------------------------------------
            if length(app.BottomWaterTemp)==1
                app.BottomWaterTemp = repmat(app.BottomWaterTemp, 1, ...
                    app.NumberOfSensors);
            end

%% Choose which method for determining equilibrium temperatures 
%% and in-situ thermal conductivity
%        The 'MethodsChoice' is hardcoded to 'iterate' now. When 
%        inversion method is integrated into SlugHeat, the user 
%        will have the option to choose inversion method instead. 
%        To come...

            MethodChoice = 'Iterate';
            
            switch MethodChoice
                case 'Iterate'
            
                    % If it is the first trial or there is no heat pulse 
                    % or this is a reprocessing, continue
                    if app.Trial == 1 || (app.Trial > 0 && ~app.PulseData) ...
                        || app.Reprocess

%% INITIALIZE main loop for processing via ITERATIVE method

                        [app.A, ...
                        app.B, ...
                        app.C, ...
                        app.D, ...
                        app.E, ...
                        app.F, ...
                        app.kFunction, ...
                        app.Currentk, ...
                        app.CurrentT, ...
                        app.TChange, ...
                        app.kChange, ...
                        app.Iteration, ...
                        app.kInitial, ...
                        app.FirstIteration, ...
                        app.TotalIterations, ...
                        app.AnotherTrial, ...
                        app.Trial, ...
                        app.RelativeDepths ...
                        ] = InitializeProcessing(app.NumberOfSensors, ...
                        app.SensorDistance, ...
                        app.TopSensorDepth, ...
                        app.kInit, app.ktype, app.Trial);
            
                    end

%% BEGIN main loop for processing via ITERATIVE method

                    % Update trial label in  GUI
                    app.label_trial.Text = num2str(app.Trial);
           
                    % Begin iterative part of the computations
                    % ----------------------------------------
                    if app.Trial == 1 || ~app.PulseData  || app.Reprocess 

                        % Clear plots
                        % -------------

                        % Frictional decay axes
                        cla(app.axes_TempvTime, 'reset')
                        cla(app.axes_TempvTau, 'reset')
                        cla(app.axes_TempvBullFunc, 'reset')
                        cla(app.axes_TimeShift, 'reset')
                        
                        % Heat Pulse decay axes
                        cla(app.axes_CTempvCtime, 'reset')
                        cla(app.axes_CTempv1_CTime, 'reset')
                        cla(app.axes_MisfitvTimeShift, 'reset')
                        cla(app.axes_TempvTimeShift, 'reset')
              
                        % Determine iteration number
                        % --------------------------
                        if app.Trial > 1
                          app.Iteration = 1;
                        end
            
                        IterationCounter = 1;
                        ChosenIteration = app.MaxNumberOfIterations;

                        % Update loading box
                        % -------------------
                        loading = uiprogressdlg(app.figure_Main, ...
                            "Message",['Processing frictional decay and' ...
                            ' heat pulse decay data...']);
                        loading.Value = .3;
               
                        % ==============================================
                        %% This is the beginning MAIN LOOP for iterating!
                            % while convergence has NOT been met and
                            % when iteration number is LESS than the
                            % MAX iteration value set by the user, kee
                            % iterating
                        % ==============================================

                        while app.Iteration <= ChosenIteration && app.Converged==0 
        
                             app.label_Iteration.Text = num2str(app.Iteration);
                
                             loading = uiprogressdlg(app.figure_Main, ...
                                 "Message",['Processing: Iteration ' ...
                                 num2str(app.Iteration)]);
                             loading.Value = .4;
                
                             % Tell user if convergence criteria is 
                             % never met and the maximum number of 
                             % iterations is used               
                             if app.Iteration == ChosenIteration && app.Converged==0
                                 iter = uiconfirm(app.figure_Main, ...
                                     ['Convergence criteria never met. ' ...
                                     'Maximum number of iterations run'], ...
                                     'Did not converge', 'Icon','info', ...
                                     'Options','Okay');
                                 waitfor(iter)
                             end
                        
                             % ========================================
                             %% Compute EQUILIBRIUM TMPERATURES after 
                             %% frictional heating decay from penetration
                             % ========================================

                             if (app.Trial == 1 || (app.Trial > 1 && app.kFunction))
                        
                                 % Set tab to frictional decay reduction 
                                 app.tabgroup_Main.SelectedTab = app.tab_FricitonalDecayReduction;
                        
                                % Compute frictional decay function
                                % ---------------------------------
                                 [app.NumberOfFricUsedPoints, ...
                                         app.MinimumFricEqTemp, ...
                                         app.MinimumFricError, ...
                                         app.MinimumFricDelays, ...
                                         app.MinimumFricSlope, ...
                                         app.HPTooLow, ...
                                         FricShiftedTime, ...
                                         app.IndexOfMinimums, ...
                                         FricDataTemp, ...
                                         FricTimeShifts, ...
                                         app.ShiftedTau, ...
                                         app.DataFAT, ...
                                         FricDataLimits, ...
                                         app.b, ...
                                         app.a, ...
                                         app.RMS...
                                         ] = FrictionalDecay( ...
                                         app.figure_Main, ...
                                         app.FricTime, ...
                                         app.FricTemp, ...
                                         app.NumberOfSensors, ...
                                         app.FrictionalDelays, ...
                                         app.FricMaxStep, ...
                                         app.TimeInc, ...
                                         app.Currentk, ...
                                         app.HyndmanCoeffs, ...
                                         app.SensorRadius, ...
                                         app.FricTauMin, ...
                                         app.FricTauMax, ...
                                         app.SensorsToUse, ...
                                         loading, ...
                                         app.A, ...
                                         app.B, ...
                                         app.C, ...
                                         app.D, ...
                                         app.E, ...
                                         app.F,...
                                         app.ShiftedRelativeDepths,...
                                         app.RelativeDepths);
        
                                   % Structure of outputs from 
                                   % frictional decay
                                   % --------------------------
                                   app.S_FricResults = struct(...
                                      'NumberOfFricUsedPoints', app.NumberOfFricUsedPoints, ...
                                      'MinimumFricEqTemp', app.MinimumFricEqTemp, ...
                                      'MinimumFricError', app.MinimumFricError, ...
                                      'MinimumFricDelays', app.MinimumFricDelays, ...
                                      'MinimumFricSlope', app.MinimumFricSlope, ...
                                      'HPTooLow', app.HPTooLow, ...
                                      'FricShiftedTime', FricShiftedTime, ...
                                      'IndexOfMinimums', app.IndexOfMinimums, ...
                                      'FricDataTemp', FricDataTemp, ...
                                      'FricTimeShifts', FricTimeShifts, ...
                                      'ShiftedTau', app.ShiftedTau, ...
                                      'DataFAT', app.DataFAT, ...
                                      'FricDataLimits', FricDataLimits, ...
                                      'b', app.b, ...
                                      'a', app.a, ...
                                      'RMS', app.RMS);
                        
                                 % If frictional decay reduction failed, 
                                 % stop program and return to penetration 
                                 % tab
                                 if app.HPTooLow 
                                     app.tabgroup_Main.SelectedTab= app.tab_PenetrationData;
                                     return
                                 end
        
                                 %% Plot frictional decay reduction 
                                 %% results
                                 % ---------------------------------
                                 if app.Iteration == ChosenIteration || app.Pause == 1

                                     [app.h_axFricTempvTime, ...
                                         app.h_axFricTempvTau, ...
                                         app.h_axFricTempvTauPoints, ...
                                         app.h_axFricTempvTauLines, ...
                                         app.h_axFricRMSvTimeShift, ...
                                         app.h_axFricRMSvTimeShiftMinDelays ...
                                         ] = PlotFrictionalDecay(...
                                         app.h_axTempAboveBWT, ...
                                         app.axes_TempvTime, ...
                                         app.axes_TempvTau, ...
                                         app.axes_TempvBullFunc,...
                                         app.axes_TimeShift, ...
                                         app.SensorsToUse, ...
                                         FricShiftedTime, ...
                                         app.IndexOfMinimums, ...
                                         FricDataTemp, ...
                                         app.FricTime, ...
                                         FricTimeShifts, ...
                                         app.ShiftedTau, ...
                                         app.FricTauMax, ...
                                         app.FricTauMin, ...
                                         app.DataFAT, ...
                                         FricDataLimits, ...
                                         app.b, ...
                                         app.a, ...
                                         app.MinimumFricEqTemp, ...
                                         app.RMS, ...
                                         app.MinimumFricDelays);
                                 end
            
                                 %% Define new equilibrium sediment 
                                 %% temperatures
                                 % ---------------------------------
                                  app.PreviousT = app.CurrentT;
                                  app.CurrentT = app.MinimumFricEqTemp';
                             end
        
                             % ========================================
                             %% Compute THERMAL CONDUCTIVITIES from heat
                             %% pulse decay, if there was a heat pulse
                             % ========================================
                             if app.PulseData 
        
        
                                 % Calculate in situ thermal conductivities 
                                 % iteratively from a heat pulse decay
                                 % ------------------------------------------
                                 [ ...
                                  app.MeankPointAtMinkDiff, ...
                                  app.kSlopeAtMinkDiff, ...
                                  app.MeankPointAtZeroInfTemp, ...
                                  app.MeankPointAtMinRMS, ...
                                  app.kSlopeAtZeroInfTemp, ...
                                  app.kSlopeAtMinRMS, ...
                                  app.TempAtInf, ...
                                  app.NumberOfUsedPoints, ...
                                  app.MinimumPulseDelays, ...
                                  app.kError, ...
                                  app.HeatPulseTime, ...
                                  app.MinkDiffIndex, ...
                                  app.ShiftedTime, ...
                                  app.DataTemp, ...
                                  app.TimeShifts, ...
                                  app.kSlopeRMS, ...
                                  app.TempAtInfinity, ...
                                  app.OneOverTime, ...
                                  app.kSlope, ...
                                  app.DataLimits] = HeatPulseDecay( ...
                                      app.figure_Main,...
                                      app.SensorsToUse, ...
                                      app.PulseTime, ...
                                      app.PulseDelays, ...
                                      app.MinimumFricEqTemp, ...
                                      app.MinimumFricSlope, ...
                                      app.PulseTemp, ...
                                      app.Currentk, ...
                                      app.HyndmanCoeffs, ...
                                      app.SensorRadius, ...	
                                      app.PulseMaxStep, ...
                                      app.TimeShiftInc, ...
                                      app.PulseTauMin, ...
                                      app.PulseTauMax, ...
                                      app.PulsePower, ...
                                      app.HeatPulseLength, ...
                                      app.NumberOfSensors);
                
                                 % Structure of outputs from heat pulse 
                                 % decay reduction
                                 % ------------------------------------
                                 app.S_HPResults = struct(...
                                  'MeankPointAtMinkDiff',    app.MeankPointAtMinkDiff, ...
                                  'kSlopeAtMinkDiff',        app.kSlopeAtMinkDiff, ...
                                  'MeankPointAtZeroInfTemp', app.MeankPointAtZeroInfTemp, ...
                                  'MeankPointAtMinRMS',      app.MeankPointAtMinRMS, ...
                                  'kSlopeAtZeroInfTemp',     app.kSlopeAtZeroInfTemp, ...
                                  'kSlopeAtMinRMS',          app.kSlopeAtMinRMS, ...
                                  'TempAtInf',               app.TempAtInf, ...
                                  'NumberOfUsedPoints',      app.NumberOfUsedPoints, ...
                                  'MinimumPulseDelays',      app.MinimumPulseDelays, ...
                                  'kError',                  app.kError, ...
                                  'HeatPulseTime',           app.HeatPulseTime, ...
                                  'MinkDiffIndex',           app.MinkDiffIndex, ...
                                  'ShiftedTime',             app.ShiftedTime, ...
                                  'DataTemp',                app.DataTemp, ...
                                  'TimeShifts',              app.TimeShifts, ...
                                  'kSlopeRMS',               app.kSlopeRMS, ...
                                  'TempAtInfinity',          app.TempAtInfinity, ...
                                  'OneOverTime',             app.OneOverTime, ...
                                  'kSlope',                  app.kSlope, ...
                                  'DataLimits',              app.DataLimits);
                        
                                 % End function if failed during heat 
                                 % pulse reduction
                                 % ----------------------------------
                                 if isempty(app.MeankPointAtMinkDiff)
                                     return
                                 end
        
                                 %% Plot results of heat pulse reduction
                                 % ------------------------------------
                                 if app.Iteration == ChosenIteration || app.Pause == 1

                                      [app.h_axHPTempvTime, ...
                                       app.h_axHPRMS, ...
                                       app.h_axHPRMSLine, ...
                                       app.h_axHPTempvInvTime, ...
                                       app.h_axHPTempvInvTimeBestFit, ...
                                       app.h_axHPTempvTimeShift, ...
                                       app.h_axHPTempvTimeShiftBestFit ...
                                       ] = PlotHeatPulseDecay(...
                                           app.SensorsToUse, ...
                                           app.PulsePower, ...
                                           app.axes_CTempvCtime, ...
                                           app.axes_CTempv1_CTime, ...
                                           app.axes_MisfitvTimeShift, ...
                                           app.axes_TempvTimeShift, ...
                                           app.h_axTempAboveBWT, ...
                                           app.ShiftedTime, ...
                                           app.DataTemp, ...
                                           app.MinkDiffIndex, ...
                                           app.HeatPulseTime, ...
                                           app.TimeShifts, ...
                                           app.kSlopeRMS, ...
                                           app.TempAtInfinity, ...
                                           app.OneOverTime, ...
                                           app.MinimumPulseDelays, ...
                                           app.kSlope, ...
                                           app.DataLimits);
                                 end
        
                                 % If convergence criteria has been met, user is alerted 
                                 % and the iterations will stop and results will be plotted 
                                 app.TotkChange = abs(sum(app.kChange(find(~isnan(app.kChange)))));
                                 if app.TotkChange < app.MinTotalkChange 
                                     iter = uiconfirm(app.figure_Main,['Convergence ' ...
                                         'reached'], 'Success', 'Icon','success', 'Options','Okay');
                                     waitfor(iter)
                                     app.Converged = 1; 
                                 end
        
                                 % Plot frictional decay reduction results
                                 % ---------------------------------------
                                 if app.Converged 
                                          [app.h_axFricTempvTime, ...
                                          app.h_axFricTempvTau, ...
                                          app.h_axFricTempvTauPoints, ...
                                          app.h_axFricTempvTauLines, ...
                                          app.h_axFricRMSvTimeShift, ...
                                          app.h_axFricRMSvTimeShiftMinDelays ...
                                          ] = PlotFrictionalDecay(...
                                          app.h_axTempAboveBWT, ...
                                          app.axes_TempvTime, ...
                                          app.axes_TempvTau, ...
                                          app.axes_TempvBullFunc,...
                                          app.axes_TimeShift, ...
                                          app.SensorsToUse, ...
                                          FricShiftedTime, ...
                                          app.IndexOfMinimums, ...
                                          FricDataTemp, ...
                                          app.FricTime, ...
                                          FricTimeShifts, ...
                                          app.ShiftedTau, ...
                                          app.FricTauMax, ...
                                          app.FricTauMin, ...
                                          app.DataFAT, ...
                                          FricDataLimits, ...
                                          app.b, ...
                                          app.a, ...
                                          app.MinimumFricEqTemp, ...
                                          app.RMS, ...
                                          app.MinimumFricDelays);
                                 end
        
                                 % Plot results of heat pulse reduction
                                 % ------------------------------------
                                 if app.Converged && app.PulseData
                                      [app.h_axHPTempvTime, ...
                                       app.h_axHPRMS, ...
                                       app.h_axHPRMSLine, ...
                                       app.h_axHPTempvInvTime, ...
                                       app.h_axHPTempvInvTimeBestFit, ...
                                       app.h_axHPTempvTimeShift, ...
                                       app.h_axHPTempvTimeShiftBestFit ...
                                       ] = PlotHeatPulseDecay(...
                                           app.SensorsToUse, ...
                                           app.PulsePower, ...
                                           app.axes_CTempvCtime, ...
                                           app.axes_CTempv1_CTime, ...
                                           app.axes_MisfitvTimeShift, ...
                                           app.axes_TempvTimeShift, ...
                                           app.h_axTempAboveBWT, ...
                                           app.ShiftedTime, ...
                                           app.DataTemp, ...
                                           app.MinkDiffIndex, ...
                                           app.HeatPulseTime, ...
                                           app.TimeShifts, ...
                                           app.kSlopeRMS, ...
                                           app.TempAtInfinity, ...
                                           app.OneOverTime, ...
                                           app.MinimumPulseDelays, ...
                                           app.kSlope, ...
                                           app.DataLimits);
                                 end
                        
                                 % Define new conductivity
                                 app.Previousk = app.Currentk;
                                 app.Currentk  = app.MeankPointAtMinkDiff';
                             else
        

                                 % =======================================
                                 %% Compute HEAT FLOW if there is NO heat 
                                 %% pulse used
                                 % =======================================
        
                                  [ ...
                                  app.SensorsUsedForBullardFit, ...
                                  app.GoodkIndex, ...
                                  app.CTRToUse, ... 
                                  app.CTR, ...
                                  app.ShiftedCTR, ...
                                  app.ShiftedRelativeDepths, ...
                                  app.TToUse, ...
                                  app.kToUse, ...
                                  app.PenetrationLag, ...
                                  app.Slope, ...
                                  app.Shift, ...
                                  app.HeatFlow, app.HFErr, ...
                                  app.HFShift, app.HFShiftErr, ...
                                  app.Averagek, app.kErr, ...
                                  app.Gradient, app.GradErr, ...
                                  app.GradShift, app.GradShiftErr] = ...
                                  HeatFlowAnalysis(app.NumberOfSensors, ...
                                  app.RelativeDepths, ...
                                  app.Currentk, ...
                                  app.MinimumFricEqTemp, ...
                                  app.Badk, ...
                                  app.BadT, ...
                                  app.SensorsToUse);
        
                                  %% Structure for outputs of heat flow analysis
                                  app.S_HeatFlowResults = struct(...
                                      'SensorsUsedForBullardFit', app.SensorsUsedForBullardFit, ...
                                      'GoodkIndex', app.GoodkIndex, ...
                                      'CTRToUse', app.CTRToUse, ... 
                                      'CTR', app.CTR, ...
                                      'ShiftedCTR', app.ShiftedCTR, ...
                                      'ShiftedRelativeDepths', app.ShiftedRelativeDepths, ...
                                      'TToUse', app.TToUse, ...
                                      'kToUse', app.kToUse, ...
                                      'PenetrationLag', app.PenetrationLag, ...
                                      'Slope', app.Slope, ...
                                      'Shift', app.Shift, ...
                                      'HeatFlow', app.HeatFlow, ...
                                      'HFErr', app.HFErr, ...
                                      'HFShift', app.HFShift, ...
                                      'HFShiftErr', app.HFShiftErr, ...
                                      'Averagek', app.Averagek, ...
                                      'kErr', app.kErr, ...
                                      'Gradient', app.Gradient, ...
                                      'GradErr', app.GradErr, ...
                                      'GradShift', app.GradShift, ...
                                      'GradShiftErr', app.GradShiftErr);
                        
                                 %Define new conductivty
                                 app.Previousk = app.Currentk;
                                 if app.kFunction
                                     app.Currentk = app.A + app.B*app.ShiftedRelativeDepths + app.C*app.ShiftedRelativeDepths.^2 + app.D*exp(app.E*app.ShiftedRelativeDepths) + app.F*log10(app.ShiftedRelativeDepths);
                                 else
                                     app.Currentk = app.kInitial;
                                 end
                                 if app.Iteration == 1 
                                     delete(app.axes_TCvDepth.Children)
                                     delete(app.axes_TempvCTR.Children)
                                 end
                        
                             end
        
                             % Update temperature and conductivity 
                             % -------------------------------------------------
                             app.TChange = abs(app.CurrentT-app.PreviousT);
                             app.kChange = abs(app.Currentk-app.Previousk);
                        
        
                             % Next iteration
                             % -----------------------
                             app.Iteration = app.Iteration + 1;
                             app.TotalIterations = app.TotalIterations + 1;
                             IterationCounter = IterationCounter +1;
            
                             app.FirstIteration = 0;
                        
                             if app.PulseData
                                 app.tabgroup_Main.SelectedTab = app.tab_HeatPulseDecayReduction;
                             else
                                 app.tabgroup_Main.SelectedTab = app.tab_FricitonalDecayReduction;
                             end
        
                             drawnow;
        
                             if app.Iteration <= app.MaxNumberOfIterations && ~app.Converged 

                                 % Delete plots from Heat Flow Analysis 
                                 % except last iteration 
                                 % ---------------------------------------
                                 delete(app.axes_TempvDepth.Children)
                                 delete(app.axes_TCvDepth.Children)
                                 delete(app.axes_TempvCTR.Children)

                             end
        
                             app.TotkChange = abs(sum(app.kChange(find(~isnan(app.kChange)))));
                             if app.TotkChange < app.MinTotalkChange 
                                 uialert(app.figure_Main,['Convergence ' ...
                                     'reached'], 'Success', 'Icon','success');
                                 app.Converged = 1; 
                             end
        
                             % Plot frictional decay reduction results
                             % ---------------------------------------
                             if app.Converged 
                                      [app.h_axFricTempvTime, ...
                                      app.h_axFricTempvTau, ...
                                      app.h_axFricTempvTauPoints, ...
                                      app.h_axFricTempvTauLines, ...
                                      app.h_axFricRMSvTimeShift, ...
                                      app.h_axFricRMSvTimeShiftMinDelays ...
                                      ] = PlotFrictionalDecay(...
                                      app.h_axTempAboveBWT, ...
                                      app.axes_TempvTime, ...
                                      app.axes_TempvTau, ...
                                      app.axes_TempvBullFunc,...
                                      app.axes_TimeShift, ...
                                      app.SensorsToUse, ...
                                      FricShiftedTime, ...
                                      app.IndexOfMinimums, ...
                                      FricDataTemp, ...
                                      app.FricTime, ...
                                      FricTimeShifts, ...
                                      app.ShiftedTau, ...
                                      app.FricTauMax, ...
                                      app.FricTauMin, ...
                                      app.DataFAT, ...
                                      FricDataLimits, ...
                                      app.b, ...
                                      app.a, ...
                                      app.MinimumFricEqTemp, ...
                                      app.RMS, ...
                                      app.MinimumFricDelays);
                             end
                
                              % Plot results of heat pulse reduction
                              % ------------------------------------
                             if app.Converged && app.PulseData
                                      [app.h_axHPTempvTime, ...
                                       app.h_axHPRMS, ...
                                       app.h_axHPRMSLine, ...
                                       app.h_axHPTempvInvTime, ...
                                       app.h_axHPTempvInvTimeBestFit, ...
                                       app.h_axHPTempvTimeShift, ...
                                       app.h_axHPTempvTimeShiftBestFit ...
                                       ] = PlotHeatPulseDecay(...
                                           app.SensorsToUse, ...
                                           app.PulsePower, ...
                                           app.axes_CTempvCtime, ...
                                           app.axes_CTempv1_CTime, ...
                                           app.axes_MisfitvTimeShift, ...
                                           app.axes_TempvTimeShift, ...
                                           app.h_axTempAboveBWT, ...
                                           app.ShiftedTime, ...
                                           app.DataTemp, ...
                                           app.MinkDiffIndex, ...
                                           app.HeatPulseTime, ...
                                           app.TimeShifts, ...
                                           app.kSlopeRMS, ...
                                           app.TempAtInfinity, ...
                                           app.OneOverTime, ...
                                           app.MinimumPulseDelays, ...
                                           app.kSlope, ...
                                           app.DataLimits);
                             end

                             % If the function for pausing between 
                             % iterations is on, tell the
                             % program to stop iterating
                             if app.Pause == 1 
                                 close(loading)
                                 uiwait(app.figure_Main)
                             end
                        end

                        if app.Pause

                            n=app.Iteration;
                            iter=cell(n,1);
                            for i=1:n
                                iter{i} = ['Iteration ' num2str(i)];
                            end
    
                        end 
                    end

                    % Switch tab to heat flow reduction
                    app.tabgroup_Main.SelectedTab = app.tab_HeatFlowAnalysis;
                    
                    % Update loading message
                    loading = uiprogressdlg(app.figure_Main,"Message", ...
                        'Plotting frictional decay and heat pulse decay data...');
                    loading.Value = .6;   

                    % ==================================================
                    %% Compute HEAT FLOW if there is a heat pulse used
                    % ==================================================
                    if app.PulseData

                      if all(isnan(app.Currentk))
                        uialert(app.figure_Main,'Failure to determine thermal conductivity. Must reprocess or set a turn off heat pulse decay reduction','ERROR', 'Icon','error')
                        return
                      end

                        % Update loading message
                        loading.Message = 'Calculating heat flow...';
                        loading.Value = .7;
                    
                        % Change tab to heat flow analysis
                        app.tabgroup_Main.SelectedTab = app.tab_HeatFlowAnalysis;
                    
                        %% Heat Flow Analysis 
                        % ------------------
            
                          [ ...
                          app.SensorsUsedForBullardFit, ...
                          app.GoodkIndex, ...
                          app.CTRToUse, ... 
                          app.CTR, ...
                          app.ShiftedCTR, ...
                          app.ShiftedRelativeDepths, ...
                          app.TToUse, ...
                          app.kToUse, ...
                          app.PenetrationLag, ...
                          app.Slope, ...
                          app.Shift, ...
                          app.HeatFlow, app.HFErr, ...
                          app.HFShift, app.HFShiftErr, ...
                          app.Averagek, app.kErr, ...
                          app.Gradient, app.GradErr, ...
                          app.GradShift, app.GradShiftErr] = HeatFlowAnalysis(app.NumberOfSensors, ...
                          app.RelativeDepths, ...
                          app.Currentk, ...
                          app.MinimumFricEqTemp, ...
                          app.Badk, ...
                          app.BadT, ...
                          app.SensorsToUse);
            
                          % Structure for outputs of heat flow analysis
                          % ---------------------------------------------
                          app.S_HeatFlowResults = struct(...
                              'SensorsUsedForBullardFit', ...
                              app.SensorsUsedForBullardFit, ...
                              'GoodkIndex', app.GoodkIndex, ...
                              'CTRToUse', app.CTRToUse, ... 
                              'CTR', app.CTR, ...
                              'ShiftedCTR', app.ShiftedCTR, ...
                              'ShiftedRelativeDepths', ...
                              app.ShiftedRelativeDepths, ...
                              'TToUse', app.TToUse, ...
                              'kToUse', app.kToUse, ...
                              'PenetrationLag', app.PenetrationLag, ...
                              'Slope', app.Slope, ...
                              'Shift', app.Shift, ...
                              'HeatFlow', app.HeatFlow, ...
                              'HFLRUnc', app.HFErr, ...
                              'HFShift', app.HFShift, ...
                              'HFShiftErr', app.HFShiftErr, ...
                              'Averagek', app.Averagek, ...
                              'kErr', app.kErr, ...
                              'Gradient', app.Gradient, ...
                              'GradErr', app.GradErr, ...
                              'GradShift', app.GradShift, ...
                              'GradShiftErr', app.GradShiftErr);
                    end

                    % Update loading message
                    loading.Message = 'Plotting heat flow plots...';
                    loading.Value = .8;

                    %% Plot heat flow analysis after iterating
                    % -----------------------------------------
                    [app.S_BullPlots ...
                     ] = PlotHeatFlowAnalysis( ...
                      app.Slope, ...
                      app.PenetrationLag,...
                      app.RelativeDepths, ...
                      app.ShiftedRelativeDepths, ...
                      app.CTR, ...
                      app.ShiftedCTR, ...
                      app.Currentk, ...
                      app.MinimumFricEqTemp, ...
                      app.Badk, ...
                      app.BadT, ...
                      app.TToUse, ...
                      app.kToUse, ...
                      app.CTRToUse, ...
                      app.h_axTempAboveBWT, ...
                      app.SensorsToUse, ...
                      app.axes_TempvDepth, ...
                      app.axes_TCvDepth, ...
                      app.axes_TempvCTR, ...
                      app.HeatFlow, ...
                      app.HFErr,  ...
                      app.HFShift,  ...                    
                      app.Averagek, ...
                      app.Gradient, ...
                      app.label_gradient, ...
                      app.label_depthoftop, ...
                      app.label_averagek, ...
                      app.label_heatflow, ...
                      app.label_shift,...
                      app.label_sigLRTitle,...
                      app.label_sigLR);
          
                    % ========================================
                    %% Compute HEAT FLOW REGRESSION analysis
                    % ========================================
                
                    % Update loading message
                    loading.Message = 'Processing heat flow regresion analysis...';
                    loading.Value = .9;
                    app.tabgroup_Main.SelectedTab=app.tab_HeatFlowRegressionAnalysis;

                    %% Heat flow regression (scatter) analysis 
                    % ----------------------------------------
                    [app.Scatter, ...
                    app.ScatterHeatFlow, ...
                    app.Sigmaa, ...
                    app.Sigmab] =  HeatFlowRegression(...
                        app.SensorsUsedForBullardFit, ...
                        app.GoodkIndex, ...
                        app.ShiftedCTR, ...
                        app.MinimumFricEqTemp, ...
                        app.MinimumFricError);
    
                    % Heat flow regressions results structure
                    S_HFRegression = struct('Scatter', app.Scatter, ...
                        'ScatterHeatFlow', app.ScatterHeatFlow,...
                        'Sigmaa',app.Sigmaa, 'Sigmab',app.Sigmab);

                    %% Plot results form heat flow regression (scatter)
                    %% analysis
                    % ------------------------------------------------
                    [app.HFLine, ...
                        app.ScatterLine, ...
                        app.SigmaHFLine, ...
                        app.SigmaScatterLine] = PlotHeatFlowRegression(...
                        app.axes_HeatFlow, ...
                        app.axes_Sigma, ...
                        app.SensorsUsedForBullardFit, ...
                        app.ScatterHeatFlow, ...
                        app.Scatter, ...
                        app.Sigmab);
                
            end

%% Finalize processing

            % Close loading message
            % -----------------------
            loading.Message = 'Finishing processing...';
            loading.Value = 1;
            app.tabgroup_Main.SelectedTab=app.tab_HeatFlowAnalysis;
            close(loading)
        
            % Update plot colors
            % -------------------
            app.axes_TempvTime.Color = app.LightGrey;
            app.axes_TempvBullFunc.Color = app.LightGrey;
            app.axes_TempvTau.Color = app.LightGrey;
            app.axes_TimeShift.Color = app.LightGrey;
            
            app.axes_TempvTimeShift.Color = app.LightGrey;
            app.axes_MisfitvTimeShift.Color = app.LightGrey;
            app.axes_CTempv1_CTime.Color = app.LightGrey;
            app.axes_CTempvCtime.Color = app.LightGrey;
            
            app.axes_TempvCTR.Color = app.LightGrey;
            app.axes_TCvDepth.Color = app.LightGrey;
            app.axes_TempvDepth.Color = app.LightGrey;
            
            app.axes_HeatFlow.Color = app.LightGrey;
            app.axes_Sigma.Color = app.LightGrey;


            % Save results to summary table
            % -------------------------------
            Cruise = app.CruiseName;
            Station = num2str(app.StationName);
            Pen = num2str(app.Penetration);
            Iter = num2str(app.Iteration-1);
            HP = num2str(app.PulsePower);
            if app.PulseData == 1
                HP = [HPStatus, ' (',HP, ' J/m)'];
            else
                HP = HPStatus;
            end
            
            BW = [num2str(round(app.edit_bottomwater.Value,1))];
            TrialNum = num2str(app.Trial);
            TotNumSens = num2str(app.NumberOfSensors);
            IgnoredSens = strjoin(string(app.IgnoredSensors));
            SensorsUsed = strjoin(string(app.SensorsToUse));
            SensorsTemp = setdiff(app.SensorsToUse, app.BadT);
            SensorsTemp = strjoin(string(SensorsTemp));
            SensorsNoTemp = strjoin(string(app.BadT));
            Sensorsk = setdiff(app.SensorsToUse, app.Badk);
            Sensorsk = strjoin(string(Sensorsk));
            SensorsNok = strjoin(string(app.Badk));
            TiltPen = num2str(round(mean(app.Tilt), 1));
            Grad = [num2str(round(app.Gradient,3))]; 
            Avk = [num2str(round(app.Averagek,1))]; 
            HF = [num2str(app.HeatFlow)]; 
            HFLRUnc = [num2str(round(app.HFErr,2))];
            CTRShift = [num2str(-app.HFShift,'%6.2f')]; 
            Notes = '';
    
            % Heat Flow Summary Table
            app.HFData = [Cruise, Station, Pen, TrialNum, Iter, HP, BW, ...
                TotNumSens, IgnoredSens, SensorsNoTemp, SensorsNok, ...
                TiltPen, Grad, Avk, HF, HFLRUnc,CTRShift, Notes];
            app.table_HFSummary.Data = [app.table_HFSummary.Data; ...
                app.HFData];
            HFResultsSummary = app.table_HFSummary;

            if isdeployed
                 save([app.AppPath '/outputs/HFResultsSummaryTable'], 'HFResultsSummary');
            else
                save([app.CurrentPath '/outputs/HFResultsSummaryTable'], 'HFResultsSummary');
            end

            app.button_saveresults.Enable='on';
    
            % Begin Error and Analysis Table
            app.ErrData = {Cruise, Station, Pen, TrialNum};
            app.Iteration = app.Iteration-1;
    
%% Print results to results (.res) file
    
            % Print out new Trial to RES file
            % ----------------------------------
            PrintStatus(app.ResFileId, ' ', 2)
            PrintStatus(app.ResFileId, '=====================', 2)
            PrintStatus(app.ResFileId, ['     TRIAL #', num2str(app.Trial)             ], 2)
            PrintStatus(app.ResFileId, '=====================', 3)
    
            % Print out sensor info to RES file
            % ----------------------------------
            PrintStatus(app.ResFileId, 'Sensors used:', 1)
            PrintStatus(app.ResFileId, '-------------------', 2)
            PrintStatus(app.ResFileId, ['Sensors included in processing:  ', SensorsUsed], 1)
            PrintStatus(app.ResFileId, ['Sensors with equilibrium temperature determinations included in heat flow determinations:  ', SensorsTemp], 1)
            PrintStatus(app.ResFileId, ['Sensors with thermal conductivity determinations included in heat flow determinations:  ', Sensorsk], 1)
    
            % Print out new PAR file 
            % ----------------------
            PrintNewPar(app.PulsePower, app.PenFileName, app.S_ParFile, ...
                app.CurrentPath, app.AppOutputs);   
    
            % Print final input parameters to RES file
            % -------------------------------------------------
            PrintParametersResults(...
			app.NumberOfColumns, ...
			app.NumberOfSensors, ...
            app.TimeScalingFactor, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.CalibrationCoeffs, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.ktype, ...
            app.PulsePower, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.kAnisotropy, ...
            app.TopSensorDepth, ...
            app.ResFileId)

            % Print frictional decay reduction results to RES file
            % ------------------------------------------------------
            PrintFricResults(...
            app.FricTime, ...
            app.NumberOfFricUsedPoints, ...
            app.MinimumFricEqTemp, ...
            app.MinimumFricError, ...
            app.SensorDistance, ...
            app.MinimumFricDelays, ...
            app.MinimumFricSlope, ...
            app.TChange, ...
            app.ResFileId, ...
            app.NumberOfColumns, ...
            app.Iteration, ...
            app.Trial, ...
            app.PulseData, ...
            app.SensorsToUse)

            % Print heat pulse decay reduction results to RES file
            % -----------------------------------------------------
            if app.PulseData
            PrintHeatPulseResults(app.PulseTime, ...
                app.NumberOfUsedPoints, ...
                app.MeankPointAtMinkDiff, ...
                app.kError, ...
                app.TempAtInf, ...
                app.MinimumPulseDelays, ...
                app.kSlopeAtMinkDiff, ...
                app.MeankPointAtZeroInfTemp, ...
                app.MeankPointAtMinRMS, ...
                app.kSlopeAtMinRMS, ...
                app.kSlopeAtZeroInfTemp, ...
                app.Iteration, ...
                app.ResFileId, ...
                app.SensorsToUse, ...
                app.Trial)
            end

            % Print heat flow reduction results to RES file
            % ----------------------------------------------
            PrintBullardResults(app.ResFileId, ...
            app.Iteration, ...
            app.Trial, ...
            app.PulseData, ...
            app.TChange, ...
            app.SensorsToUse, ...
            app.ShiftedRelativeDepths, ...
            app.MinimumFricEqTemp, ...
            app.BottomWaterTemp, ...
            app.MinimumFricError, ...
            app.Currentk, ...
            app.HeatFlow, ...
            app.HFErr, ...
            app.HFShift, ...
            app.HFShiftErr, ...
            app.Gradient, ...
            app.GradErr, ...
            app.Converged,...
            app.TotkChange, ...
            app.kChange)


%% Save results

            if app.PulseData==0
                app.S_HPResults=[];
            end
       
            app.S_Results = struct('Parameters', app.S_ParFile, 'PenetrationInfo', ...
                app.S_MATFile,'HeatFlowResults', app.S_HeatFlowResults, ...
                'FrictionalDecayResults', app.S_FricResults, 'HeatPulseDecayResults', app.S_HPResults, 'HeatFlowRegressionResults', S_HFRegression);
            ResultsStruct=app.S_Results;
        
            if isdeployed
                save([app.AppOutputs app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4) '_HF-Summary.mat'], 'HFResultsSummary');
                save([app.AppOutputs app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4) '_Results.mat'], 'ResultsStruct')
            else
                save([app.CurrentPath '/outputs/' app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4) '_HF-Summary.mat'], 'HFResultsSummary');
                save([app.CurrentPath '/outputs/' app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4) '_Results.mat'], 'ResultsStruct')
            end

            % Alert user that .res output file is created
            % --------------------------------------------------------       
            uialert(app.figure_Main, ['Output files created for penetration: ' ...
               '' newline newline ...
               app.ResFileName newline ...
               [app.PenFileName(1:end-4) '.par' newline ...
                app.PenFileName(1:end-4) '_Results.mat' newline ...
                app.PenFileName(1:end-4) '_HF-Summary.mat'] newline], ...
               'Success', 'Icon','success')

            % Save results to excel
            % -----------------------
            button_saveresultsPushed(app);

            % Save figures 
            % --------------
            % Define where to save figures
            if isdeployed
                savespot =  [app.AppOutputs app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4)];
            else
                savespot =  [app.CurrentPath '/outputs/' app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4)];
            end

            % Set file names 
                pen = '_pen';
                gradient = '_grad';
                kdepth = '_kdepth';
                bullard = '_hf';
            
            % Export figures
            exportgraphics(app.axes_TempAboveBWT, [savespot pen '.eps'],'ContentType','vector');
            exportgraphics(app.axes_TempvDepth, [savespot gradient '.eps'],'ContentType','vector');
            exportgraphics(app.axes_TCvDepth, [savespot kdepth '.eps'],'ContentType','vector');
            exportgraphics(app.axes_TempvCTR, [savespot bullard '.eps'],'ContentType','vector');

        end
        end

        % Button pushed function: button_Restart
        function button_RestartPushed(app, event)
% =================================================================
%% Called when button for restarting entire program is pressed.
% =================================================================   

            % Reset general processing parameters
            % ----------------------------------------
            app.SensorsToUse = app.AllSensors;
            app.Trial = 0;
            app.Reprocess = 0;
            app.UseWaterSensor = 1;
            app.WaterThermistor = 1;
             
            % Reset GUI
            % ----------
            ResetAll(app.axes_TempAboveBWT, app.axes_BottomWaterTemp, ...
                app.axes_Depth, app.axes_Tilt, app.axes_TempvTime, app.axes_TempvTau, ...
                app.axes_TempvBullFunc, app.axes_TimeShift, app.axes_CTempvCtime, ...
                app.axes_CTempv1_CTime, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift, ...
                app.axes_TempvDepth, app.axes_TCvDepth, app.axes_TempvCTR, ...
                app.axes_HeatFlow, app.axes_Sigma, ...
                app.label_gradient, app.label_depthoftop, app.label_averagek, app.label_heatflow, app.label_shift, ...
                app.label_trial, app.label_Iteration, app.label_currentpathfull, ...
                app.label_parfilename, app.label_penfilename, app.label_tapfilename, ...
                app.label_resfilename, ...
                app.edit_PenStart, app.edit_HP, app.edit_PenEnd, ...
                app.checkbox_BottomWaterPlot, app.checkbox_DepthPlot, ...
                app.checkbox_TiltPlot, app.PenCheckboxes, app.FricCheckboxes, app.HPCheckboxes, ...
                app.checkbox_UseHP, ...
                app.S_MATFile, app.NumberOfSensors, app.ProgramLogId, app.SensorsToUse, app.label_HPIgnored, app.lamp_ParFile);

            % Reset all plots for senstivity analysis
            % -----------------------------------------
            delete(app.axes_SA_TCvDepth.Children)
            delete(app.axes_SA_TempvCTR.Children)
            delete(app.axes_SA_SDvIter.Children)

            % Reset parameters to default PAR file
            % -------------------------------------------------

            [app.S_ParFile, ...
            app.NumberOfSensors, ...	
            app.WaterThermistor, ...
            app.TimeScalingFactor, ...
            app.DeltaTime, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.TempError, ...
            app.CalibrationCoeffs, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.ktype, ...
            app.PulsePowerPARFile, ...
            app.TimeShiftInit, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.kAnisotropy, ...
            app.TopSensorDepth, ...
            app.ProbeLength,...
            app.Offset] = ReadParFile(app.ParFile, app.ProgramLogId, ...
            append.ParFileName, app.CurrentPath);

        end

        % Value changed function: edit_PenStart
        function edit_PenStartValueChanged(app, event)
% ====================================================================
%% Called when penetration start time text edit field value is changed.
% =====================================================================   
            
            % Define new value
            % -----------------
            app.PenetrationRecord = app.edit_PenStart.Value;

            % Delete old penetration time lines 
            delete(app.S_Lines.S_PenLines.PenLine)
            delete(app.S_Lines.S_PenLines.PenBWTLine)
            delete(app.S_Lines.S_PenLines.PenDepthLine)
            delete(app.S_Lines.S_PenLines.PenTiltLine)

            % Plot new penetration time lines
            % ---------------------------------
            app.S_Lines.S_PenLines.PenLine = xline(app.axes_TempAboveBWT, app.PenetrationRecord, '--k');
            app.S_Lines.S_PenLines.PenLine.Label = 'Start of Penetration';
            app.S_Lines.S_PenLines.PenLine.FontSize = 16;
            app.S_Lines.S_PenLines.PenLine.FontWeight = 'bold';
            app.S_Lines.S_PenLines.PenBWTLine = xline(app.axes_BottomWaterTemp, app.PenetrationRecord, '--k');
            app.S_Lines.S_PenLines.PenDepthLine = xline(app.axes_Depth, app.PenetrationRecord, '--k');
            app.S_Lines.S_PenLines.PenTiltLine = xline(app.axes_Tilt, app.PenetrationRecord, '--k');

            app.PenStartChanged=1;

        end

        % Value changed function: edit_HP
        function edit_HPValueChanged(app, event)
% ====================================================================
%% Called when heat pulse start time text edit field value is changed.
% =====================================================================  
                
                % Define new value
                % -----------------
                value = app.edit_HP.Value;
                app.HeatPulseRecord = value;

                % Delete old penetration time lines 
                % ----------------------------------
                delete(app.S_Lines.S_HPLines.HPLine)
                delete(app.S_Lines.S_HPLines.HPBWTLine)
                delete(app.S_Lines.S_HPLines.HPDepthLine)
                delete(app.S_Lines.S_HPLines.HPTiltLine)

                % Plot new penetration time lines
                % ----------------------------------
                app.S_Lines.S_HPLines.HPLine = xline(app.axes_TempAboveBWT, app.HeatPulseRecord, '--k');
                app.S_Lines.S_HPLines.HPLine.Label = 'Heat Pulse';
                app.S_Lines.S_HPLines.HPLine.FontSize = 16;
                app.S_Lines.S_HPLines.HPLine.FontWeight = 'bold';
                app.S_Lines.S_HPLines.HPBWTLine = xline(app.axes_BottomWaterTemp, app.HeatPulseRecord, '--k');
                app.S_Lines.S_HPLines.HPDepthLine = xline(app.axes_Depth, app.HeatPulseRecord, '--k');
                app.S_Lines.S_HPLines.HPTiltLine = xline(app.axes_Tilt, app.HeatPulseRecord, '--k');

            
                app.HeatPulseChanged=1;

        end

        % Value changed function: edit_PenEnd
        function edit_PenEndValueChanged(app, event)
% ====================================================================
%% Called when penetration end time text edit field value is changed.
% =====================================================================  
            
            % Define new value
            % ----------------
            app.EndRecord = app.edit_PenEnd.Value;

            % Delete old penetration time lines 
            % ----------------------------------
            delete(app.S_Lines.S_EndPenLines.EndLine)
            delete(app.S_Lines.S_EndPenLines.EndBWTLine)
            delete(app.S_Lines.S_EndPenLines.EndDepthLine)
            delete(app.S_Lines.S_EndPenLines.EndTiltLine)

            % Plot new penetration time lines
            % ----------------------------------
            app.S_Lines.S_EndPenLines.EndLine = xline(app.axes_TempAboveBWT, app.EndRecord, '--k');
            app.S_Lines.S_EndPenLines.EndLine.Label = 'End of Penetration';
            app.S_Lines.S_EndPenLines.EndLine.FontSize = 16;
            app.S_Lines.S_EndPenLines.EndLine.FontWeight = 'bold';
            app.S_Lines.S_EndPenLines.EndBWTLine = xline(app.axes_BottomWaterTemp, app.EndRecord, '--k');
            app.S_Lines.S_EndPenLines.EndDepthLine = xline(app.axes_Depth, app.EndRecord, '--k');
            app.S_Lines.S_EndPenLines.EndTiltLine = xline(app.axes_Tilt, app.EndRecord, '--k');

            app.PenEndChanged=1;

        end

        % Size changed function: figure_Main
        function figure_MainSizeChanged(app, event)
% ===========================================
%% Called when the main figure size changes.
% ===========================================

            % Align X axes of all visibile plots on the PENETRATION DATA
            % tab
            % ----------------------------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, ...
                app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)

            % Align X axes of the two time shift plots on the HEAT PULSE
            % DECAY tab
            % ----------------------------------------------------------
            
            xAlignHPD(app.button_ShowAll_HP, app.axes_MisfitvTimeShift, ...
                app.axes_TempvTimeShift)

        end

        % Button pushed function: button_IgnoreData
        function button_IgnoreDataPushed(app, event)
% ===================================================================
%% Called when the user chooses to discard data using the 
%% Ignore Data button.
% ===================================================================

            % If there is not a heat pulse, discard with this function
            % --------------------------------------------------------
            if ~app.PulseData
                [app.A, app.B, app.C, app.D, app.E, app.F, ...
                app.kInitial, ...
                app.kFunction...
                ] = DiscardSensorsNoHP( ...
                app.kInit, ...
                app.ktype, ...
                app.PulseData, ...
                app.NumberOfSensors, ...
                app.SensorDistance, ...
                app.TopSensorDepth, ...
                app.kInitial);
            else
                app.IgnoredSensors(app.IgnoredSensors==0) = [];
                app.SensorsToUse = app.AllSensors;
            end

%% Call AUXILIARY APPLICATION to allow user to define which
%% data from which sensors to ignore

            app.DiscardDataDialogApp = DiscardData(app, app.IgnoredSensors, ...
                app.NumberOfSensors, app.AllSensors, app.h_axTempAboveBWT, ...
                app.LogFileId, app.ProgramLogId, app.Badk, app.BadT);

            % Turn off pause between iterations
            app.switch_PauseBetweenIter.Value = 'Off';
            app.Pause = 0;
            
            app.Reprocess=0;
           
            
        end

        % Value changed function: checkbox_TempvDepth
        function checkbox_TempvDepthValueChanged(app, event)
% ===================================================================
%% Called when user wants to turn off or on Temp vs. Depth plot.
% ===================================================================            
            
            % Define value of checkbox (on or off) 
            % ------------------------------------
            app.BullTempvDepthPlot = app.checkbox_TempvDepth.Value;
            
            % Define which plots are on this tab
            % ----------------------------------
            ap = num2str(app.BullTempvDepthPlot);
            bp = num2str(app.TCvDepthPlot);
            cp = num2str(app.TempvCTRPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
            cpx = strcat(cp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_BullAnalysisAxes.ColumnWidth = {apx, bpx, cpx};

            % If this plot is OFF, must update Y axis label for TC vs.
            % Depth
            % ---------------------------------------------------------
            if app.BullTempvDepthPlot == 0
                app.axes_TCvDepth.YLabel = app.axes_TempvDepth.YLabel;
                app.axes_TCvDepth.YTickLabel = app.axes_TempvDepth.YTickLabel;
            else
                ylabel(app.axes_TCvDepth, [])
                app.axes_TCvDepth.YTickLabel = [];
                ylabel(app.axes_TempvDepth, 'Relative Depth (m)', ...
                'FontWeight','bold', 'FontSize',16);

            end
            
        end

        % Value changed function: checkbox_TCvDepth
        function checkbox_TCvDepthValueChanged(app, event)
% ==============================================================================
%% Called when user wants to turn off or on Thermal Conductivity vs. Depth plot.
% ============================================================================== 
            
            % Define value of checkbox (on or off) 
            % ------------------------------------
            app.TCvDepthPlot = app.checkbox_TCvDepth.Value;

            % Define which plots are on this tab
            % ----------------------------------
            ap = num2str(app.BullTempvDepthPlot);
            bp = num2str(app.TCvDepthPlot);
            cp = num2str(app.TempvCTRPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
            cpx = strcat(cp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_BullAnalysisAxes.ColumnWidth = {apx, bpx, cpx};
            
        end

        % Value changed function: checkbox_TempvCTR
        function checkbox_TempvCTRValueChanged(app, event)
% =========================================================
%% Called when user wants to turn off or on Bullard plot.
% =========================================================           
            
            % Define value of checkbox (on or off) 
            % ------------------------------------
            app.TempvCTRPlot = app.checkbox_TempvCTR.Value;

            % Define which plots are on this tab
            % ----------------------------------
            ap = num2str(app.BullTempvDepthPlot);
            bp = num2str(app.TCvDepthPlot);
            cp = num2str(app.TempvCTRPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
            cpx = strcat(cp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_BullAnalysisAxes.ColumnWidth = {apx, bpx, cpx};
            
        end

        % Button pushed function: button_NextIteration
        function button_NextIterationPushed(app, event)
% ==============================================
%% Called when next iteration button is pressed.
% ==============================================

            % Reset all frictional and heat pulse decay axes for new
            % iteration plots
            % ---------------------------------------------------
            delete(app.axes_TimeShift.Children)
            delete(app.axes_TempvTau.Children)
            delete(app.axes_TempvBullFunc.Children)
            delete(app.axes_TempvTime.Children)

            delete(app.axes_TempvTimeShift.Children)
            delete(app.axes_MisfitvTimeShift.Children)
            delete(app.axes_CTempv1_CTime.Children)
            delete(app.axes_CTempvCtime.Children)

            % Resume iterative processig while loop until max iteration
            % number
            % ----------------------------------------------------------
            uiresume(app.figure_Main)
            drawnow;

            
        end

        % Button pushed function: button_EndIterations
        function button_EndIterationsPushed(app, event)
% ==============================================
%% Called when end iterations button is pressed.
% ==============================================            
            
            % Manually notify app that convergence criteria has been met 
            % ----------------------------------------------------------
            app.Converged = 1;
            app.Pause=0;

            % Resume iterative processig while loop until max iteration
            % number
            % -------------------------------------------------------
            uiresume(app.figure_Main)


        end

        % Button pushed function: button_saveresults
        function button_saveresultsPushed(app, event)
% ==============================================
%% Called when save results button is pressed.
% ==============================================      

             % Get heat flow results summary table on final tab of GUI.
             % -------------------------------------------------------
             HFResultsSummary = app.table_HFSummary;
            
             % Save results to excel and .mat files, if these files do not
             % already exist in the same folder.
             % -------------------------------------------------------
             if isdeployed
                if ~exist([app.AppOutputs 'HFResultsSummary.xlsx'], "file")
                
                    % Save the results from heat flow summary table along with
                    % column names to a .mat file and .xlsx file
                    save([app.AppOutputs 'HFResultsSummaryTable'], 'HFResultsSummary');
                    HeatFlowResults = [app.table_HFSummary.ColumnName'; HFResultsSummary.Data];
                    writematrix(HeatFlowResults, [app.AppOutputs 'HFResultsSummary.xlsx']);
                
                    % Inform user that the two files were saved in the current
                    % directory
                    uialert(app.figure_Main, ['Heat flow results table saved as MAT file ' ... 
                    ' and as EXCEL file in your current path outputs subfolder (' ...
                    app.AppOutputs newline newline ...
                    'HFResultsSummaryTable.mat' newline ...
                    'HFResultsSummary.xlsx'], 'SAVED', 'Icon','success')
    
    
                % If theses files do already exist in the same folder, tell
                % user that these files must be moved or they will be
                % overwritten.
                % -------------------------------------------------------
                else
    
                    % Inform user that these files need to be removed or 
                    % moved in order to save a new one
                    uialert(app.figure_Main, ['Heat flow results table already ' ...
                        'exists in ' app.AppOutputs newline newline ...
                    ['Must move or remove the following files from this directory and ' ...
                    'save again:'] newline newline ...
                    'HFResultsSummaryTable.mat' newline ...
                    'HFResultsSummary.xlsx'], 'MUST REMOVE EXISTING RESULTS FILE', ...
                    'Icon','error')
                    return
                end
             else
                if ~exist([app.CurrentPath '/outputs/HFResultsSummary.xlsx'], "file")
                
                    % Save the results from heat flow summary table along with
                    % column names to a .mat file and .xlsx file
                    save([app.CurrentPath '/outputs/HFResultsSummaryTable'], 'HFResultsSummary');
                    HeatFlowResults = [app.table_HFSummary.ColumnName'; HFResultsSummary.Data];
                    writematrix(HeatFlowResults, [app.CurrentPath '/outputs/' 'HFResultsSummary.xlsx']);
                
                    % Inform user that the two files were saved in the current
                    % directory
                    uialert(app.figure_Main, ['Heat flow results table saved as MAT file ' ... 
                    ' and as EXCEL file in your current path outputs subfolder (' ...
                    app.CurrentPath '/outputs/)' newline newline ...
                    'HFResultsSummaryTable.mat' newline ...
                    'HFResultsSummary.xlsx'], 'SAVED', 'Icon','success')
    
    
                % If theses files do already exist in the same folder, tell
                % user that these files must be moved or they will be
                % overwritten.
                % -------------------------------------------------------
                else
    
                    % Inform user that these files need to be removed or 
                    % moved in order to save a new one
                    uialert(app.figure_Main, ['Heat flow results table already ' ...
                        'exists in ' app.CurrentPath '/outputs/' newline newline ...
                    ['Must move or remove the following files from this directory and ' ...
                    'save again:'] newline newline ...
                    'HFResultsSummaryTable.mat' newline ...
                    'HFResultsSummary.xlsx'], 'MUST REMOVE EXISTING RESULTS FILE', ...
                    'Icon','error')
                    return
                end
             end

        end

        % Button pushed function: button_clearresults
        function button_clearresultsPushed(app, event)
% ==============================================
%% Called when clear results button is pressed.
% ==============================================   

            % Clear the table in the final tab of the GUI
            % --------------------------------------------
            app.table_HFSummary.Data = [];

            % Disable the save results button.
            % --------------------------------------------
            app.button_saveresults.Enable='off';

        end

        % Selection changed function: buttongroup_PlotControls_Fric
        function buttongroup_PlotControls_FricSelectionChanged2(app, event)
% ===============================================================
%% Called the user moves to the frictional decay tab on the GUI.
% ===============================================================   

            % Define value of checkboxes (on or off) 
            % ---------------------------------------------------------
            app.TempTimePlot     = app.button_TempvsTimeFric.Value;
            app.TempTauPlot      = app.button_TempvsTauFric.Value;
            app.TempBullFuncPlot = app.button_TempvsBullFunFric.Value;
            app.MisfitShiftPlot  = app.button_MisfitvsShiftFric.Value;

            % Update layout for plots on this tab
            % -----------------------------------
            plotLayout(app.grid_FricDecayAxes, app.TempTimePlot, app.axes_TempvTime, app.TempTauPlot,...
                app.axes_TempvTau, app.TempBullFuncPlot, app.axes_TempvBullFunc, ...
                app.MisfitShiftPlot, app.axes_MisfitvTimeShift)

        end

        % Value changed function: checkbox_HFPlot
        function checkbox_HFPlotValueChanged(app, event)
% ======================================================================
%% Called the user wants to turn on or off the heat flow "scatter" plot.
% ======================================================================               
            
            % Define value of checkbox (on or off)
            % ---------------------------------------
            app.HFPlot = app.checkbox_HFPlot.Value;

            % Define which plots are shown on this tab
            % ------------------------------------------
            ap = num2str(app.HFPlot);
            bp = num2str(app.SigmaPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_HeatFlowRegAxes.RowHeight = {apx, bpx};

            if app.HFPlot == 0
                app.HFLine.Visible = 'off';
                app.ScatterLine.Visible = 'off';
            elseif app.HFPlot == 1
                app.HFLine.Visible = 'on';
                app.ScatterLine.Visible = 'on';
            end

            % Update plot layout on this tab
            % --------------------------------
            plotLayout(app.grid_HeatFlowRegAxes, app.HFPlot, app.axes_HeatFlow, ...
                app.SigmaPlot, app.axes_Sigma)
            
        end

        % Value changed function: checkbox_SigmaPlot
        function checkbox_SigmaPlotValueChanged(app, event)
% ================================================================================
%% Called the user wants to turn on or off the normalized heat flow "scatter" plot.
% ================================================================================                
            
            % Define value of checkbox (on or off)
            % -------------------------------------
            app.SigmaPlot = app.checkbox_SigmaPlot.Value;

            % Define which plots are shown on this tab
            % ------------------------------------------
            ap = num2str(app.HFPlot);
            bp = num2str(app.SigmaPlot);
            
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            % ---------------------------------------------
            apx = strcat(ap, 'x');
            bpx = strcat(bp, 'x');
        
            % Update grid layout. 
            % Keep temperature plot at a constant weight 5x all other plots
            % ---------------------------------------------
            app.grid_HeatFlowRegAxes.RowHeight = {apx, bpx};
            
            if app.SigmaPlot == 0
                app.SigmaHFLine.Visible = 'off';
                app.SigmaScatterLine.Visible = 'off';
            elseif app.SigmaPlot == 1
                app.SigmaHFLine.Visible = 'on';
                app.SigmaScatterLine.Visible = 'on';
            end

            % Update plot layout on this tab
            % --------------------------------
            plotLayout(app.grid_HeatFlowRegAxes, app.HFPlot, app.axes_HeatFlow, ...
                app.SigmaPlot, app.axes_Sigma)            
            
        end

        % Button pushed function: button_OpenControlsPanel
        function button_OpenControlsPanelButtonPushed(app, event)
% =============================================================================
%% Called when the button for bringing back the user controls window is pressed.
% =============================================================================           
            
            % Update the main grid layout
            % ---------------------------
            app.grid_Main.ColumnWidth = {'1x', '0x', '8x'};

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

            % Align X axes of the two time shift plots on the HEAT PULSE
            % DECAY tab
            % ----------------------------------------------------------
            xAlignHPD(app.button_ShowAll_HP, app.axes_MisfitvTimeShift, app.axes_TempvTimeShift)

        end

        % Value changed function: switch_PauseBetweenIter
        function switch_PauseBetweenIterValueChanged(app, event)
% =======================================================================
%% Called when switch button for pausing between iterations is switched.
% =======================================================================            
            
            % Switch the pause iterations parameter based on the value of 
            % this switch button
            % ---------------------------------------------------------
            switch app.switch_PauseBetweenIter.Value
                case 'On'
                app.Pause = 1;
                case 'Off'
                app.Pause = 0;
            end
            
        end

        % Selection change function: tabgroup_Main
        function tabgroup_MainSelectionChanged(app, event)
% ======================================================
%% Called when the user moves to another tab in the GUI.
% ======================================================            
            
            % Define which tab is selected
            % -----------------------------
            selectedTab = app.tabgroup_Main.SelectedTab;

            % Update GUI based on which tab is selected
            % -----------------------------------------

            if selectedTab == app.tab_PenetrationData
               app.panel_PlotControls_Pen.Visible = 'on';
               app.panel_PlotControls_Fric.Visible = 'off';     
               app.panel_PlotControls_HP.Visible = 'off';     
               app.panel_PlotControls_HeatFlow.Visible = 'off';     
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_FricitonalDecayReduction
               app.panel_PlotControls_Fric.Visible = 'on';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_HP.Visible = 'off';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_HeatPulseDecayReduction
               app.panel_PlotControls_HP.Visible = 'on';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_HeatFlowAnalysis
               app.panel_PlotControls_HeatFlow.Visible = 'on';
               app.panel_PlotControls_HP.Visible = 'off';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_HeatFlowRegressionAnalysis
               app.panel_PlotControls_Scatter.Visible = 'on';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_HP.Visible = 'off';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
               app.panel_PlotControls_SensAn.Visible = 'off';
            elseif selectedTab == app.tab_SensitivityAnalysis
               app.panel_PlotControls_SensAn.Visible = 'on';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_HP.Visible = 'off';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
            elseif selectedTab == app.tab_HFSummary
               app.panel_PlotControls_SensAn.Visible = 'off';
               app.panel_PlotControls_Scatter.Visible = 'off';
               app.panel_PlotControls_HeatFlow.Visible = 'off';
               app.panel_PlotControls_HP.Visible = 'off';
               app.panel_PlotControls_Pen.Visible = 'off';
               app.panel_PlotControls_Fric.Visible = 'off';
            end


        end

        % Button pushed function: button_reset
        function button_resetPushed(app, event)
% ======================================================
%% Called when the reset penetration button is pressed.
% ======================================================   

%% Clear processing plots

            % Frictional Decay %
            delete(app.axes_TempvTime.Children)
            delete(app.axes_TempvTau.Children)
            delete(app.axes_TempvBullFunc.Children)
            delete(app.axes_TimeShift.Children)

            app.grid_FricDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_FricDecayAxes.ColumnWidth = {'1x', '1x'};

            % Heat Pulse Decay %
            delete(app.axes_CTempvCtime.Children)
            delete(app.axes_CTempv1_CTime.Children)
            delete(app.axes_MisfitvTimeShift.Children)
            delete(app.axes_TempvTimeShift.Children)

            app.grid_HPDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_HPDecayAxes.ColumnWidth = {'1x', '1x'};

            % Heat Flow Analysis %
            cla(app.axes_TempvDepth, 'reset')
            cla(app.axes_TCvDepth, 'reset')
            cla(app.axes_TempvCTR, 'reset')
            app.label_gradient.Text = '';
            app.label_depthoftop.Text = '';
            app.label_averagek.Text = '';
            app.label_heatflow.Text = ''; 
            app.label_shift.Text = '';

            % Heat Flow Regression %
            cla(app.axes_HeatFlow, 'reset')
            cla(app.axes_Sigma, 'reset')

             % Sensitivity Analysis %
            delete(app.axes_SA_TCvDepth.Children)
            delete(app.axes_SA_TempvCTR.Children)
            delete(app.axes_SA_SDvIter.Children)
  
            % Disable post-processing capabilities
            app.button_SensAn.Enable        = 0;
            app.button_IgnoreData.Enable    = 0;
            app.button_reset.Enable         = 0;
            app.button_NextIteration.Enable = 0;
            app.button_EndIterations.Enable = 0;

            % Tell program to re-iterate next time it processes the data
            app.Reprocess=1;
  
            app.SensorsToUse=1:app.NumberOfSensors;

%% Plot raw penetration data

            % Plot raw temperature
            [app.S_Lines, ...
            app.AllSensors,...
            app.h_axTempAboveBWT] = ...
            PlotTemp(app.axes_TempAboveBWT, ...
            app.axes_BottomWaterTemp, ...
            app.axes_Depth, ...
            app.axes_Tilt, ...
            app.checkbox_BottomWaterPlot, ...
            app.AllSensorsTemp, ...
            app.NumberOfSensors, ...
            app.WaterThermistor, ...
            app.WaterSensorTemp, ...
            app.AllRecords, ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ... 
            app.PulseData);

            % Plot depth
            PlotDepth(app.figure_Main, ...
            app.axes_Depth, ...
            app.checkbox_DepthPlot, ...
            app.Tilt, ...
            app.Depth, ...
            app.DepthMean, ...
            app.TAPRecord, ...
            app.Reprocess)

            % Plot tilt
            PlotTilt(app.TAPRecord, ...
            app.figure_Main, ...
            app.TiltMean, ...
            app.Tilt, ...
            app.checkbox_TiltPlot, ...
            app.grid_PenetrationInfo, ...
            app.axes_Tilt, ...
            app.Reprocess)

           % Plot sensor checkboxes 
           [app.PenCheckboxes, ...
            app.FricCheckboxes, ...
            app.HPCheckboxes ...
           ] = PlotCheckboxes( ...
              app.SensorsToUse, ...
              app.NumberOfSensors, ...
              app.panel_PD_Thermistors, ...
              app.panel_FD_Thermistors, ...
              app.panel_HPD_Thermistors, ...
              app.panel_BA_Thermistors, ...
              app.panel_HF_Thermistors, ...
              app.panel_SA_Thermistors, ...
              app.h_axTempAboveBWT, ...
              app.h_axFricTempvTime, ...
              app.h_axFricTempvTau, ...
              app.h_axFricTempvTauPoints, ...
              app.h_axFricTempvTauLines, ...
              app.h_axFricRMSvTimeShift, ...
              app.h_axFricRMSvTimeShiftMinDelays, ...
              app.h_axHPTempvTime, ...
              app.h_axHPRMS, ...
              app.h_axHPRMSLine, ...
              app.h_axHPTempvInvTime, ...
              app.h_axHPTempvInvTimeBestFit, ...
              app.h_axHPTempvTimeShift, ...
              app.h_axHPTempvTimeShiftBestFit);


          % Enable and reset values for plot control checkboxes
          % ----------------------------------------------------
          for i=1:app.NumberOfSensors
            app.PenCheckboxes{i}.Enable = 'on';
            app.FricCheckboxes{i}.Enable = 'on';
            app.HPCheckboxes{i}.Enable = 'on';
    
            app.PenCheckboxes{i}.Value = 1;
            app.FricCheckboxes{i}.Value = 1;
            app.HPCheckboxes{i}.Value = 1;
          end

          % Set selected tab to penetration tab
          % -----------------------------------
          app.tabgroup_Main.SelectedTab=app.tab_PenetrationData;  
          
        
        end

        % Value changed function: checkbox_UseHP
        function checkbox_UseHPValueChanged(app, event)
% ======================================================
%% Called when the user manually defines whether to use 
%% a heat pulse or ignore it with the Use HP checkbox.
% ======================================================           
         
        % Set parameters based on checkbox value
        % --------------------------------------
        app.Reprocess = 1;
        app.UseHP = app.checkbox_UseHP.Value;
            
        if app.UseHP == 0
            app.NoInitialize = 1;
            app.label_HPIgnored.Visible='on';
        else
            app.label_HPIgnored.Visible='off';
        end


        end

        % Button pushed function: button_SensAn
        function button_SensAnButtonPushed(app, event)
% =============================================================
%% Called when the Run Sensitivity Analysis button is pressed.
% =============================================================   

%% Initialize

        % Define sensitviity analysis number
        % -----------------------------------
        app.NumSensAnalyses = app.NumSensAnalyses + 1;


        % Reset all plots for senstivity analysis
        % -----------------------------------------
        delete(app.axes_SA_TCvDepth.Children)
        delete(app.axes_SA_TempvCTR.Children)
        delete(app.axes_SA_SDvIter.Children)
        delete(app.axes_SA_TCvDepth.Legend)
        delete(app.axes_SA_TempvCTR.Legend)

        app.label_standev.Text = ' ';
        app.label_currentiteration.Text = ' ';
        app.label_currentheatflow.Text = ' ';
        app.label_initheatflow.Text = ' ';
        app.label_heatflowrange.Text = ' ';
        app.label_meanheatflow.Text = ' ';
        app.label_kDistribution.Text = ' ';
        app.label_thermconbounds.Text = ' ';
        app.label_meandev.Text = ' ';
        app.label_minthickness.Text = ' ';

        % Update labels
        app.label_StanDevTitle.Interpreter = 'latex';
        app.label_StanDevTitle_perc.Interpreter = 'latex';
        app.label_StanDevTitle.Text = '\bf$ \sigma_{q-MC}';
        app.label_StanDevTitle_perc.Text = '$ \sigma_{q-MC}';
            

        % Define what sensors to use
        % --------------------------
   
        GoodT = setxor(app.SensorsUsedForBullardFit,app.BadT);
        Goodk = setxor(app.SensorsUsedForBullardFit,app.Badk);
        
        app.TToUse = intersect(GoodT,app.SensorsUsedForBullardFit);    % SensorsToUse for T.
        app.kToUse = intersect(Goodk,app.SensorsUsedForBullardFit);    % SensorsToUse for k.

        % Set up input parameters for analysis
        % -------------------------------------
        app.tabgroup_Main.SelectedTab = app.tab_HeatFlowAnalysis;

        kAll = ones(1,app.NumberOfSensors);
        kAll(app.kToUse)=app.Currentk(app.kToUse);

%% AUXILIARY APPLICATION called for setting sensitivity analysis parameter distributions        

        app.SetUpSensDialogApp = SetUpSensAnalysis(app, ...
            app.NumberOfSensors,...
            app.SensorsToUse, ...
            app.kToUse, ...
            kAll, ...
            app.PulseData, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.kAnisotropy, ...
            app.Bins, ...
            app.BadT);
            
        % Update labels if the user chose a different distribution of
        % therm con values
        %       NORMAL : app.kDistribution == 1
        %       GAMMA :  app.kDistribution == 2
        %       NONE:    app.kDistribution == 3
        
        % Wait to execute rest of code until auxillary app is deleted
        waitfor(app.SetUpSensDialogApp)
        
        % Try to continue. If there is an error (due to closing the aux
        % app before running sensitivity analysis) then the function
        % stops running.
        try 
            if app.kDistribution == 1
                app.label_kDistribution.Text = 'Gaussian';
            elseif app.kDistribution == 2
                app.label_kDistribution.Text = 'Gamma';
            elseif app.kDistribution == 3 && app.kAnisotropy == 1
                app.label_kDistribution.Text = 'None';
            elseif app.kDistribution == 3 && app.kAnisotropy ~= 1
                app.label_kDistribution.Text = 'Anisotrop';
            end
        catch
            return
        end 
        if app.RunAnalysis ~= 1
            return
        end
    
        % Update loading dialog box and tab selected
        % -------------------------------------------
        loading = uiprogressdlg(app.figure_Main, "Cancelable","on", ...
               "Message",'Running sensitivity analysis...', 'Value', 0.2);
    
        loading.Message = 'Beginning sensitivity analysis...'; 
        app.tabgroup_Main.SelectedTab = app.tab_SensitivityAnalysis;

 
%% Run sensitivity analysis
           
        [app.Params,...
         app.Results]...
         = SensitivityAnalysis(app.NumberOfSensors,...
                app.MaxSAIterations, ...
                app.Sigmak0, ...
                app.kMin, ...
                app.kMax, ...
                app.MinThickness, ...
                app.kAnisotropy, ...
                app.CTRToUse, ...
                app.IgnoredSensors, ...
                app.Badk, ...
                app.BadT,...
                app.ShiftedCTR, ...
                app.ShiftedRelativeDepths, ...
                app.Slope, ...
                app.Currentk, ...
                app.MinimumFricEqTemp, ...
                app.h_axTempAboveBWT, ...
                app.SensorsUsedForBullardFit, ...
                app.HeatFlow, ...
                app.axes_SA_SDvIter, ...
                app.axes_SA_TCvDepth, ...
                app.axes_SA_TempvCTR, ...
                app.label_heatflowrange, ...
                app.label_meanheatflow, ...
                app.label_currentheatflow, ...
                app.label_initheatflow, ...
                app.label_standev, ...
                app.label_currentiteration, ...
                app.label_meandev, ...
                app.label_minthickness, ...
                app.label_thermconbounds, ...
                app.label_ThermConBoundsTitle, ...
                app.label_standev_perc, ...
                loading, ...
                app.table_ErrorUncertaintySummary, ...
                app.ErrData, ...
                app.kDistribution, ...
                app.r, ...
                app.Meank,...
                app.UseFrictional, ...
                app.figure_Main, ...
                app.FricTime, ...
                app.FricTemp, ...
                app.FrictionalDelays, ...
                app.FricMaxStep, ...
                app.TimeInc, ...
                app.HyndmanCoeffs, ...
                app.SensorRadius, ...
                app.FricTauMin, ...
                app.FricTauMax, ...
                app, ...
                app.NumSensAnalyses);    

%% Finalize

        % Close sensitivity analysis
        % --------------------------------
        app.button_saveErrRestults.Enable = 'on';
        app.button_clearErrResults.Enable = 'on';
    
    
        % Structure for sensitivity anslyses results
        % -----------------------------------------
        if isempty(app.S_SensResults)
             app.S_SensResults.Trial_1 = struct('Results', app.Results, 'Params', app.Params);
             SensResults = app.S_SensResults.Trial_1;
        else
             app.S_SensResults.(['Trial_' num2str(app.NumSensAnalyses)]) = struct('Results', app.Results, 'Params', app.Params);
             SensResults = app.S_SensResults.(['Trial_' num2str(app.NumSensAnalyses)]);
        end
    
    
         % Plot limits for histogram of heat flow results
         % -----------------------------------------------
         if isempty(app.S_SensResults)
             minimum = round(app.Results.Minq)-1;
             maximum = round(app.Results.Maxq)+1;
         else
        
             SensCell = struct2cell(app.S_SensResults);
             minimums = NaN(length(SensCell),1);
             maximums =  NaN(length(SensCell),1);
        
             for i = 1:length(SensCell)
                 if ~isempty(fieldnames(SensCell{i}.Results))
                     minimums(i) = SensCell{i}.Results.Minq;
                     maximums(i) = SensCell{i}.Results.Maxq;
                 else
                     minimums(i) = NaN;
                     maximums(i) = NaN;
                 end
             end
        
             if length(minimums)>1
                 minimum = round(min(minimums));
                 maximum = round(max(maximums));
             else
                 minimum = round(minimums);
                 maximum = round(maximums);
             end
        end
        
        app.axes_senshist.XTick = minimum: 3 : maximum;

        % Print sensitivity analysis results to results (.res) file, 
        % if the analysis was completed
        % ---------------------------------------------------------
        if isfield(app.Results,'AveUnc')
        PrintSensResults(app.Params, ...
                         app.Results,...
                         app.SensorsToUse, ...
                         app.NumberOfColumns, ...
                         app.ResFileId, ...
                         app.NumSensAnalyses)
        end
 
        % Save results to to .mat file
        % ------------------------------
        if isdeployed
            save([app.AppOutputs app.PenFileName(1:end-4) '-out/' ...
            app.PenFileName(1:end-4) '_T' num2str(app.NumSensAnalyses) ...
            '_SensitivityAnalysisResults.mat'], 'SensResults');
        else
            save([app.CurrentPath '/outputs/' app.PenFileName(1:end-4) '-out/' ...
                app.PenFileName(1:end-4) '_T' num2str(app.NumSensAnalyses) ...
                '_SensitivityAnalysisResults.mat'], 'SensResults');
        end
 
        % Enable error and uncertainty results table save button
        % -------------------------------------------------------
        app.button_saveErrRestults.Enable='on';
 
        % Run save error results button
        % -----------------------------
        button_saveErrRestultsButtonPushed(app);
 
        % Save figures
        % -------------
        if isdeployed
            savespot =  [app.AppOutputs app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4)];
        else
            savespot =  [app.CurrentPath '/outputs/' app.PenFileName(1:end-4) '-out/' app.PenFileName(1:end-4)];
        end

        kdistr = '_kdistr';
        sens = '_sens';
        unc = '_unc';
        
 
        exportgraphics(app.axes_SA_TCvDepth, [savespot kdistr '.eps'], ...
            'ContentType','vector');
        exportgraphics(app.axes_SA_TempvCTR, [savespot sens '.eps'], ...
            'ContentType','vector');
        exportgraphics(app.axes_SA_SDvIter, [savespot unc '.eps'], ...
            'ContentType','vector');
 

        end

        % Button pushed function: button_saveErrRestults
        function button_saveErrRestultsButtonPushed(app, event)
% ====================================================================
%% Called when the save error and ucnertaitny table button is pressed.
% ====================================================================  
        
            ErrResultsSummary = app.table_ErrorUncertaintySummary;
            
            if isdeployed 
                if ~exist([app.AppOutputs 'ErrResultsSummary.xlsx'], "file")
                
                    % Save the results from heat flow summary table along with
                    % column names to a .mat file and .xlsx file
                    save([app.AppOutputs 'ErrResultsSummaryTable'], 'ErrResultsSummary');
                    ErrorUncertaintyResults = [ErrResultsSummary.ColumnName'; ErrResultsSummary.Data];
                    writematrix(ErrorUncertaintyResults, [app.AppOutputs 'ErrResultsSummary.xlsx']);
                
                
                    % Inform user that the two files were saved in the current
                    % directory
                    uialert(app.figure_Main, ['Error and uncertainty analysis results table saved as MAT file ' ... 
                    ' and as EXCEL file in your current path outputs subfolder (' app.AppOutputs newline newline ...
                    'ErrResultsSummaryTable.mat' newline ...
                    'ErrResultsSummary.xlsx'], 'SAVED', 'Icon','success')
                else
        
                    % Inform user that there already exists a table in this 
                    % directory that needs to be removed or moved in order to 
                    % save a new one
                    uialert(app.figure_Main, ['Error and uncertainty analysis ' ...
                        'results table already exists in ' app.AppOutputs newline newline ...
                        ['Must move or remove the following files from this directory ' ...
                        'and save again:'] newline newline ...
                        'ErrResultsSummaryTable.mat' newline ...
                       'ErrResultsSummary.xlsx'], 'MUST REMOVE EXISTING RESULTS FILE', 'Icon','error')
                    return
        
                end
            else
                if ~exist([app.CurrentPath '/outputs/ErrResultsSummary.xlsx'], "file")
                
                    % Save the results from heat flow summary table along with
                    % column names to a .mat file and .xlsx file
                    save([app.CurrentPath '/outputs/ErrResultsSummaryTable'], 'ErrResultsSummary');
                    ErrorUncertaintyResults = [ErrResultsSummary.ColumnName'; ErrResultsSummary.Data];
                    writematrix(ErrorUncertaintyResults, [app.CurrentPath '/outputs/' 'ErrResultsSummary.xlsx']);
                
                
                    % Inform user that the two files were saved in the current
                    % directory
                    uialert(app.figure_Main, ['Error and uncertainty analysis results table saved as MAT file ' ... 
                    ' and as EXCEL file in your current path outputs subfolder (' app.CurrentPath '/outputs/)' newline newline ...
                    'ErrResultsSummaryTable.mat' newline ...
                    'ErrResultsSummary.xlsx'], 'SAVED', 'Icon','success')
                else
        
                    % Inform user that there already exists a table in this 
                    % directory that needs to be removed or moved in order to 
                    % save a new one
                    uialert(app.figure_Main, ['Error and uncertainty analysis ' ...
                        'results table already exists in ' app.CurrentPath ...
                        '/outputs/' newline newline ...
                        ['Must move or remove the following files from this directory ' ...
                        'and save again:'] newline newline ...
                        'ErrResultsSummaryTable.mat' newline ...
                       'ErrResultsSummary.xlsx'], 'MUST REMOVE EXISTING RESULTS FILE', 'Icon','error')
                    return
        
                end
            end


        end

        % Button pushed function: button_clearErrResults
        function button_clearErrResultsButtonPushed(app, event)
% ====================================================================
%% Called when the clear error and ucnertaitny table button is pressed.
% ====================================================================      
            
            % Clear error and uncertainty results data table
            app.table_ErrorUncertaintySummary.Data = [];
            
            % Disable the save error and uncertainty results button
            app.button_saveErrRestults.Enable='off';

        end

        % Button pushed function: button_NewParFile
        function button_NewParFilePushed(app, event)
% ===============================================================
%% Called when the new parameters (.par) file button is pressed.
% ===============================================================

%% Get new .par file

            % Ask user to get new .par file
            % -------------------------------
            if isdeployed
                [app.NewParFileName, app.ParFilePath] = uigetfile( ...
		            [app.AppOutputs '*.par'], ...
		            'Select new parameters file');
                    drawnow;
                    figure(app.figure_Main)
            else
                [app.NewParFileName, app.ParFilePath] = uigetfile( ...
		            [app.CurrentPath '/outputs/' '*.par'], ...
		            'Select new parameters file');
                    drawnow;
                    figure(app.figure_Main)
            end

            app.NewParFile = [app.ParFilePath app.NewParFileName];

            % If the user cancelled, return to main function
            % ----------------------------------------------
            if ~app.NewParFileName
                return
            end 

%% Read the new .par file in

            [app.S_ParFile, ...
            app.NumberOfSensors, ...	
            app.WaterThermistor, ...
            app.TimeScalingFactor, ...
            app.DeltaTime, ...
            app.SensorRadius, ...
            app.SensorDistance, ...
            app.TempError, ...
            app.CalibrationCoeffs, ...
            app.HyndmanCoeffs, ...
            app.FrictionalDelays, ...
            app.FricMaxStep, ...
            app.TimeInc, ...
            app.FricTauMin, ...
            app.FricTauMax, ...
            app.PulseDelays, ...
            app.kInit, ...
            app.ktype, ...
            app.PulsePowerPARFile, ...
            app.TimeShiftInit, ...
            app.TimeShiftInc, ...
            app.PulseMaxStep, ...
            app.kTolerance, ...
            app.PulseTauMin, ...
            app.PulseTauMax, ...
            app.HeatPulseLength, ...
            app.MinTotalkChange, ...
            app.MaxNumberOfIterations, ...
            app.MaxSAIterations, ...
            app.Sigmak0, ...
            app.kMin, ...
            app.kMax, ...
            app.MinThickness, ...
            app.kAnisotropy, ...
            app.TopSensorDepth, ...
            app.ProbeLength,...
            app.Offset] = ReadParFile(app.NewParFile, app.ProgramLogId, app.NewParFileName, app.CurrentPath);   
            app.label_parfilename.Text = app.NewParFileName;    
            if ~contains(app.label_ParFile.Text, 'SlugHeat22')
                app.lamp_ParFile.Visible = 'on';
            end     

            % Tell program to re-iterate for equil. temp and therm.
            % con. values with new parameters
            % ----------------------------------------------------
            app.Reprocess = 1;  

            % Confirm with user which pulse power to use
            % ------------------------------------------
            PowerConfirm = uiconfirm(app.figure_Main, ['Pulse power ' ...
                'will be changed from what is read in from the ' ...
                'PEN file or MAT file. Must confirm which heat pulse ' ...
                'power to use before continuing'], 'Confirm pulse power to use', ...
                'Options',{['Use power read in from PEN and MAT file: ', num2str(app.PulsePower)], ...
                ['Use power defined in new PAR file: ', num2str(app.PulsePowerPARFile)]});
            switch PowerConfirm
                case ['Use power read in from PEN and MAT file: ', num2str(app.PulsePower)]
                    app.PulsePower = app.PulsePower;
                case ['Use power defined in new PAR file: ', num2str(app.PulsePowerPARFile)]
                    app.PulsePower = app.PulsePowerPARFile;
            end 

        end

        % Value changed function: button_changeBW
        function button_changeBWValueChanged(app, event)
% ===================================================================
%% Called when the cahnge bottom water temperature button is pressed.
% ===================================================================

%% RESET PENETRATION (need to re-do all iterations with new BW)
           
            % Re-set trial
            % -------------
            app.Trial = 0;
            app.label_trial.Text = '';
            app.label_Iteration.Text = '';
                
            % CLear processing plots
            % -------------------------

            % Frictional Decay %
            delete(app.axes_TempvTime.Children)
            delete(app.axes_TempvTau.Children)
            delete(app.axes_TempvBullFunc.Children)
            delete(app.axes_TimeShift.Children)

            app.grid_FricDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_FricDecayAxes.ColumnWidth = {'1x', '1x'};

            % Heat Pulse Decay %
            delete(app.axes_CTempvCtime.Children)
            delete(app.axes_CTempv1_CTime.Children)
            delete(app.axes_MisfitvTimeShift.Children)
            delete(app.axes_TempvTimeShift.Children)

            app.grid_HPDecayAxes.RowHeight = {'1x', '1x'};
            app.grid_HPDecayAxes.ColumnWidth = {'1x', '1x'};

                % Heat Flow Analysis axes
                % ----------------------
                cla(app.axes_TempvDepth, 'reset')
                cla(app.axes_TCvDepth, 'reset')
                cla(app.axes_TempvCTR, 'reset')
                app.label_gradient.Text = '';
                app.label_depthoftop.Text = '';
                app.label_averagek.Text = '';
                app.label_heatflow.Text = ''; 
                app.label_shift.Text = '';

                % Heat Flow Regression
                % ----------------------
                cla(app.axes_HeatFlow, 'reset')
                cla(app.axes_Sigma, 'reset')

          % Reset all plots for senstivity analysis
          % -----------------------------------------
          delete(app.axes_SA_TCvDepth.Children)
          delete(app.axes_SA_TempvCTR.Children)
          delete(app.axes_SA_SDvIter.Children)
          
%% AUXILIOARY APPLCIATION: Call bottom water app to determine new bottom water temperature

          app.BottomWaterApp = BottomWaterAuxApp(app, ...
              app.AllSensorsCalibratedTemp, ...
              app.WaterSensorTemp,...
              app.WaterThermistor, ...
              app.NumberOfSensors, ...
              app.SensorsToUse, ...
              app.AllRecords, ...
              app.PenetrationRecord, ...
              app.AllSensorsTemp, ...
              app.edit_bottomwater.Value);
            
           waitfor(app.BottomWaterApp);

           app.BWChosen=1;

%% Correct new temepratures
           % Convert penetration file raw data to corrected temperature
           % -------------------------------------------------------------
           [...
            app.BottomWaterTemp, ...
            app.WaterSensorTemp, ...
            app.AllSensorsTemp, ...
            app.AllSensorsCalibratedTemp,...
            app.WaterThermistor] = TempCorrection( ...
             app.BWChosen, ...
             app.BottomWaterValue,...
             app.AllSensorsRawData, ...
             app.WaterThermistor, ...
             app.WaterSensorRawData, ...
             app.Offset, ...
             app.PenetrationRecord, ...
             app.AllRecords, ...
             app.MeanCalibTemps, ...
             app.NumberOfSensors, ...
             app.SensorsToUse, ...
             app.figure_Main, ...
             app);


%% Re-plot all new temperature data

            % Split penetration and heat pulse data
            % -----------------------------------------
            [app.FricTime, ...
            app.FricTemp, ...
            app.PulseData, ...
            app.PulseTime, ...
            app.PulseTemp ...
            ] = SplitDecays( ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...   
            app.AllRecords, ...
            app.TimeScalingFactor, ...
            app.AllSensorsTemp, app.LogFileId,app.PenStartChanged, ...
            app.HeatPulseChanged, app.PenEndChanged, app.ProgramLogId, ...
            app.NumberOfColumns);

            % For checkboxes, all plots and checkboxes must have a 
            % value to start
            % ------------------------------------------------------
            app.h_axFricTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTau = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauPoints = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauLines = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShiftMinDelays = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPRMS = zeros(1, app.NumberOfSensors);
            app.h_axHPRMSLine = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTimeBestFit = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShiftBestFit = zeros(1, app.NumberOfSensors);
            app.PenCheckboxes = zeros(1, app.NumberOfSensors);
            app.FricCheckboxes = zeros(1, app.NumberOfSensors);
            app.HPCheckboxes = zeros(1, app.NumberOfSensors);

            % Plot raw penetration data
            % ---------------------------

            % Plot raw temperature
           [app.S_Lines, ...
            app.AllSensors,...
            app.h_axTempAboveBWT] = ...
            PlotTemp(app.axes_TempAboveBWT, ...
            app.axes_BottomWaterTemp, ...
            app.axes_Depth, ...
            app.axes_Tilt, ...
            app.checkbox_BottomWaterPlot, ...
            app.AllSensorsTemp, ...
            app.NumberOfSensors, ...
            app.WaterThermistor, ...
            app.WaterSensorTemp, ...
            app.AllRecords, ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ... 
            app.PulseData);

            % Plot depth
            PlotDepth(app.figure_Main, ...
            app.axes_Depth, ...
            app.checkbox_DepthPlot, ...
            app.Tilt, ...
            app.Depth, ...
            app.DepthMean, ...
            app.TAPRecord, ...
            app.Reprocess)

            % Plot tilt
            PlotTilt(app.TAPRecord, ...
            app.figure_Main, ...
            app.TiltMean, ...
            app.Tilt, ...
            app.checkbox_TiltPlot, ...
            app.grid_PenetrationInfo, ...
            app.axes_Tilt, ...
            app.Reprocess)

            % Plot sensor checkboxes 
            % ---------------------------
           [app.PenCheckboxes, ...
            app.FricCheckboxes, ...
            app.HPCheckboxes ...
            ] = PlotCheckboxes( ...
              app.SensorsToUse, ...
              app.NumberOfSensors, ...
              app.panel_PD_Thermistors, ...
              app.panel_FD_Thermistors, ...
              app.panel_HPD_Thermistors, ...
              app.panel_BA_Thermistors, ...
              app.panel_HF_Thermistors, ...
              app.panel_SA_Thermistors, ...
              app.h_axTempAboveBWT, ...
              app.h_axFricTempvTime, ...
              app.h_axFricTempvTau, ...
              app.h_axFricTempvTauPoints, ...
              app.h_axFricTempvTauLines, ...
              app.h_axFricRMSvTimeShift, ...
              app.h_axFricRMSvTimeShiftMinDelays, ...
              app.h_axHPTempvTime, ...
              app.h_axHPRMS, ...
              app.h_axHPRMSLine, ...
              app.h_axHPTempvInvTime, ...
              app.h_axHPTempvInvTimeBestFit, ...
              app.h_axHPTempvTimeShift, ...
              app.h_axHPTempvTimeShiftBestFit);

            % Update plot layout so that Bottom Water plot is 
            % hidden if no bottom water sensor
            % -------------------------------------------------
            app.BWPlot = app.checkbox_BottomWaterPlot.Value;
            app.DepthPlot = app.checkbox_DepthPlot.Value;
            app.TiltPlot = app.checkbox_TiltPlot.Value;
            
            % Update plot layout
           % --------------------
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, ...
                app.BWPlot, app.axes_BottomWaterTemp, app.TiltPlot, ...
                app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

            for i=1:app.NumberOfSensors
                if ~app.PenCheckboxes{i}.Value 
                    app.h_axTempAboveBWT(i).Visible = 'off';
                end
            end
            
            % Update labels
            % -------------
            updateLabels(app.label_currentpathfull, app.CurrentPath, app.label_penfilename, ...
            app.PenFileName, app.label_tapfilename, app.TAPFileName, app.label_resfilename, ...
            app.ResFileName, app.label_Cruise, app.CruiseName, ...
            app.label_Station, app.StationName, app.label_Penetration, app.Penetration, ...
            app.label_Latitude, app.Latitude, app.label_Longitude, app.Longitude, app.edit_PenStart, ...
            app.PenetrationRecord, app.edit_PenEnd, app.EndRecord, app.edit_HP, app.HeatPulseRecord)
            
            app.checkbox_BottomWaterPlot.Value=1;

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, ...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, ...
                app.TempPlot, app.axes_TempAboveBWT)

            drawnow;

            % Go to penetration tab
            % ---------------------
            app.tabgroup_Main.SelectedTab = app.tab_PenetrationData;

            figure(app.figure_Main)

            % If not heat pulse, remove HP data from GUI
            % --------------------------------------------
            if app.EndRecord > app.HeatPulseRecord
                app.PulseData = 1;
            else
                app.PulseData = 0;
                app.S_Lines.S_HPLines.HPLine.Visible = 'off';
                app.grid_DataControls.RowHeight = {'1x', '0x', '1x'};
            end

            % Reset parameters for sensitivity analysis
            % -----------------------------------------
            app.Bins = 25;

        end

        % Value changed function: edit_bottomwater
        function edit_bottomwaterValueChanged(app, event)
% ===============================================================
%% Called when the bottom water temperature value is changed.
% ===============================================================
            
%% Set new bottom water value   
            app.BottomWaterValue = app.edit_bottomwater.Value;

%% Convert penetration file raw data to corrected temperature
           
            [...
            app.BottomWaterTemp, ...
            app.WaterSensorTemp, ...
            app.AllSensorsTemp, ...
            app.AllSensorsCalibratedTemp,...
            app.WaterThermistor] = TempCorrection( ...
             app.BWChosen, ...
             app.BottomWaterValue,...
             app.AllSensorsRawData, ...
             app.WaterThermistor, ...
             app.WaterSensorRawData, ...
             app.Offset, ...
             app.PenetrationRecord, ...
             app.AllRecords, ...
             app.MeanCalibTemps, ...
             app.NumberOfSensors, ...
             app.SensorsToUse, ...
             app.figure_Main, ...
             app);

%% Re-plot all new temperature relative to bottom water data

            % Split penetration and heat pulse data
            % -----------------------------------------
            [app.FricTime, ...
            app.FricTemp, ...
            app.PulseData, ...
            app.PulseTime, ...
            app.PulseTemp ...
            ] = SplitDecays( ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ...   
            app.AllRecords, ...
            app.TimeScalingFactor, ...
            app.AllSensorsTemp, app.LogFileId,app.PenStartChanged, ...
            app.HeatPulseChanged, app.PenEndChanged, app.ProgramLogId, ...
            app.NumberOfColumns);

            % For checkboxes, all plots and checkboxes must have a value to
            % start
            % ------------------------------------------------------------
            app.h_axFricTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTau = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauPoints = zeros(1, app.NumberOfSensors);
            app.h_axFricTempvTauLines = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axFricRMSvTimeShiftMinDelays = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPRMS = zeros(1, app.NumberOfSensors);
            app.h_axHPRMSLine = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTime = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvInvTimeBestFit = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShift = zeros(1, app.NumberOfSensors);
            app.h_axHPTempvTimeShiftBestFit = zeros(1, app.NumberOfSensors);
            app.PenCheckboxes = zeros(1, app.NumberOfSensors);
            app.FricCheckboxes = zeros(1, app.NumberOfSensors);
            app.HPCheckboxes = zeros(1, app.NumberOfSensors);


            % Plot raw penetration data
            % ---------------------------

            % Plot raw temperature
            [app.S_Lines, ...
            app.AllSensors,...
            app.h_axTempAboveBWT] = ...
            PlotTemp(app.axes_TempAboveBWT, ...
            app.axes_BottomWaterTemp, ...
            app.axes_Depth, ...
            app.axes_Tilt, ...
            app.checkbox_BottomWaterPlot, ...
            app.AllSensorsTemp, ...
            app.NumberOfSensors, ...
            app.WaterThermistor, ...
            app.WaterSensorTemp, ...
            app.AllRecords, ...
            app.PenetrationRecord, ...
            app.HeatPulseRecord, ...
            app.EndRecord, ... 
            app.PulseData);

            % Plot depth
            PlotDepth(app.figure_Main, ...
            app.axes_Depth, ...
            app.checkbox_DepthPlot, ...
            app.Tilt, ...
            app.Depth, ...
            app.DepthMean, ...
            app.TAPRecord, ...
            app.Reprocess)

            % Plot tilt
            PlotTilt(app.TAPRecord, ...
            app.figure_Main, ...
            app.TiltMean, ...
            app.Tilt, ...
            app.checkbox_TiltPlot, ...
            app.grid_PenetrationInfo, ...
            app.axes_Tilt, ...
            app.Reprocess)

            % Plot sensor checkboxes 
            % ---------------------------
            [app.PenCheckboxes, ...
            app.FricCheckboxes, ...
            app.HPCheckboxes ...
            ] = PlotCheckboxes( ...
              app.SensorsToUse, ...
              app.NumberOfSensors, ...
              app.panel_PD_Thermistors, ...
              app.panel_FD_Thermistors, ...
              app.panel_HPD_Thermistors, ...
              app.panel_BA_Thermistors, ...
              app.panel_HF_Thermistors, ...
              app.panel_SA_Thermistors, ...
              app.h_axTempAboveBWT, ...
              app.h_axFricTempvTime, ...
              app.h_axFricTempvTau, ...
              app.h_axFricTempvTauPoints, ...
              app.h_axFricTempvTauLines, ...
              app.h_axFricRMSvTimeShift, ...
              app.h_axFricRMSvTimeShiftMinDelays, ...
              app.h_axHPTempvTime, ...
              app.h_axHPRMS, ...
              app.h_axHPRMSLine, ...
              app.h_axHPTempvInvTime, ...
              app.h_axHPTempvInvTimeBestFit, ...
              app.h_axHPTempvTimeShift, ...
              app.h_axHPTempvTimeShiftBestFit);


            % Update plot layout so that Bottom Water plot is hidden if no
            % bottom water sensor
            % --------------------------------------------------------------------
            app.BWPlot = app.checkbox_BottomWaterPlot.Value;
            app.DepthPlot = app.checkbox_DepthPlot.Value;
            app.TiltPlot = app.checkbox_TiltPlot.Value;
            
            % Update plot layout
            % ------------------
            plotLayout(app.grid_PenDataAxes, app.DepthPlot, app.axes_Depth, app.BWPlot,...
                app.axes_BottomWaterTemp, app.TiltPlot, app.axes_Tilt, app.TempPlot, ...
                app.axes_TempAboveBWT)

           for i=1:app.NumberOfSensors
               if ~app.PenCheckboxes{i}.Value 
                   app.h_axTempAboveBWT(i).Visible = 'off';
               end
           end
            
            % Update labels
            % -------------------------------------------
            updateLabels(app.label_currentpathfull, ...
                app.CurrentPath, app.label_penfilename, ...
            app.PenFileName, app.label_tapfilename, ...
            app.TAPFileName, app.label_resfilename, ...
            app.ResFileName, app.label_Cruise, app.CruiseName, ...
            app.label_Station, app.StationName, ...
            app.label_Penetration, app.Penetration, ...
            app.label_Latitude, app.Latitude, app.label_Longitude, ...
            app.Longitude, app.edit_PenStart, ...
            app.PenetrationRecord, app.edit_PenEnd, app.EndRecord, ...
            app.edit_HP, app.HeatPulseRecord)
            
            app.checkbox_BottomWaterPlot.Value=0;
            app.checkbox_BottomWaterPlot.Value=1;

            % Align X axes of all visibile plots
            % ------------------------------------
            xAlign(app.DepthPlot, app.axes_Depth, app.BWPlot, app.axes_BottomWaterTemp, ...
                app.TiltPlot, app.axes_Tilt, app.TempPlot, app.axes_TempAboveBWT)

            drawnow;

            app.tabgroup_Main.SelectedTab = app.tab_PenetrationData;

            figure(app.figure_Main)

            % If not heat pulse, remove HP data from GUI
            % --------------------------------------------
            if app.EndRecord > app.HeatPulseRecord
                app.PulseData = 1;
            else
                app.PulseData = 0;
                app.S_Lines.S_HPLines.HPLine.Visible = 'off';
                app.grid_DataControls.RowHeight = {'1x', '0x', '1x'};
            end

            % Reset parameters for sensitivity analysis
            % -----------------------------------------
            app.Bins = 25;


        end

        % Button pushed function: button_DiscardSensors
        function button_DiscardSensorsPushed(app, event)
% ===============================================================
%% Called when the discard sensors button below the sensor legend 
%% on the penetration tab is pressed.
% ===============================================================
            
%% Define which sensors to discard

            % Find bad sensors
            % ----------------------
            for i=app.NumberOfSensors:-1:1
                if app.PenCheckboxes{i}.Value == 0 
                    app.IgnoredSensors(i) = i;
                    app.PenCheckboxes{i}.Enable= 'off';
                    app.FricCheckboxes{i}.Enable = 'off';
                    app.FricCheckboxes{i}.Value = 0;
                    app.HPCheckboxes{i}.Enable = 'off';
                    app.HPCheckboxes{i}.Value = 0;
                end
            end

            % Define bad sensors
            % ----------------------
            app.IgnoredSensors(app.IgnoredSensors==0) = [];
            app.IgnoredSensors = unique(app.IgnoredSensors);
           
            
            % Warn user before removing data
            % ------------------------------------
            confirmation = uiconfirm(app.figure_Main, ['Recordings from all unchecked ' ...
                'sensors will be discarded and REMOVED from ' ...
                'data.' newline newline ...
                'Sensors discarded ' newline int2str(app.IgnoredSensors)], ...
                'Confirm sensor discard', 'Icon', 'warning');
            switch confirmation
            case 'OK'
            
                % Update LOG file to indicate which 
                % sensors are used and which are ignored
                % ----------------------------------------
                if isempty(app.IgnoredSensors) 
                    PrintStatus(app.LogFileId,'All the sensors are used in processing!   ',2);
                else
                    PrintStatus(app.LogFileId,['These sensors are IGNORED in processing: ' ...
                            int2str(app.IgnoredSensors)],2);
                end
                
	            PrintStatus(app.ProgramLogId, ['-- Removing bad data from sensors to be ignored ' ...
                    'in processing'],2);
            case 'Cancel'
                return
            
            end
            
            app.Reprocess = 1;      % when this is 1, it tells the program that 
                                    % the full calculations of eq. temp and 
                                    % thermal conductivity were NOT made 
                                    % for these sensors.
            
            app.SensorsToUse = setdiff(app.SensorsToUse, app.IgnoredSensors);


%% Update temperatures using only the sensors not discarded
     
           [...
            app.BottomWaterTemp, ...
            app.WaterSensorTemp, ...
            app.AllSensorsTemp, ...
            app.AllSensorsCalibratedTemp,...
            app.WaterThermistor] = TempCorrection( ...
             app.BWChosen, ...
             app.BottomWaterValue,...
             app.AllSensorsRawData, ...
             app.WaterThermistor, ...
             app.WaterSensorRawData, ...
             app.Offset, ...
             app.PenetrationRecord, ...
             app.AllRecords, ...
             app.MeanCalibTemps, ...
             app.NumberOfSensors, ...
             app.SensorsToUse, ...
             app.figure_Main, ...
             app);
            
        end

        % Selection changed function: buttongroup_PlotControls_HP
        function buttongroup_PlotControls_HPSelectionChanged(app, event)
% ===============================================================
%% Called when the selected plots to show or hide  heat pulse 
%% decay reduction tab is changed.
% ===============================================================            
            
% Define which plots to show and hide
            app.TempTimePlotHPPlot     = app.button_TempvsTime_HP.Value;
            app.MisfitShiftHPPlot      = app.button_MisfitvShift_HP.Value;
            app.TempInvTimeHPPlot      = app.button_TempvsTimeInv_HP.Value;
            app.TempTimeShiftHPPlot    = app.button_TvShift_HP.Value;
    
% Update plot layouts based on which plots to show and hide
            plotLayout(app.grid_HPDecayAxes, app.TempTimePlotHPPlot, ...
                app.axes_CTempvCtime, app.MisfitShiftHPPlot,...
                app.axes_MisfitvTimeShift, app.TempInvTimeHPPlot, ...
                app.axes_CTempv1_CTime, ...
                app.TempTimeShiftHPPlot, app.axes_TempvTimeShift)
            
        end

        % Value changed function: checkbox_TiltPlot_2
        function checkbox_TiltPlot_2ValueChanged(app, event)
% ===============================================================
%% Called when tilt plot checkbox value is changed.
% ===============================================================            
% Get value   
            value = app.checkbox_TiltPlot_2.Value;
% If checked, turn on tilt plots        
            if value==1
                app.S_BullPlots.h_axBullTempvShiftedDepthPolynomial.Visible = 1;
                app.S_BullPlots.h_axCTRvShiftedDepthPolynomial.Visible = 1;
% If unchecked, turn off tilt plots        
            else
                app.S_BullPlots.h_axBullTempvShiftedDepthPolynomial.Visible = 0;
                app.S_BullPlots.h_axCTRvShiftedDepthPolynomial.Visible = 0;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure_Main = uifigure('Visible', 'off');
            app.figure_Main.Name = 'MATLAB App';
            app.figure_Main.SizeChangedFcn = createCallbackFcn(app, @figure_MainSizeChanged, true);
            app.grid_Main = uigridlayout(app.figure_Main);
            app.panel_ControlWindow = uipanel(app.grid_Main);
            app.grid_ControlWindow = uigridlayout(app.panel_ControlWindow);
            app.panel_header = uipanel(app.grid_ControlWindow);
            app.grid_header = uigridlayout(app.panel_header);
            app.button_ControlWindowExit = uibutton(app.grid_header, 'push');
            app.button_ControlWindowExit.ButtonPushedFcn = createCallbackFcn(app, @button_ControlWindowExitButtonPushed, true);
            app.button_ControlWindowExit.Icon = fullfile(pathToMLAPP, 'images', 'hide-toolbar.jpg');
            app.button_ControlWindowExit.Tooltip = {'Hide controls window'};
            app.button_ControlWindowExit.Text = '';
            app.panel_Logos = uipanel(app.grid_header);
            app.grid_Logos = uigridlayout(app.panel_Logos);
            app.image_UCSCLogo = uiimage(app.grid_Logos);
            app.image_UCSCLogo.ImageSource = fullfile(pathToMLAPP, 'Images', 'UCSC-HydrogeologyLogo.jpg');
            app.image_SlugHeat = uiimage(app.grid_header);
            app.image_SlugHeat.ImageSource = fullfile(pathToMLAPP, 'Images', 'slugheatLogo.png');
            app.button_Restart = uibutton(app.grid_header, 'push');
            app.button_Restart.ButtonPushedFcn = createCallbackFcn(app, @button_RestartPushed, true);
            app.button_Restart.Tooltip = {'Restart from when SlugHeat is first launched. All penetration data will be removed from SlugHeat'};
            app.button_Restart.Text = 'Restart';
            app.panel_Commands = uipanel(app.grid_ControlWindow);
            app.panel_Commands.Title = 'Commands';
            app.grid_Commands = uigridlayout(app.panel_Commands);
            app.button_SensAn = uibutton(app.grid_Commands, 'push');
            app.button_SensAn.ButtonPushedFcn = createCallbackFcn(app, @button_SensAnButtonPushed, true);
            app.button_SensAn.Enable = 'off';
            app.button_SensAn.Tooltip = {'Run a sensitivitty analysis using varying input parameters to see effect on heat flow'};
            app.button_SensAn.Text = 'Run Sensitivity Analysis';
            app.button_IgnoreData = uibutton(app.grid_Commands, 'push');
            app.button_IgnoreData.ButtonPushedFcn = createCallbackFcn(app, @button_IgnoreDataPushed, true);
            app.button_IgnoreData.Enable = 'off';
            app.button_IgnoreData.Tooltip = {'Discard data from chosen sensors '};
            app.button_IgnoreData.Text = 'Ignore Data ';
            app.button_EndIterations = uibutton(app.grid_Commands, 'push');
            app.button_EndIterations.ButtonPushedFcn = createCallbackFcn(app, @button_EndIterationsPushed, true);
            app.button_EndIterations.Enable = 'off';
            app.button_EndIterations.Text = 'End';
            app.button_NextIteration = uibutton(app.grid_Commands, 'push');
            app.button_NextIteration.ButtonPushedFcn = createCallbackFcn(app, @button_NextIterationPushed, true);
            app.button_NextIteration.Enable = 'off';
            app.button_NextIteration.Tooltip = {'Continue processing to next iteration'};
            app.button_NextIteration.Text = 'Next Iteration';
            app.button_reset = uibutton(app.grid_Commands, 'push');
            app.button_reset.ButtonPushedFcn = createCallbackFcn(app, @button_resetPushed, true);
            app.button_reset.Enable = 'off';
            app.button_reset.Tooltip = {'Reset penetration data and prep for re-processing'};
            app.button_reset.Text = 'Reset Penetration';
            app.button_Process = uibutton(app.grid_Commands, 'push');
            app.button_Process.ButtonPushedFcn = createCallbackFcn(app, @button_ProcessButtonPushed, true);
            app.button_Process.Tooltip = {'Calculate equilibrium temperatures, thermal conductivity, and heat flow '};
            app.button_Process.Text = 'Process Heat Flow Data';
            app.label_ResFile = uilabel(app.grid_Commands);
            app.label_ResFile.Text = 'Results File:';
            app.label_resfilename = uilabel(app.grid_Commands);
            app.label_resfilename.Text = '';
            app.panel_PlotControls_SensAn = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_SensAn.Tooltip = {'Turn display of plots ''on'' and ''off'''};
            app.panel_PlotControls_SensAn.Title = 'Plot Controls';
            app.grid_PlotControls_Pen_2 = uigridlayout(app.panel_PlotControls_SensAn);
            app.checkbox_std = uicheckbox(app.grid_PlotControls_Pen_2);
            app.checkbox_std.Enable = 'off';
            app.checkbox_std.Text = 'Uncertainty';
            app.checkbox_std.Value = true;
            app.checkbox_ThermCon = uicheckbox(app.grid_PlotControls_Pen_2);
            app.checkbox_ThermCon.Enable = 'off';
            app.checkbox_ThermCon.Text = 'Thermal conductivity';
            app.checkbox_ThermCon.Value = true;
            app.checkbox_HeatFlow = uicheckbox(app.grid_PlotControls_Pen_2);
            app.checkbox_HeatFlow.Enable = 'off';
            app.checkbox_HeatFlow.Text = 'Heat flow';
            app.checkbox_HeatFlow.Value = true;
            app.panel_PlotControls_Scatter = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_Scatter.Tooltip = {'Turn display of plots ''on'' and ''off'''};
            app.panel_PlotControls_Scatter.Title = 'Plot Controls';
            app.grid_Scatter = uigridlayout(app.panel_PlotControls_Scatter);
            app.checkbox_HFPlot = uicheckbox(app.grid_Scatter);
            app.checkbox_HFPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_HFPlotValueChanged, true);
            app.checkbox_HFPlot.Text = 'Heat Flow Scatter';
            app.checkbox_HFPlot.Value = true;
            app.checkbox_SigmaPlot = uicheckbox(app.grid_Scatter);
            app.checkbox_SigmaPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_SigmaPlotValueChanged, true);
            app.checkbox_SigmaPlot.Text = 'Sigma Heat Flow Scatter';
            app.checkbox_SigmaPlot.Value = true;
            app.panel_PlotControls_HeatFlow = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_HeatFlow.Tooltip = {'Turn display of plots ''on'' and ''off'''};
            app.panel_PlotControls_HeatFlow.Title = 'Plot Controls';
            app.grid_PlotControls_HeatFlow = uigridlayout(app.panel_PlotControls_HeatFlow);
            app.checkbox_TempvCTR = uicheckbox(app.grid_PlotControls_HeatFlow);
            app.checkbox_TempvCTR.ValueChangedFcn = createCallbackFcn(app, @checkbox_TempvCTRValueChanged, true);
            app.checkbox_TempvCTR.Text = 'Temp vs. CTR';
            app.checkbox_TempvCTR.Value = true;
            app.checkbox_TempvDepth = uicheckbox(app.grid_PlotControls_HeatFlow);
            app.checkbox_TempvDepth.ValueChangedFcn = createCallbackFcn(app, @checkbox_TempvDepthValueChanged, true);
            app.checkbox_TempvDepth.Text = 'Temp vs. Depth';
            app.checkbox_TempvDepth.Value = true;
            app.checkbox_TCvDepth = uicheckbox(app.grid_PlotControls_HeatFlow);
            app.checkbox_TCvDepth.ValueChangedFcn = createCallbackFcn(app, @checkbox_TCvDepthValueChanged, true);
            app.checkbox_TCvDepth.Text = 'TC vs. Depth';
            app.checkbox_TCvDepth.Value = true;
            app.checkbox_TiltPlot_2 = uicheckbox(app.grid_PlotControls_HeatFlow);
            app.checkbox_TiltPlot_2.ValueChangedFcn = createCallbackFcn(app, @checkbox_TiltPlot_2ValueChanged, true);
            app.checkbox_TiltPlot_2.Text = 'Polynomial';
            app.panel_PenetrationInfo = uipanel(app.grid_ControlWindow);
            app.panel_PenetrationInfo.Tooltip = {'Data from PEN (penetration) file and TAP (temperature and pressure) file loaded in'};
            app.panel_PenetrationInfo.Title = 'Penetration Data';
            app.grid_PenetrationInfo = uigridlayout(app.panel_PenetrationInfo);
            app.label_TapFile = uilabel(app.grid_PenetrationInfo);
            app.label_TapFile.Text = 'TAP File:';
            app.label_tapfilename = uilabel(app.grid_PenetrationInfo);
            app.label_tapfilename.Text = '';
            app.label_PenFile = uilabel(app.grid_PenetrationInfo);
            app.label_PenFile.Text = 'Penetration File:';
            app.label_penfilename = uilabel(app.grid_PenetrationInfo);
            app.label_penfilename.Tooltip = {'Selected penetration text file'};
            app.label_penfilename.Text = '';
            app.button_LoadPen = uibutton(app.grid_PenetrationInfo, 'push');
            app.button_LoadPen.ButtonPushedFcn = createCallbackFcn(app, @button_LoadPenPushed, true);
            app.button_LoadPen.Tooltip = {'Load penetration data'};
            app.button_LoadPen.Text = 'Load Penetration';
            app.panel_Parameters = uipanel(app.grid_ControlWindow);
            app.panel_Parameters.Tooltip = {'Default input parameters '};
            app.panel_Parameters.Title = 'Input Parameters ';
            app.grid_Parameters = uigridlayout(app.panel_Parameters);
            app.label_ParFile = uilabel(app.grid_Parameters);
            app.label_ParFile.Text = 'Input Parameters File:';
            app.button_SetParams = uibutton(app.grid_Parameters, 'push');
            app.button_SetParams.ButtonPushedFcn = createCallbackFcn(app, @button_SetParamsPushed, true);
            app.button_SetParams.Tooltip = {'Launch auxiliary application for insepcting and modifying all input parameters'};
            app.button_SetParams.Text = 'Manually set input parameters';
            app.label_parfilename = uilabel(app.grid_Parameters);
            app.label_parfilename.Tooltip = {'Default text file defining all input parameters for data processing'};
            app.label_parfilename.Text = 'SlugHeat22.par';
            app.button_NewParFile = uibutton(app.grid_Parameters, 'push');
            app.button_NewParFile.ButtonPushedFcn = createCallbackFcn(app, @button_NewParFilePushed, true);
            app.button_NewParFile.Tooltip = {'Choose a new PAR file to use for defining input parameters'};
            app.button_NewParFile.Text = 'Choose another PAR file';
            app.lamp_ParFile = uilamp(app.grid_Parameters);
            app.lamp_ParFile.Visible = 'off';
            app.lamp_ParFile.Tooltip = {'User has changed input parameters  from those defined in deafult parameter file "SlugHeat22.par"'};
            app.panel_DataControls = uipanel(app.grid_ControlWindow);
            app.panel_DataControls.Tooltip = {'Control timing of penetration events'};
            app.panel_DataControls.Title = 'Data Controls';
            app.grid_DataControls = uigridlayout(app.panel_DataControls);
            app.PenetrtionStartLabel = uilabel(app.grid_DataControls);
            app.PenetrtionStartLabel.Text = 'Penetration Start:';
            app.edit_PenStart = uieditfield(app.grid_DataControls, 'numeric');
            app.edit_PenStart.ValueChangedFcn = createCallbackFcn(app, @edit_PenStartValueChanged, true);
            app.edit_PenStart.Tooltip = {'Record number defining the timing of the start of the penetration'};
            app.HeatPulseLabel = uilabel(app.grid_DataControls);
            app.HeatPulseLabel.Text = 'Heat Pulse:';
            app.edit_HP = uieditfield(app.grid_DataControls, 'numeric');
            app.edit_HP.ValueChangedFcn = createCallbackFcn(app, @edit_HPValueChanged, true);
            app.edit_HP.Tooltip = {'Record number defining the timing of the fire of the calibrated heat pulse'};
            app.PenetrationEndLabel = uilabel(app.grid_DataControls);
            app.PenetrationEndLabel.Text = 'Penetration End:';
            app.edit_PenEnd = uieditfield(app.grid_DataControls, 'numeric');
            app.edit_PenEnd.ValueChangedFcn = createCallbackFcn(app, @edit_PenEndValueChanged, true);
            app.edit_PenEnd.Tooltip = {'Record number defining the timing of the end of the penetration'};
            app.button_changeBW = uibutton(app.grid_DataControls, 'state');
            app.button_changeBW.ValueChangedFcn = createCallbackFcn(app, @button_changeBWValueChanged, true);
            app.button_changeBW.Tooltip = {'Inspect of modify temperature used as bottom water for all processing'};
            app.button_changeBW.Text = 'Change BW tempearture';
            app.BWTLabel = uilabel(app.grid_DataControls);
            app.BWTLabel.Text = 'Bottom water (°C):';
            app.edit_bottomwater = uieditfield(app.grid_DataControls, 'numeric');
            app.edit_bottomwater.ValueChangedFcn = createCallbackFcn(app, @edit_bottomwaterValueChanged, true);
            app.edit_bottomwater.Editable = 'off';
            app.edit_bottomwater.Tooltip = {'Bottom water temperature used for all processing'};
            app.PauseIterationsLabel = uilabel(app.grid_DataControls);
            app.PauseIterationsLabel.Text = 'Pause iterations:';
            app.switch_PauseBetweenIter = uiswitch(app.grid_DataControls, 'slider');
            app.switch_PauseBetweenIter.Items = {'Off', 'On '};
            app.switch_PauseBetweenIter.ValueChangedFcn = createCallbackFcn(app, @switch_PauseBetweenIterValueChanged, true);
            app.switch_PauseBetweenIter.Tooltip = {'Pause processing between iterations. If "on", all calculations for equilibrium temperatures and thermal conductivity values for each sensor will be plotted each iteration'};
            app.checkbox_UseHP = uicheckbox(app.grid_DataControls);
            app.checkbox_UseHP.ValueChangedFcn = createCallbackFcn(app, @checkbox_UseHPValueChanged, true);
            app.checkbox_UseHP.Tooltip = {'Check to use heat pulse reduction in all processing'};
            app.checkbox_UseHP.Text = 'Use heat pulse reduction';
            app.checkbox_UseHP.Value = true;
            app.panel_PlotControls_HP = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_HP.Tooltip = {'Turn display of plots ''on'' and ''off'''};
            app.panel_PlotControls_HP.Title = 'Plot Controls ';
            app.grid_PlotControls_HP = uigridlayout(app.panel_PlotControls_HP);
            app.buttongroup_PlotControls_HP = uibuttongroup(app.grid_PlotControls_HP);
            app.buttongroup_PlotControls_HP.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_PlotControls_HPSelectionChanged, true);
            app.buttongroup_PlotControls_HP.Tooltip = {'Turn on and off plots'};
            app.button_ShowAll_HP = uiradiobutton(app.buttongroup_PlotControls_HP);
            app.button_ShowAll_HP.Text = 'Show all plots';
            app.button_ShowAll_HP.Value = true;
            app.button_TempvsTime_HP = uiradiobutton(app.buttongroup_PlotControls_HP);
            app.button_TempvsTime_HP.Text = 'T vs. Time (s)';
            app.button_MisfitvShift_HP = uiradiobutton(app.buttongroup_PlotControls_HP);
            app.button_MisfitvShift_HP.Text = 'Misfit vs. Shift';
            app.button_TvShift_HP = uiradiobutton(app.buttongroup_PlotControls_HP);
            app.button_TvShift_HP.Text = 'T vs. Shift';
            app.button_TempvsTimeInv_HP = uiradiobutton(app.buttongroup_PlotControls_HP);
            app.button_TempvsTimeInv_HP.Text = 'T vs. 1/Time (s-1)';
            app.panel_PlotControls_Fric = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_Fric.Tooltip = {'Turn display of plots ''on'' and ''off'''};
            app.panel_PlotControls_Fric.Title = 'Plot Controls ';
            app.grid_PlotControls_Fric = uigridlayout(app.panel_PlotControls_Fric);
            app.buttongroup_PlotControls_Fric = uibuttongroup(app.grid_PlotControls_Fric);
            app.buttongroup_PlotControls_Fric.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_PlotControls_FricSelectionChanged2, true);
            app.buttongroup_PlotControls_Fric.Tooltip = {'Turn on and off plots'};
            app.button_ShowAllFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_ShowAllFric.Text = 'Show all plots';
            app.button_ShowAllFric.Value = true;
            app.button_TempvsTimeFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_TempvsTimeFric.Text = 'T vs. Time (s)';
            app.button_TempvsTauFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_TempvsTauFric.Text = 'T vs. Tau ';
            app.button_MisfitvsShiftFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_MisfitvsShiftFric.Text = 'Misfit vs. Shift ';
            app.button_TempvsBullFunFric = uiradiobutton(app.buttongroup_PlotControls_Fric);
            app.button_TempvsBullFunFric.Text = 'T vs.  F(α,τ)  ';
            app.panel_PlotControls_Pen = uipanel(app.grid_ControlWindow);
            app.panel_PlotControls_Pen.Tooltip = {'Turn display of plots ''on'' and ''off'''};
            app.panel_PlotControls_Pen.Title = 'Plot Controls';
            app.grid_PlotControls_Pen = uigridlayout(app.panel_PlotControls_Pen);
            app.checkbox_TiltPlot = uicheckbox(app.grid_PlotControls_Pen);
            app.checkbox_TiltPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_TiltPlotValueChanged, true);
            app.checkbox_TiltPlot.Text = 'Tilt';
            app.checkbox_TiltPlot.Value = true;
            app.checkbox_BottomWaterPlot = uicheckbox(app.grid_PlotControls_Pen);
            app.checkbox_BottomWaterPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_BottomWaterPlotValueChanged, true);
            app.checkbox_BottomWaterPlot.Text = 'Bottom Water';
            app.checkbox_BottomWaterPlot.Value = true;
            app.checkbox_DepthPlot = uicheckbox(app.grid_PlotControls_Pen);
            app.checkbox_DepthPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox_DepthPlotValueChanged, true);
            app.checkbox_DepthPlot.Text = 'Depth';
            app.checkbox_DepthPlot.Value = true;
            app.panel_TabGroup = uipanel(app.grid_Main);
            app.grid_TabGroup = uigridlayout(app.panel_TabGroup);
            app.tabgroup_Main = uitabgroup(app.grid_TabGroup);
            app.tabgroup_Main.SelectionChangedFcn = createCallbackFcn(app, @tabgroup_MainSelectionChanged, true);
            app.tab_PenetrationData = uitab(app.tabgroup_Main);
            app.tab_PenetrationData.Title = 'Penetration Data';
            app.grid_PenetrationData = uigridlayout(app.tab_PenetrationData);
            app.panel_PenDataAxes = uipanel(app.grid_PenetrationData);
            app.grid_PenDataAxes = uigridlayout(app.panel_PenDataAxes);
            app.axes_TempAboveBWT = uiaxes(app.grid_PenDataAxes);
            zlabel(app.axes_TempAboveBWT, 'Z')
            app.axes_TempAboveBWT.Tag = 'axes_tag';
            app.axes_Tilt = uiaxes(app.grid_PenDataAxes);
            zlabel(app.axes_Tilt, 'Z')
            app.axes_BottomWaterTemp = uiaxes(app.grid_PenDataAxes);
            app.axes_BottomWaterTemp.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes_Depth = uiaxes(app.grid_PenDataAxes);
            zlabel(app.axes_Depth, 'Z')
            app.axes_Depth.YDir = 'reverse';
            app.label_RecordNumber = uilabel(app.grid_PenDataAxes);
            app.label_RecordNumber.Text = 'Record Number';
            app.image_templabel = uiimage(app.grid_PenDataAxes);
            app.image_templabel.ImageSource = fullfile(pathToMLAPP, 'images', 'temp_label.png');
            app.image_BWlabel = uiimage(app.grid_PenDataAxes);
            app.image_BWlabel.ImageSource = fullfile(pathToMLAPP, 'images', 'BW_label.png');
            app.image_depthlabel = uiimage(app.grid_PenDataAxes);
            app.image_depthlabel.ImageSource = fullfile(pathToMLAPP, 'images', 'depth_label.png');
            app.Image = uiimage(app.grid_PenDataAxes);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'tilt_label.png');
            app.panel_PD_Thermistors = uipanel(app.grid_PenetrationData);
            app.panel_PD_Thermistors.Title = 'Sensors';
            app.GridLayout = uigridlayout(app.grid_PenetrationData);
            app.button_DiscardSensors = uibutton(app.GridLayout, 'push');
            app.button_DiscardSensors.ButtonPushedFcn = createCallbackFcn(app, @button_DiscardSensorsPushed, true);
            app.button_DiscardSensors.Text = 'Discard sensors';
            app.tab_FricitonalDecayReduction = uitab(app.tabgroup_Main);
            app.tab_FricitonalDecayReduction.Title = 'Frictional Equilibration Reduction';
            app.grid_FrictionalDecayReduction = uigridlayout(app.tab_FricitonalDecayReduction);
            app.panel_FricDecayAxes = uipanel(app.grid_FrictionalDecayReduction);
            app.grid_FricDecayAxes = uigridlayout(app.panel_FricDecayAxes);
            app.axes_TempvTime = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TempvTime, 'X')
            ylabel(app.axes_TempvTime, 'Y')
            zlabel(app.axes_TempvTime, 'Z')
            app.axes_TempvTau = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TempvTau, 'X')
            ylabel(app.axes_TempvTau, 'Y')
            zlabel(app.axes_TempvTau, 'Z')
            app.axes_TimeShift = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TimeShift, 'X')
            ylabel(app.axes_TimeShift, 'Y')
            zlabel(app.axes_TimeShift, 'Z')
            app.axes_TempvBullFunc = uiaxes(app.grid_FricDecayAxes);
            xlabel(app.axes_TempvBullFunc, 'F(2,τ)')
            ylabel(app.axes_TempvBullFunc, 'Y')
            zlabel(app.axes_TempvBullFunc, 'Z')
            app.panel_FD_Thermistors = uipanel(app.grid_FrictionalDecayReduction);
            app.panel_FD_Thermistors.Title = 'Sensors';
            app.tab_HeatPulseDecayReduction = uitab(app.tabgroup_Main);
            app.tab_HeatPulseDecayReduction.Title = 'Heat Pulse Decay Reduction';
            app.grid_HeatPulseDecayReduction = uigridlayout(app.tab_HeatPulseDecayReduction);
            app.panel_HPDecayAxes = uipanel(app.grid_HeatPulseDecayReduction);
            app.grid_HPDecayAxes = uigridlayout(app.panel_HPDecayAxes);
            app.axes_CTempv1_CTime = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_CTempv1_CTime, 'X')
            ylabel(app.axes_CTempv1_CTime, 'Y')
            zlabel(app.axes_CTempv1_CTime, 'Z')
            app.axes_MisfitvTimeShift = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_MisfitvTimeShift, 'X')
            ylabel(app.axes_MisfitvTimeShift, 'Y')
            zlabel(app.axes_MisfitvTimeShift, 'Z')
            app.axes_TempvTimeShift = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_TempvTimeShift, 'X')
            ylabel(app.axes_TempvTimeShift, 'Y')
            zlabel(app.axes_TempvTimeShift, 'Z')
            app.axes_CTempvCtime = uiaxes(app.grid_HPDecayAxes);
            xlabel(app.axes_CTempvCtime, 'X')
            ylabel(app.axes_CTempvCtime, 'Y')
            zlabel(app.axes_CTempvCtime, 'Z')
            app.label_HPIgnored = uilabel(app.grid_HPDecayAxes);
            app.label_HPIgnored.Visible = 'off';
            app.label_HPIgnored.Text = 'Heat pulse reduction ignored';
            app.panel_HPD_Thermistors = uipanel(app.grid_HeatPulseDecayReduction);
            app.panel_HPD_Thermistors.Title = 'Sensors';
            app.tab_HeatFlowAnalysis = uitab(app.tabgroup_Main);
            app.tab_HeatFlowAnalysis.Title = 'Heat Flow Analysis';
            app.grid_HeatFlowAnalysis = uigridlayout(app.tab_HeatFlowAnalysis);
            app.panel_BullAnalysisAxes = uipanel(app.grid_HeatFlowAnalysis);
            app.grid_BullAnalysisAxes = uigridlayout(app.panel_BullAnalysisAxes);
            app.panel_TempvDepth = uipanel(app.grid_BullAnalysisAxes);
            app.grid_TempvDepth = uigridlayout(app.panel_TempvDepth);
            app.axes_TempvDepth = uiaxes(app.grid_TempvDepth);
            xlabel(app.axes_TempvDepth, 'X')
            ylabel(app.axes_TempvDepth, 'Y')
            zlabel(app.axes_TempvDepth, 'Z')
            app.grid_GradientDepth = uigridlayout(app.grid_TempvDepth);
            app.label_gradient = uilabel(app.grid_GradientDepth);
            app.label_gradient.Text = 'gradient';
            app.label_DepthofTopTitle = uilabel(app.grid_GradientDepth);
            app.label_DepthofTopTitle.Text = 'Depth of Top Sensor:';
            app.label_depthoftop = uilabel(app.grid_GradientDepth);
            app.label_depthoftop.Text = 'depth of top sensor';
            app.label_GradientTitle = uilabel(app.grid_GradientDepth);
            app.label_GradientTitle.Text = 'Gradient:';
            app.panel_TCvDepth = uipanel(app.grid_BullAnalysisAxes);
            app.grid_TCvDepth = uigridlayout(app.panel_TCvDepth);
            app.axes_TCvDepth = uiaxes(app.grid_TCvDepth);
            xlabel(app.axes_TCvDepth, 'X')
            ylabel(app.axes_TCvDepth, 'Y')
            zlabel(app.axes_TCvDepth, 'Z')
            app.axes_TCvDepth.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.panel_averagek = uipanel(app.grid_TCvDepth);
            app.grid_Averagek = uigridlayout(app.panel_averagek);
            app.label_AveragekTitle = uilabel(app.grid_Averagek);
            app.label_AveragekTitle.Text = 'Average Thermal Conductivity:';
            app.label_averagek = uilabel(app.grid_Averagek);
            app.label_averagek.Text = 'average k';
            app.panel_CTRvTemp = uipanel(app.grid_BullAnalysisAxes);
            app.grid_CTRvTemp = uigridlayout(app.panel_CTRvTemp);
            app.axes_TempvCTR = uiaxes(app.grid_CTRvTemp);
            xlabel(app.axes_TempvCTR, 'X')
            ylabel(app.axes_TempvCTR, 'Y')
            zlabel(app.axes_TempvCTR, 'Z')
            app.panel_HF_Shift = uipanel(app.grid_CTRvTemp);
            app.grid_HFShfit = uigridlayout(app.panel_HF_Shift);
            app.label_HeatFlowTitle = uilabel(app.grid_HFShfit);
            app.label_HeatFlowTitle.Text = 'Heat Flow:';
            app.label_ShiftTitle = uilabel(app.grid_HFShfit);
            app.label_ShiftTitle.Text = 'Depth Shift:';
            app.label_shift = uilabel(app.grid_HFShfit);
            app.label_shift.Text = 'shift';
            app.label_heatflow = uilabel(app.grid_HFShfit);
            app.label_heatflow.Text = 'heat flow';
            app.label_sigLRTitle = uilabel(app.grid_HFShfit);
            app.label_sigLRTitle.Text = 'sig';
            app.label_sigLR = uilabel(app.grid_HFShfit);
            app.label_sigLR.Text = 'unc';
            app.panel_BA_Thermistors = uipanel(app.grid_HeatFlowAnalysis);
            app.panel_BA_Thermistors.Title = 'Sensors';
            app.tab_HeatFlowRegressionAnalysis = uitab(app.tabgroup_Main);
            app.tab_HeatFlowRegressionAnalysis.Title = 'Heat Flow Scatter Analysis';
            app.grid_HeatFlowRegressionAnalysis = uigridlayout(app.tab_HeatFlowRegressionAnalysis);
            app.panel_HeatFlowRegAxes = uipanel(app.grid_HeatFlowRegressionAnalysis);
            app.grid_HeatFlowRegAxes = uigridlayout(app.panel_HeatFlowRegAxes);
            app.axes_Sigma = uiaxes(app.grid_HeatFlowRegAxes);
            xlabel(app.axes_Sigma, 'X')
            ylabel(app.axes_Sigma, 'Y')
            zlabel(app.axes_Sigma, 'Z')
            app.axes_Sigma.XDir = 'reverse';
            app.axes_HeatFlow = uiaxes(app.grid_HeatFlowRegAxes);
            xlabel(app.axes_HeatFlow, 'X')
            ylabel(app.axes_HeatFlow, 'Y')
            zlabel(app.axes_HeatFlow, 'Z')
            app.axes_HeatFlow.XDir = 'reverse';
            app.panel_HF_Thermistors = uipanel(app.grid_HeatFlowRegressionAnalysis);
            app.panel_HF_Thermistors.Title = 'Sensors';
            app.tab_SensitivityAnalysis = uitab(app.tabgroup_Main);
            app.tab_SensitivityAnalysis.Title = 'Monte Carlo Sensitivity Analysis';
            app.grid_SensitivityAnalysis = uigridlayout(app.tab_SensitivityAnalysis);
            app.panel_SensAnalysisAxes = uipanel(app.grid_SensitivityAnalysis);
            app.grid_SensAnalysisAxes = uigridlayout(app.panel_SensAnalysisAxes);
            app.panel_SA_TempvCTR = uipanel(app.grid_SensAnalysisAxes);
            app.grid_SA_TempvCTR = uigridlayout(app.panel_SA_TempvCTR);
            app.axes_SA_TempvCTR = uiaxes(app.grid_SA_TempvCTR);
            xlabel(app.axes_SA_TempvCTR, 'X')
            ylabel(app.axes_SA_TempvCTR, 'Y')
            zlabel(app.axes_SA_TempvCTR, 'Z')
            app.grid_heatflow_SA = uigridlayout(app.grid_SA_TempvCTR);
            app.label_MeanHFTitle = uilabel(app.grid_heatflow_SA);
            app.label_MeanHFTitle.Text = 'Mean q: ';
            app.label_meanheatflow = uilabel(app.grid_heatflow_SA);
            app.label_meanheatflow.Text = 'mean heat flow';
            app.label_HFRangeTitle = uilabel(app.grid_heatflow_SA);
            app.label_HFRangeTitle.Text = 'Total Heat Flow Range: ';
            app.label_heatflowrange = uilabel(app.grid_heatflow_SA);
            app.label_heatflowrange.Text = 'total heat flow range';
            app.label_InitHeatFlowTitle = uilabel(app.grid_heatflow_SA);
            app.label_InitHeatFlowTitle.Text = 'Initial q: ';
            app.label_initheatflow = uilabel(app.grid_heatflow_SA);
            app.label_initheatflow.Text = 'initial heat flow';
            app.label_CurrentHFTitle = uilabel(app.grid_heatflow_SA);
            app.label_CurrentHFTitle.Text = 'Current q: ';
            app.label_currentheatflow = uilabel(app.grid_heatflow_SA);
            app.label_currentheatflow.Text = 'current heat flow';
            app.label_InitHeatFlowTitle_2 = uilabel(app.grid_heatflow_SA);
            app.label_InitHeatFlowTitle_2.Text = 'Heat Flow Results';
            app.panel_SA_SDvIter = uipanel(app.grid_SensAnalysisAxes);
            app.grid_SA_SDvIter = uigridlayout(app.panel_SA_SDvIter);
            app.axes_SA_SDvIter = uiaxes(app.grid_SA_SDvIter);
            xlabel(app.axes_SA_SDvIter, 'X')
            ylabel(app.axes_SA_SDvIter, 'Y')
            zlabel(app.axes_SA_SDvIter, 'Z')
            app.panel_heatflow_SA = uipanel(app.grid_SA_SDvIter);
            app.grid_iterations = uigridlayout(app.panel_heatflow_SA);
            app.label_CurrentIterationTitle = uilabel(app.grid_iterations);
            app.label_CurrentIterationTitle.Text = 'Current realization:';
            app.label_currentiteration = uilabel(app.grid_iterations);
            app.label_currentiteration.Text = 'current realization';
            app.label_StanDevTitle = uilabel(app.grid_iterations);
            app.label_StanDevTitle.Text = 'Current q Uncertainty :';
            app.label_standev = uilabel(app.grid_iterations);
            app.label_standev.Text = 'standard deviation';
            app.label_InitHeatFlowTitle_3 = uilabel(app.grid_iterations);
            app.label_InitHeatFlowTitle_3.Text = 'Heat Flow Uncertainty';
            app.label_StanDevTitle_perc = uilabel(app.grid_iterations);
            app.label_StanDevTitle_perc.Text = 'Current q Uncertainty (% of mean):';
            app.label_standev_perc = uilabel(app.grid_iterations);
            app.label_standev_perc.Text = 'standard deviation';
            app.panel_SA_TCvDepth = uipanel(app.grid_SensAnalysisAxes);
            app.grid_SA_TCvDepth = uigridlayout(app.panel_SA_TCvDepth);
            app.axes_SA_TCvDepth = uiaxes(app.grid_SA_TCvDepth);
            xlabel(app.axes_SA_TCvDepth, 'X')
            zlabel(app.axes_SA_TCvDepth, 'Z')
            app.Panel_6 = uipanel(app.grid_SA_TCvDepth);
            app.grid_distributions = uigridlayout(app.Panel_6);
            app.label_MinThickTitle = uilabel(app.grid_distributions);
            app.label_MinThickTitle.Text = {'Min'; 'Layer Thickness:'};
            app.label_MeanDevTitle = uilabel(app.grid_distributions);
            app.label_MeanDevTitle.Text = 'k Standard Deviation:';
            app.label_meandev = uilabel(app.grid_distributions);
            app.label_meandev.Text = '';
            app.label_thermconbounds = uilabel(app.grid_distributions);
            app.label_thermconbounds.Text = '[min max]';
            app.label_minthickness = uilabel(app.grid_distributions);
            app.label_minthickness.Text = '';
            app.label_ThermConBoundsTitle = uilabel(app.grid_distributions);
            app.label_ThermConBoundsTitle.Text = {'k '; 'Range:'};
            app.label_MinThickTitle_3 = uilabel(app.grid_distributions);
            app.label_MinThickTitle_3.Text = 'Parameter Distributions';
            app.label_MinThickTitle_4 = uilabel(app.grid_distributions);
            app.label_MinThickTitle_4.Text = '';
            app.label_MinThickTitle_2 = uilabel(app.grid_distributions);
            app.label_MinThickTitle_2.Text = 'k Distribution Type:';
            app.label_kDistribution = uilabel(app.grid_distributions);
            app.label_kDistribution.Text = 'Gaussian';
            app.panel_SA_Thermistors = uipanel(app.grid_SensitivityAnalysis);
            app.panel_SA_Thermistors.Title = 'Sensors';
            app.tab_HFSummary = uitab(app.tabgroup_Main);
            app.tab_HFSummary.Title = 'Results Summary';
            app.GridLayout3 = uigridlayout(app.tab_HFSummary);
            app.panel_HFSummary = uipanel(app.GridLayout3);
            app.panel_HFSummary.Title = 'Heat Flow Results:';
            app.grid_HFSummary = uigridlayout(app.panel_HFSummary);
            app.table_HFSummary = uitable(app.grid_HFSummary);
            app.table_HFSummary.ColumnName = {'Cruise'; 'Station'; 'Penetration'; 'Trial'; 'Iterations'; 'Use HP?'; 'Bottom Water (°C)'; 'Tot Sensors'; 'Sensors Ignored'; 'Sensors ignored (T only)'; 'Sensors ignored (k only)'; 'Tilt (°)'; 'Therm. Gradient (°C/m)'; 'Av. Therm. Conductivity (W/m°C)'; 'Heat Flow (mW/m^2)'; 'Linear regression misfit (mW/m2)'; 'Shift (m)'; 'Notes'};
            app.table_HFSummary.RowName = {};
            app.table_HFSummary.ColumnEditable = true;
            app.button_clearresults = uibutton(app.grid_HFSummary, 'push');
            app.button_clearresults.ButtonPushedFcn = createCallbackFcn(app, @button_clearresultsPushed, true);
            app.button_clearresults.Text = 'CLEAR';
            app.button_saveresults = uibutton(app.grid_HFSummary, 'push');
            app.button_saveresults.ButtonPushedFcn = createCallbackFcn(app, @button_saveresultsPushed, true);
            app.button_saveresults.Text = 'SAVE';
            app.panel_ErrorUncertaintyAnalysisTable = uipanel(app.grid_HFSummary);
            app.panel_ErrorUncertaintyAnalysisTable.Title = 'Error and Uncertainty Results:';
            app.GridLayout2 = uigridlayout(app.panel_ErrorUncertaintyAnalysisTable);
            app.table_ErrorUncertaintySummary = uitable(app.GridLayout2);
            app.table_ErrorUncertaintySummary.ColumnName = {'Cruise'; 'Station'; 'Penetration'; 'Trial Number'; 'Iterations'; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.table_ErrorUncertaintySummary.RowName = {};
            app.table_ErrorUncertaintySummary.ColumnEditable = true;
            app.button_saveErrRestults = uibutton(app.grid_HFSummary, 'push');
            app.button_saveErrRestults.ButtonPushedFcn = createCallbackFcn(app, @button_saveErrRestultsButtonPushed, true);
            app.button_saveErrRestults.Text = 'SAVE';
            app.button_clearErrResults = uibutton(app.grid_HFSummary, 'push');
            app.button_clearErrResults.ButtonPushedFcn = createCallbackFcn(app, @button_clearErrResultsButtonPushed, true);
            app.button_clearErrResults.Text = 'CLEAR';
            app.MonteCarloCompareTab = uitab(app.tabgroup_Main);
            app.MonteCarloCompareTab.Title = 'Monte Carlo Compare';
            app.grid_sensresults = uigridlayout(app.MonteCarloCompareTab);
            app.axes_sensscatter = uiaxes(app.grid_sensresults);
            xlabel(app.axes_sensscatter, 'Heat Flow Uncertainty (% of mean)')
            ylabel(app.axes_sensscatter, 'Iteration')
            zlabel(app.axes_sensscatter, 'Z')
            app.axes_sensscatter.XAxisLocation = 'top';
            app.axes_senshist = uiaxes(app.grid_sensresults);
            xlabel(app.axes_senshist, 'Heat Flow (mW/m2)')
            ylabel(app.axes_senshist, 'Count')
            zlabel(app.axes_senshist, 'Z')
            app.axes_senshist.XAxisLocation = 'top';
            app.panel_ProcessingInfoLabels = uipanel(app.grid_TabGroup);
            app.grid_ProcessingInfoLabels = uigridlayout(app.panel_ProcessingInfoLabels);
            app.edit_StationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_StationTitle.Text = 'Station: ';
            app.edit_LatitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LatitudeTitle.Text = 'Latitude:';
            app.edit_LongitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LongitudeTitle.Text = 'Longitude:';
            app.edit_ProcessingTimeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_ProcessingTimeTitle.Text = 'Processing Time:';
            app.edit_CruiseTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_CruiseTitle.Text = 'Cuise:';
            app.label_Cruise = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Cruise.Text = 'cruise name';
            app.label_Station = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Station.Text = 'station name';
            app.label_Penetration = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Penetration.Text = 'penetration name';
            app.label_Latitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Latitude.Text = 'latitude';
            app.label_Longitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Longitude.Text = 'longitude';
            app.label_ProcessingTime = uilabel(app.grid_ProcessingInfoLabels);
            app.label_ProcessingTime.Text = 'start of processing';
            app.edit_PenetrationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_PenetrationTitle.Text = 'Penetration:';
            app.panel_TopLabels = uipanel(app.grid_TabGroup);
            app.grid_TopLabels = uigridlayout(app.panel_TopLabels);
            app.label_SlugHeat = uilabel(app.grid_TopLabels);
            app.label_SlugHeat.Text = 'SlugHeat';
            app.label_CurrentPath = uilabel(app.grid_TopLabels);
            app.label_CurrentPath.Text = 'Current Path:';
            app.label_currentpathfull = uilabel(app.grid_TopLabels);
            app.label_currentpathfull.Text = '';
            app.label_IterationTitle = uilabel(app.grid_TopLabels);
            app.label_IterationTitle.Text = 'Iterations:';
            app.label_Iteration = uilabel(app.grid_TopLabels);
            app.label_Iteration.Text = '';
            app.label_TrialTitle = uilabel(app.grid_TopLabels);
            app.label_TrialTitle.Text = 'Trial:';
            app.label_trial = uilabel(app.grid_TopLabels);
            app.label_trial.Text = '';
            app.panel_TopBar = uipanel(app.grid_TabGroup);
            app.button_OpenControlsPanel = uibutton(app.grid_Main, 'push');
            app.button_OpenControlsPanel.ButtonPushedFcn = createCallbackFcn(app, @button_OpenControlsPanelButtonPushed, true);
            app.button_OpenControlsPanel.Icon = fullfile(pathToMLAPP, 'Images', 'arrow.png');
            app.button_OpenControlsPanel.Text = '';
            % Show the figure after all components are created
            app.figure_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlugHeat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_Main)
        end
    end
end